classdef Classification_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu    matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ChangereferencematerialfolderMenu  matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        ProceedtoDownholeModuleButton   matlab.ui.control.Button
        GetRMfilesButton                matlab.ui.control.Button
        ChosenEditField                 matlab.ui.control.EditField
        ChooseButton                    matlab.ui.control.Button
        GroupbyspotsizeButton           matlab.ui.control.Button
        ContainsEditField               matlab.ui.control.EditField
        ContainsEditFieldLabel          matlab.ui.control.Label
        ProgressPanel                   matlab.ui.container.Panel
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        IntervalButton                  matlab.ui.control.Button
        UnknownButton                   matlab.ui.control.Button
        ProceedtoDriftModuleButton      matlab.ui.control.Button
        InHouseReferenceMaterialButton  matlab.ui.control.Button
        QualityControlReferenceMaterialButton  matlab.ui.control.Button
        CalibratorButton                matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        RMListBox                       matlab.ui.control.ListBox
        ReferenceMaterialTable          matlab.ui.control.Table
        ContextMenu2                    matlab.ui.container.ContextMenu
        ShowRMvaluesMenu                matlab.ui.container.Menu
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp

        selected_spot
        selected_referencematerial
        selected_string


        editstatus = false

    end

    methods (Access = private)

        function update_Classification_Module_GUI(app)

            % assign spots to reference materials
            % Ensure the first item is a cell array of character vectors
            app.RMListBox.Items = {'---------'};
            app.RMListBox.ItemsData = 1;
            % Convert the names to a cell array of character vectors
            if isfield(app.base.project.session, 'referencematerials')

                names = cellstr(app.base.project.session.referencematerials.names);
                if isempty(names)
                    message = "The chosen RM file folder contains no RM files. Maybe the folder was renamed or moved. You can change the folder path in this module in the Settings menu.";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                end
                % Append the rest of the items from the referencematerials.names
                app.RMListBox.Items = [app.RMListBox.Items, names'];
                app.RMListBox.ItemsData = [app.RMListBox.ItemsData, 1:numel(names)];

            end

            update_bracket_id(app)
            update_reference_material_table(app)
            update_refmat_table_colours(app)
            update_reference_material_table(app)

        end

        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            app.ClassificationButton.Enable = "on";
            app.ClassificationButton.BackgroundColor = orange; % orange because this is the active module
            app.ClassificationButton.FontWeight = "bold";

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        function changes = check_changes(app)

            status = app.base.project.session.config.module_status;

            if  status.drift || status.quantification

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "You changed something, do you want to reset drift and quantification?";
                title = "Attention";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Yes'
                        app.base.project.session.config.module_status.drift = false;
                        app.base.project.session.config.module_status.quantification = false;
                        app.base.project.session.config.module_status.results = false;

                        % delete all variables that depend on this
                        if isfield(app.base.project.session.spot, 'bkgd_corrected_cps')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'bkgd_corrected_cps');
                        end
                        if isfield(app.base.project.session.spot, 'sensitivities')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'sensitivities');
                        end
                        if isfield(app.base.project.session.spot, 'cal_sensitivities')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'cal_sensitivities');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_composition')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_composition');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_limits_of_detection');
                        end
                        if isfield(app.base.project.session.spot, 'Fe_2_3')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_2_3');
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%
                        if isfield(app.base.project.session.spot, 'internal_standard')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard');
                        end
                        if isfield(app.base.project.session.spot, 'internal_standard_mass_fraction')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_mass_fraction');
                        end
                        if isfield(app.base.project.session.spot, 'internal_standard_oxide_wt')                    
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_oxide_wt');
                        end
                        if isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide');
                        end
                        %%%%%%%%%%%%%%%%%

                        if isequal(app.base.project.session.config.reductiontype, 'Inclusion')
                            %%%%%%%%%%%%%
                            if isfield(app.base.project.session.spot, 'internal_standard_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'second_internal_standard_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'second_internal_standard_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'second_internal_standard_mass_fraction_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'second_internal_standard_mass_fraction_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'internal_standard_oxide_wt_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'internal_standard_oxide_wt_inclusion');
                            end
                            if isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide_inclusion')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide_inclusion');
                            end

                            %%%%%%%%%%%%%

                            if isfield(app.base.project.session.spot, 'inclusion_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_composition');
                            end
                            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_limits_of_detection');
                            end
                            if isfield(app.base.project.session.spot, 'mixing_ratio')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'mixing_ratio');
                            end
                            if isfield(app.base.project.session.spot, 'mix_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'mix_composition');
                            end
                            if isfield(app.base.project.session.spot, 'bkgd_corrected_cps_mix')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'bkgd_corrected_cps_mix');
                            end


                        end

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        changes = true;

                        update_progress_buttons(app)

                    case 'Cancel'
                        update_Classification_Module_GUI(app)
                        changes = false;
                        return
                end
            else
                changes = true;
            end
        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end


        %%%%%%%%%%% functions for the spots %%%%%%%%%%%%%%

        function get_RM_Files(app)

            RM_files = dir(fullfile(app.base.project.session.config.RM_folder_path, '*.csv'));

            filenames = {};
            allTables = {};
            alluncertainites = {};

            % New lists for the new data types
            allRatios = {};
            allRatioUnc = {};
            allAges = {};
            allAgeUnc = {};

            for i = 1:length(RM_files)
                % Get the full path of the file
                filePath = fullfile(app.base.project.session.config.RM_folder_path, RM_files(i).name);

                opts = detectImportOptions(filePath);
                raw = readtable(filePath, opts);

                % Extract all keys (First column) and values (Second column)
                keys = raw{:, 1};
                if iscell(keys)
                    keys = string(keys); % Ensure string array for easier string operations
                end

                % Values are in column 2, uncertainties (if they exist) in column 3
                vals = table2array(raw(:, 2));
                if size(raw, 2) >= 3
                    uncs = table2array(raw(:, 3));
                else
                    uncs = nan(size(vals)); % Fill with NaNs if no 3rd column exists
                end

                % Identify indices for each category
                idx_age = strcmpi(keys, 'Age');
                idx_ratio = contains(keys, '/');
                idx_element = ~idx_age & ~idx_ratio; % Anything else is treated as an element

                % 1. Process Elements (Original behavior)
                elements = keys(idx_element);
                elements_val = vals(idx_element);
                elements_unc = uncs(idx_element);

                dataTable = array2table(elements_val', 'VariableNames', elements');
                allTables{i} = dataTable;

                % Check if element uncertainties are present (not all NaN)
                if any(~isnan(elements_unc))
                    uncTable = array2table(elements_unc', 'VariableNames', elements');
                    alluncertainites{i} = uncTable;
                else
                    alluncertainites{i} = [];
                end

                % 2. Process Ratios
                if any(idx_ratio)
                    ratios = keys(idx_ratio);
                    ratios_val = vals(idx_ratio);
                    ratios_unc = uncs(idx_ratio);

                    % Note: array2table will automatically sanitize names like '206Pb/238U' to valid MATLAB variables (e.g., 'x206Pb_238U')
                    ratioTable = array2table(ratios_val', 'VariableNames', ratios');
                    allRatios{i} = ratioTable;

                    if any(~isnan(ratios_unc))
                        ratioUncTable = array2table(ratios_unc', 'VariableNames', ratios');
                        allRatioUnc{i} = ratioUncTable;
                    else
                        allRatioUnc{i} = [];
                    end
                else
                    allRatios{i} = [];
                    allRatioUnc{i} = [];
                end

                % 3. Process Age
                if any(idx_age)
                    allAges{i} = vals(idx_age);
                    if any(~isnan(uncs(idx_age)))
                        allAgeUnc{i} = uncs(idx_age);
                    else
                        allAgeUnc{i} = [];
                    end
                else
                    allAges{i} = [];
                    allAgeUnc{i} = [];
                end

                % Extract the filename without extension
                filename = RM_files(i).name;
                filename = filename(1:end-4);
                filenames{i} = filename;
            end

            % Assign standard variables to the app structure
            app.base.project.session.referencematerials.names = filenames';
            app.base.project.session.referencematerials.data = allTables';
            app.base.project.session.referencematerials.uncertainty = alluncertainites';

            % Assign new variables to the app structure
            app.base.project.session.referencematerials.ratio = allRatios';
            app.base.project.session.referencematerials.ratio_uncertainty = allRatioUnc';
            app.base.project.session.referencematerials.age = allAges';
            app.base.project.session.referencematerials.age_uncertainty = allAgeUnc';

        end


        function update_reference_material_table(app)
            % Get spot data
            spot = app.base.project.session.spot;
            numSpots = numel(spot.name);

            % Direct assignment (cell arrays for names, bracket_id; string arrays for type/refmat)
            spotname = spot.name(:);
            type = spot.type(:);
            refmat = spot.referencematerial(:);
            bracket_id = spot.bracket_id(:);

            % Create table
            T = table((1:numSpots)', spotname, refmat, type, bracket_id);
            T.Properties.VariableNames = {'#', 'Spotname', 'Reference Material', 'Type', 'Bracket id'};

            % Update UI table
            app.ReferenceMaterialTable.Data = T;
            app.ReferenceMaterialTable.ColumnName = T.Properties.VariableNames;
        end


        function update_refmat_table_colours(app)
            % Get data
            refmat = app.base.project.session.spot.referencematerial;
            type = app.base.project.session.spot.type;
            nRows = height(app.ReferenceMaterialTable.Data);

            % Initialize all to white
            bg = ones(nRows, 3);

            % Logical indices for each condition
            idx_none_unknown = refmat == "None" & type == "Unknown";
            idx_refmat_not_none_unknown = refmat ~= "None" & type == "Unknown";
            idx_none_type_not_unknown_ihrm = refmat == "None" & type ~= "Unknown" & type ~= "IHRM";
            idx_cal = refmat ~= "None" & refmat ~= "Select Reference Material" & type == "CAL";
            idx_qcrm = refmat ~= "None" & refmat ~= "Select Reference Material" & type == "QCRM";
            idx_ihrm = type == "IHRM";

            % Apply colors
            bg(idx_refmat_not_none_unknown, :) = repmat([1, 229/255, 204/255], sum(idx_refmat_not_none_unknown), 1);
            app.base.project.session.spot.type(idx_refmat_not_none_unknown) = "Select Type";

            bg(idx_none_type_not_unknown_ihrm, :) = repmat([1, 229/255, 204/255], sum(idx_none_type_not_unknown_ihrm), 1);
            app.base.project.session.spot.referencematerial(idx_none_type_not_unknown_ihrm) = "Select Reference Material";

            bg(idx_cal, :) = repmat([204/255, 1, 204/255], sum(idx_cal), 1);
            bg(idx_qcrm, :) = repmat([204/255, 1, 1], sum(idx_qcrm), 1);

            bg(idx_ihrm, :) = repmat([204/255, 204/255, 1], sum(idx_ihrm), 1);
            app.base.project.session.spot.referencematerial(idx_ihrm) = "None";

            % Set background color
            app.ReferenceMaterialTable.BackgroundColor = bg;
        end


        function update_bracket_id(app)
            for i = 1:height(app.ReferenceMaterialTable.Data)
                if app.base.project.session.spot.referencematerial(i) ~= "None" && app.base.project.session.spot.type(i) == "CAL" && not(app.base.project.session.spot.bracket_id(i) > 1)
                    app.base.project.session.spot.bracket_id(i) = 1;
                elseif app.base.project.session.spot.type(i) == "QCRM" || app.base.project.session.spot.type(i) == "IHRM"
                    app.base.project.session.spot.bracket_id(i) = 0;
                end
            end
        end


    end

    methods (Access = public)

        %%%%%%%%%%%%%%%%%%%%%%%%% API function  %%%%%%%%%%%%%%%%%%%%%%%%%

        function [settings] = assign_spots_to_reference_materials(app, settings)
            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the type is not input
                if not(isfield(settings,'type')) || isempty(settings.type)
                    % now return the settings with the options for type:
                    settings.type.Options={"CAL", "QCRM", "IHRM"};
                    disp('Types must be provided in settings.type');
                    test_state = false;
                end

                % if the referencematerial is not input
                if not(isfield(settings,'referencematerial')) || isempty(settings.referencematerial)
                    settings.referencematerial.Options=string(app.RMListBox.Items(2:end)); % these options need to be the items of the RMListBox but as strings
                    test_state = false;
                    disp('Reference Materials must be provided in settings.referencematerial');
                end

                % if the spotnumber is not input
                if not(isfield(settings,'spotnumber')) || isempty(settings.spotnumber)
                    disp('Spot numbers must be provided in settings.spotnumber');
                    return
                    test_state = false;
                end

                % if the spotnumber is not input
                if not(isfield(settings,'bracket_id')) || isempty(settings.bracket_id)
                    disp('bracket_id can be set via settings.bracket_id. If no input is given the bracket_id will be 1 for all CALs')
                    bracket_id_not_given = true;
                else
                    bracket_id_not_given = false;
                    bracket_id = settings.bracket_id;
                end

                spotnumber = settings.spotnumber;
                type = settings.type;
                referencematerial = settings.referencematerial;

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else
                    % Store the results in the app structure
                    app.base.project.session.spot.referencematerial(spotnumber) = referencematerial;
                    app.base.project.session.spot.type(spotnumber) = type;

                    if bracket_id_not_given == true
                        update_bracket_id(app)
                    elseif bracket_id_not_given == false
                        app.base.project.session.spot.bracket_id(spotnumber) = bracket_id;
                        update_bracket_id(app)
                    end

                end

                if isfield(settings, 'classification_module_GUI_update_state') && settings.classification_module_GUI_update_state == true
                    update_Classification_Module_GUI(app)
                end

            catch ME
                DisplayError(ME,'assign_spots_to_reference_material')

                message = "assign_spots_to_reference_material failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("assign_spots_to_reference_material failed")
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)


            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.classification = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);


            % Only set default values if fields don't exist or are empty
            if ~isfield(app.base.project.session.spot, 'type') || isempty(app.base.project.session.spot.type)
                app.base.project.session.spot.type = repmat("Unknown", 1, numel(app.base.project.session.spot.name))';
            end

            if ~isfield(app.base.project.session.spot, 'referencematerial') || isempty(app.base.project.session.spot.referencematerial)
                app.base.project.session.spot.referencematerial = repmat("None", 1, numel(app.base.project.session.spot.name))';
            end

            if ~isfield(app.base.project.session.spot, 'bracket_id') || isempty(app.base.project.session.spot.bracket_id)
                app.base.project.session.spot.bracket_id = zeros(1, numel(app.base.project.session.spot.name))';
            end


            update_progress_buttons(app)


            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            % Change session.spot.prm_sensitivities to session.spot.cal_sensitivities
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end

            %update GUI
            update_Classification_Module_GUI(app)
        end

        % Button pushed function: ProceedtoDriftModuleButton
        function ProceedtoDriftModuleButtonPushed(app, event)

            
            % penalty if the CAL or QCRM selection was not finished
            if any(contains(unique(app.base.project.session.spot.referencematerial), 'Select Reference Material'))...
                    || any(contains(unique(app.base.project.session.spot.type), 'Select Type'))

                message = "Select Reference Material and Type for all QCRM and CAL";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("Select Reference Material and Type for all QCRM and CAL")
                return
            end


%             unique_CALs = unique(app.base.project.session.spot.referencematerial);
%             unique_CALs = unique_CALs(~strcmp(unique_CALs, 'None'));
% 
%             if any(ismember(app.base.project.session.referencematerials.data{3, 1}.Properties.VariableNames, app.base.project.session.spot.matrix_only_tracer))
% 
%                 % if the host only tracer element is not in
%                 % the CAL the mix cannot be quantified and
%                 % therefore the LODs cannot be calculated
% 
%                 message = {['The limits of detection for  for calibrator ' char(unique_CALs) ' could not be calculated. Make sure to that the matrix-only tracer element has a known mass fraction (i.e. the element is defined in the RM file) in the calibrator ' char(unique_CALs(cal_idx)) ' and that the host for this spot is also quantified.']};
%                 uialert(app.UIFigure, message, 'Warning');
%                 failed = true;
%                 return
% 
%             end


            app.base.project.session.config.module_status.classification = true;

            if app.editstatus == true
                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
            else
                selection = 'Yes';
            end

            switch selection
                case 'Yes'

                    progress = uiprogressdlg(app.UIFigure, 'Title', 'Correcting drift...', ...
                        'Message', 'Calculating...', 'Cancelable', 'off', 'Indeterminate','on');

                    settings.ICP_Base_GUI_update_state = true;

                    [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                    Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)

                    delete(progress)
                    delete(app)

                case 'Cancel'
                    return
            end


        end

        % Button pushed function: CalibratorButton
        function CalibratorButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            app.base.project.session.spot.type(app.selected_spot) = "CAL";

            % deselect the analytes to cirumvent weird behaviour
            %app.selected_analytes = [];

            update_Classification_Module_GUI(app)
        end

        % Value changed function: RMListBox
        function RMListBoxValueChanged(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            app.selected_referencematerial = app.RMListBox.Value;

            app.base.project.session.spot.referencematerial(app.selected_spot) = app.base.project.session.referencematerials.names(app.selected_referencematerial);

            % deselect the analytes to cirumvent weird behaviour
            %app.selected_analytes = [];

            update_Classification_Module_GUI(app)
        end

        % Button pushed function: QualityControlReferenceMaterialButton
        function QualityControlReferenceMaterialButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            app.base.project.session.spot.type(app.selected_spot) = "QCRM";

            update_Classification_Module_GUI(app)
        end

        % Button pushed function: InHouseReferenceMaterialButton
        function InHouseReferenceMaterialButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            app.base.project.session.spot.type(app.selected_spot) = "IHRM";
            app.base.project.session.spot.referencematerial(app.selected_spot) = "None";

            update_Classification_Module_GUI(app)
        end

        % Button pushed function: UnknownButton
        function UnknownButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            app.base.project.session.spot.type(app.selected_spot) = "Unknown";
            app.base.project.session.spot.referencematerial(app.selected_spot) = "None";

            update_Classification_Module_GUI(app)

        end

        % Callback function: ReferenceMaterialTable
        function ReferenceMaterialTableSelectionChanged(app, event)
            app.selected_spot = unique(app.ReferenceMaterialTable.Selection(:,1));

            app.ChosenEditField.Value = '';
            app.ContainsEditField.Value = '';
            %update_Classification_Module_GUI(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            app.base.project.session.spot.type = repmat("Unknown", 1, numel(app.base.project.session.spot.name))';
            app.base.project.session.spot.referencematerial = repmat("None", 1, numel(app.base.project.session.spot.name))';
            app.base.project.session.spot.bracket_id = zeros(1, numel(app.base.project.session.spot.name))';

            update_Classification_Module_GUI(app)

        end

        % Value changed function: ContainsEditField
        function ContainsEditFieldValueChanged(app, event)
            %ChooseButtonPushed(app, event)

            %             app.selected_string = app.ContainsEditField.Value;
            %
            %             % Initialize an empty array to store the indices of the selected spots
            %             selected_spot_indices = [];
            %
            %             % Iterate through the spot names
            %             for i = 1:length(app.base.project.session.spot.name)
            %                 % Check if the spot name contains the selected string
            %                 if contains(app.base.project.session.spot.name{i}, app.selected_string)
            %                     % If it does, add the index to the selected_spot_indices array
            %                     selected_spot_indices(end+1) = i;
            %                 end
            %             end
            %
            %             % Store the selected spot indices in the app property
            %             app.selected_spot = selected_spot_indices;

        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)
            if isempty(app.base) || isempty(app.base.project)
                delete(app)
                return
            end

            settings.ICP_Base_GUI_update_state = true;

            app.base.project.session.config.module_status.classification = true;


            save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings)

            delete(app)
        end

        % Button pushed function: DriftButton, ImportButton, 
        % IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try
                if any(contains(unique(app.base.project.session.spot.referencematerial), 'Select Reference Material'))...
                        || any(contains(unique(app.base.project.session.spot.type), 'Select Type'))

                    message = "Select Reference Material and Type for all QCRM and CAL";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");
                    disp("Select Reference Material and Type for all QCRM and CAL")
                    return
                end

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to jump modules?";
                    title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end
            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Cell edit callback: ReferenceMaterialTable
        function ReferenceMaterialTableCellEdit(app, event)

            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            %indices = event.Indices;
            newData = event.NewData;

            % Validate New Data. has to be numeric and an integer

            for spot_idx = app.selected_spot'

                if ~isequal(app.base.project.session.spot.bracket_id(spot_idx), 0)
                    app.base.project.session.spot.bracket_id(spot_idx) = newData;
                else
                    app.base.project.session.spot.bracket_id(spot_idx) = 0;
                end

            end

            % old code, not used anymore
            % if isequal(event.PreviousData, 0)
            %     newData = event.PreviousData;
            %     app.ReferenceMaterialTable.Data.(indices(2))(indices(1)) = {newData};
            % else
            %     for spot_idx = app.selected_spot'
            %
            %         if ~isequal(app.base.project.session.spot.bracket_id(spot_idx), 0)
            %             app.base.project.session.spot.bracket_id(spot_idx) = newData;
            %         else
            %             app.base.project.session.spot.bracket_id(spot_idx) = 0;
            %         end
            %
            %     end
            % end


            update_Classification_Module_GUI(app)
        end

        % Button pushed function: GroupbyspotsizeButton
        function GroupbyspotsizeButtonPushed(app, event)


        spotsize = app.base.project.session.meta.laser_parameters.spotsize;
        spot_type = app.base.project.session.spot.type;
        bracket_id = app.base.project.session.spot.bracket_id;

        % Initialize a new bracket_id array
        new_bracket_id = zeros(size(bracket_id));

        % Get unique spotsizes
        unique_spotsizes = unique(spotsize(spot_type == "CAL"));

        % Initialize a counter for each spotsize
        counter = 1;

        % Loop through each unique spotsize
        for i = 1:length(unique_spotsizes)
            current_spotsize = unique_spotsizes(i);

            % Find indices where the spotsize matches and the type is "CAL"
            indices = (spotsize == current_spotsize) & (spot_type == "CAL");

            % Assign sequential bracket_id values for these indices
            new_bracket_id(indices) = counter;

            % Increment the counter
            counter = counter + 1;
        end

        % Update the original bracket_id with the new values
        app.base.project.session.spot.bracket_id = new_bracket_id;

        update_Classification_Module_GUI(app)
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            
            app.selected_string = app.ContainsEditField.Value;

            % Initialize an empty array to store the indices of the selected spots
            selected_spot_indices = [];

            % Iterate through the spot names
            for i = 1:length(app.base.project.session.spot.name)
                % Check if the spot name contains the selected string
                if contains(app.base.project.session.spot.name{i}, app.selected_string)
                    % If it does, add the index to the selected_spot_indices array
                    selected_spot_indices(end+1) = i;
                end
            end

            % Store the selected spot indices in the app property
            app.selected_spot = selected_spot_indices;

            app.ChosenEditField.Value = app.selected_string;
            app.ContainsEditField.Value = '';

            %update_Classification_Module_GUI(app)
        end

        % Menu selected function: ChangereferencematerialfolderMenu
        function ChangereferencematerialfolderMenuSelected(app, event)


            % Open folder selection dialog
            selectedFolder = uigetdir('', 'Select Reference Material Folder');

            % Check if user selected a folder (not cancelled)
            if selectedFolder == 0
                return
            end

            % Store the selected folder path (adjust property name as needed)
            app.base.project.session.config.RM_folder_path = selectedFolder;

            % Call your existing function
            get_RM_Files(app)

            update_Classification_Module_GUI(app)
        end

        % Button pushed function: GetRMfilesButton
        function GetRMfilesButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            % read in the RM Files
            try
                if ~isfield(app.base.project.session.config, 'RM_folder_path')

                    % Check if the main app has the path property set (User Documents)
                    if isprop(app.sourceapp, 'default_RM_path') && ~isempty(app.sourceapp.default_RM_path)
                        app.base.project.session.config.RM_folder_path = app.sourceapp.default_RM_path;

                        % Fallback (Installation Directory)
                    elseif exist(fullfile(app.sourceapp.installpath, 'RM_Files'), 'dir')
                        app.base.project.session.config.RM_folder_path = fullfile(app.sourceapp.installpath, 'RM_Files');
                    end
                end

                get_RM_Files(app)

            catch ME
                DisplayError(ME,'Error reading RM files')
                message={'Error reading RM files'};
                uialert(app.UIFigure,message,' Error');
            end


            update_Classification_Module_GUI(app)
        end

        % Menu selected function: ShowRMvaluesMenu
        function ShowRMvaluesMenuSelected(app, event)
           
            if isempty(app.selected_spot)
                uialert(app.UIFigure, "Click on a spot first", "No spot selected");
                return;
            end

            if ~isequal(numel(app.selected_spot), 1)
                uialert(app.UIFigure, "Only select one spot!", "Too many spots selected");
                return;
            end

            % Selected reference material name for the current spot
            rm_name = string(app.base.project.session.spot.referencematerial(app.selected_spot));

            if isequal(rm_name, "None")
                uialert(app.UIFigure, "Don't select None", "None is not a reference material");
                return;
            end

            % All available RM names
            rm_names = string(app.base.project.session.referencematerials.names);

            % Find matching RM
            idx = find(rm_names == rm_name, 1);

            if isempty(idx)
                uialert(app.UIFigure, "Reference material '" + rm_name + "' was not found.", "RM not found");
                return;
            end

            % Reference values
            rmRefData = app.base.project.session.referencematerials.data{idx};

            % Uncertainty values
            % Adjust this line if your uncertainty is stored under a different field
            rmUncData = app.base.project.session.referencematerials.uncertainty{idx};

            if isempty(rmUncData)

                % Build display cell array with a first label column
                displayData = [
                    [{'µg/g'}, table2cell(rmRefData)]
                    ];

            else

                % Build display cell array with a first label column
                displayData = [
                    [{'µg/g'}, table2cell(rmRefData)];
                    [{'± µg/g'   }, table2cell(rmUncData)]
                    ];

            end

            % Column names
            colNames = [{''}, rmRefData.Properties.VariableNames];

            % Create popup window
            figWidth = min(max(120 * numel(colNames), 700), 1800);
            fig = uifigure( ...
                'Name', rm_name + " - These values can be changed in the .csv files in: " + app.base.project.session.config.RM_folder_path, ...
                'Position', [100 100 figWidth 170]);

            % Create table
            uit = uitable(fig);
            uit.Position = [20 20 figWidth - 40 130];
            uit.Data = displayData;
            uit.ColumnName = colNames;
            uit.RowName = {};
            uit.ColumnEditable = false(1, numel(colNames));
            uit.ColumnWidth = repmat({'auto'}, 1, numel(colNames));


        end

        % Button pushed function: ProceedtoDownholeModuleButton
        function ProceedtoDownholeModuleButtonPushed(app, event)
            
            
            % penalty if the CAL or QCRM selection was not finished
            if any(contains(unique(app.base.project.session.spot.referencematerial), 'Select Reference Material'))...
                    || any(contains(unique(app.base.project.session.spot.type), 'Select Type'))

                message = "Select Reference Material and Type for all QCRM and CAL";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("Select Reference Material and Type for all QCRM and CAL")
                return
            end


            app.base.project.session.config.module_status.classification = true;

            if app.editstatus == true
                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to proceed?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
            else
                selection = 'Yes';
            end

            switch selection
                case 'Yes'

                    progress = uiprogressdlg(app.UIFigure, 'Title', 'Correcting drift...', ...
                        'Message', 'Calculating...', 'Cancelable', 'off', 'Indeterminate','on');

                    settings.ICP_Base_GUI_update_state = true;

                    [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                    Downhole_Correction_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)

                    delete(progress)
                    delete(app)

                case 'Cancel'
                    return
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ChangereferencematerialfolderMenu = uimenu(app.SettingsMenu);
            app.ChangereferencematerialfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangereferencematerialfolderMenuSelected, true);
            app.ChangereferencematerialfolderMenu.Text = 'Change reference material folder';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReferenceMaterialTable = uitable(app.GridLayout);
            app.ReferenceMaterialTable.ColumnName = {'#'; 'Spotname'; 'Type'; 'RM Name'; 'Bracket id'};
            app.ReferenceMaterialTable.RowName = {};
            app.ReferenceMaterialTable.ColumnSortable = [true true true true true];
            app.ReferenceMaterialTable.ColumnEditable = [false false false false true];
            app.ReferenceMaterialTable.CellEditCallback = createCallbackFcn(app, @ReferenceMaterialTableCellEdit, true);
            app.ReferenceMaterialTable.SelectionChangedFcn = createCallbackFcn(app, @ReferenceMaterialTableSelectionChanged, true);
            app.RMListBox = uilistbox(app.GridLayout);
            app.RMListBox.Items = {''};
            app.RMListBox.ValueChangedFcn = createCallbackFcn(app, @RMListBoxValueChanged, true);
            app.RMListBox.Value = '';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalibratorButton = uibutton(app.GridLayout, 'push');
            app.CalibratorButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratorButtonPushed, true);
            app.CalibratorButton.Text = 'Calibrator';
            app.QualityControlReferenceMaterialButton = uibutton(app.GridLayout, 'push');
            app.QualityControlReferenceMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @QualityControlReferenceMaterialButtonPushed, true);
            app.QualityControlReferenceMaterialButton.Text = 'Quality Control Reference Material';
            app.InHouseReferenceMaterialButton = uibutton(app.GridLayout, 'push');
            app.InHouseReferenceMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @InHouseReferenceMaterialButtonPushed, true);
            app.InHouseReferenceMaterialButton.Text = 'In-House Reference Material';
            app.ProceedtoDriftModuleButton = uibutton(app.GridLayout, 'push');
            app.ProceedtoDriftModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDriftModuleButtonPushed, true);
            app.ProceedtoDriftModuleButton.Text = 'Proceed to Drift Module';
            app.UnknownButton = uibutton(app.GridLayout, 'push');
            app.UnknownButton.ButtonPushedFcn = createCallbackFcn(app, @UnknownButtonPushed, true);
            app.UnknownButton.Text = 'Unknown';
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.ContainsEditFieldLabel = uilabel(app.GridLayout);
            app.ContainsEditFieldLabel.Text = 'Contains:';
            app.ContainsEditField = uieditfield(app.GridLayout, 'text');
            app.ContainsEditField.ValueChangedFcn = createCallbackFcn(app, @ContainsEditFieldValueChanged, true);
            app.GroupbyspotsizeButton = uibutton(app.GridLayout, 'push');
            app.GroupbyspotsizeButton.ButtonPushedFcn = createCallbackFcn(app, @GroupbyspotsizeButtonPushed, true);
            app.GroupbyspotsizeButton.Text = 'Group by spotsize';
            app.ChooseButton = uibutton(app.GridLayout, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.ChosenEditField = uieditfield(app.GridLayout, 'text');
            app.ChosenEditField.Editable = 'off';
            app.GetRMfilesButton = uibutton(app.GridLayout, 'push');
            app.GetRMfilesButton.ButtonPushedFcn = createCallbackFcn(app, @GetRMfilesButtonPushed, true);
            app.GetRMfilesButton.Text = 'Get RM files';
            app.ProceedtoDownholeModuleButton = uibutton(app.GridLayout, 'push');
            app.ProceedtoDownholeModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDownholeModuleButtonPushed, true);
            app.ProceedtoDownholeModuleButton.Text = 'Proceed to Downhole Module';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ShowRMvaluesMenu = uimenu(app.ContextMenu2);
            app.ShowRMvaluesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowRMvaluesMenuSelected, true);
            app.ShowRMvaluesMenu.Text = 'Show RM values';
            % Assign app.ContextMenu2
            app.ReferenceMaterialTable.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classification_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end