classdef Downhole_Correction_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BaseMenu                      matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu  matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        FitDropDown                   matlab.ui.control.DropDown
        FitDropDownLabel              matlab.ui.control.Label
        RatioDropDown                 matlab.ui.control.DropDown
        RatioDropDownLabel            matlab.ui.control.Label
        BracketidDropDown             matlab.ui.control.DropDown
        BracketidDropDownLabel        matlab.ui.control.Label
        CalibratorDropDown            matlab.ui.control.DropDown
        CalibratorDropDownLabel       matlab.ui.control.Label
        ProgressPanel                 matlab.ui.container.Panel
        ResultsButton                 matlab.ui.control.Button
        QuantificationButton          matlab.ui.control.Button
        DriftButton                   matlab.ui.control.Button
        ClassificationButton          matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        IntervalButton                matlab.ui.control.Button
        ProceedtoQuantificationModuleButton  matlab.ui.control.Button
        RatioAxes                     matlab.ui.control.UIAxes
        ResidualAxes                  matlab.ui.control.UIAxes
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp

        all_CAL
        selected_CAL
        selected_bracket_id

        selected_ratio
        ratio_matrix
        x_values

    end

    methods (Access = private)

        function update_downhole_module(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");

            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown.Items = validCALs;
            app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketidDropDown.Items = string(uniqueBracketIds);
            app.BracketidDropDown.ItemsData = uniqueBracketIds;


            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown.Value = uniqueBracketIds(1);
            end

            % Check which ratios app.selected_CAL has and which were
            % measured and fill the dropdown with those
            % Find the index of the selected RM in the global loaded structures
            global_rm_idx = find(strcmp(app.base.project.session.referencematerials.names, selectedRM));

            if ~isempty(global_rm_idx)
                % Extract the ratio table for this specific reference material
                rm_ratio_table = app.base.project.session.referencematerials.ratio{global_rm_idx};

                if ~isempty(rm_ratio_table)
                    % Extract the exact ratio strings (e.g., '206Pb/238U')
                    all_ratios = rm_ratio_table.Properties.VariableNames;
                    available_ratios = {};

                    % The measured analytes (e.g., {'Pb206', 'U238'})
                    analytes = app.base.project.session.spot.analytes;

                    % Filter out ratios that don't have measured analytes
                    for k = 1:length(all_ratios)
                        ratio_str = all_ratios{k};
                        parts = split(ratio_str, '/');

                        if length(parts) == 2
                            % Extract numbers and letters using regex
                            % Groups: 1=leading numbers, 2=letters, 3=trailing numbers
                            matchNum = regexp(parts{1}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');
                            matchDen = regexp(parts{2}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');

                            if ~isempty(matchNum) && ~isempty(matchDen)
                                % Reconstruct as {Element}{Mass} format (e.g., 'Pb' + '206')
                                req_num = [matchNum{2}, matchNum{1}, matchNum{3}];
                                req_den = [matchDen{2}, matchDen{1}, matchDen{3}];

                                % Both the numerator and denominator must be in the analytes list
                                if ismember(req_num, analytes) && ismember(req_den, analytes)
                                    available_ratios{end+1} = ratio_str;
                                end
                            end
                        end
                    end

                    if ~isempty(available_ratios)
                        % Update the RatioDropDown Items with ONLY the possible ratios
                        app.RatioDropDown.Items = available_ratios;

                        % Set a default selected ratio
                        if isempty(app.RatioDropDown.Value) || ~ismember(app.RatioDropDown.Value, available_ratios)
                            app.RatioDropDown.Value = available_ratios{1};
                        end
                    end
                end
            end


            plot_all_ratios(app)
            calculate_plot_mean_ratio(app)
            fit_downhole_correction(app)


        end


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            app.DriftButton.Enable = "on";
            app.DriftButton.BackgroundColor = green;
            app.DriftButton.FontWeight = 'bold';

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end


        %%%%%% these functions actually do the downhole correction %%%%%%%%


        function plot_all_ratios(app)

            % Get the chosen ratio from the dropdown
            chosen_ratio = string(app.RatioDropDown.Value);

            % Check if there is actually a valid ratio to plot
            if isempty(chosen_ratio) || chosen_ratio == "No Ratios Available" || chosen_ratio == "No Valid Ratios"
                cla(app.RatioAxes);
                return;
            end

            % Parse the ratio to get the column names in {Element}{Mass} format
            parts = split(chosen_ratio, '/');
            if length(parts) == 2
                matchNum = regexp(parts{1}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');
                matchDen = regexp(parts{2}, '([0-9]*)([a-zA-Z]+)([0-9]*)', 'tokens', 'once');

                if ~isempty(matchNum) && ~isempty(matchDen)
                    % Reconstruct to match analytes (e.g. 'Pb206' and 'U238')
                    req_num = [matchNum{2}, matchNum{1}, matchNum{3}];
                    req_den = [matchDen{2}, matchDen{1}, matchDen{3}];
                else
                    return; % If regex fails, exit
                end
            else
                return; % If there isn't exactly one '/', exit
            end

            % 1. Get the Selected Reference Material name safely
            % Get reference materials only for CAL spots
            unique_names = unique(app.base.project.session.spot.referencematerial);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            unique_names = unique_names(~strcmp(unique_names, 'Select Reference Material'));
            selectedRM = unique_names(app.CalibratorDropDown.Value);

            % 2. Get the Selected Bracket ID safely
            selectedBracket = app.BracketidDropDown.Value;

            % 3. Create logical masks for our 3 criteria
            is_cal = strcmp(app.base.project.session.spot.type, "CAL");
            is_rm = strcmp(app.base.project.session.spot.referencematerial, selectedRM);
            is_bracket = (app.base.project.session.spot.bracket_id == selectedBracket);

            % Get the spot indices that match ALL THREE criteria
            app.all_CAL = find(is_cal & is_rm & is_bracket);

            % If no matching spots exist for this combination, clear axes and exit
            if isempty(app.all_CAL)
                cla(app.RatioAxes);
                return;
            end

            % Get the signal starts and ends of these specific matched spots
            signal_starts = app.base.project.session.spot.signal_starts(app.all_CAL);
            signal_ends = app.base.project.session.spot.signal_ends(app.all_CAL);

            % Store config and data structurally
            datatype = app.base.project.session.config.datatype;
            all_signal_data = app.base.project.session.spot.signal_data;

            % Prepare the axes (clears any previous plots and allows overlays)
            cla(app.RatioAxes);
            hold(app.RatioAxes, 'on');

            % Create an empty cell array to collect signals for averaging
            collected_ratios = cell(length(app.all_CAL), 1);

            % Loop through each matched CAL spot to extract and plot
            for i = 1:length(app.all_CAL)

                cur_start = signal_starts(i);
                cur_end = signal_ends(i);

                try
                    % Extract the specific numerator and denominator columns
                    if strcmp(datatype, 'Continuous')
                        num_signal = all_signal_data{cur_start:cur_end, req_num};
                        den_signal = all_signal_data{cur_start:cur_end, req_den};

                    elseif strcmp(datatype, 'Single')
                        spot_idx = app.all_CAL(i);
                        spot_table = all_signal_data{spot_idx};
                        num_signal = spot_table{cur_start:cur_end, req_num};
                        den_signal = spot_table{cur_start:cur_end, req_den};
                    else
                        continue;
                    end

                    % Calculate the ratio line for this specific spot
                    ratio_signal = num_signal ./ den_signal;

                    % Plot the individual ratio (Optional: color slightly grey/transparent if you want)
                    plot(app.RatioAxes, ratio_signal);

                    % Store in our collection array
                    collected_ratios{i} = ratio_signal;

                catch
                    % If the table indexing fails (e.g., column doesn't exist), skip to the next
                    continue;
                end
            end

            % --- now get the right interval within signal starts and ends ---

            % now get the right signal interval
            % for now I use the minimm offset at the start and end

            if isempty(collected_ratios)
                hold(app.RatioAxes, 'off');
                return;
            end

            % Get the absolute matrix start and end times for the matched CAL spots
            % Note: cell2mat is used to convert from cell array to a standard numeric array
            matrix_starts_abs = cell2mat(app.base.project.session.spot.matrix_1_starts(app.all_CAL));
            matrix_ends_abs = cell2mat(app.base.project.session.spot.matrix_1_ends(app.all_CAL));

            % Get the corresponding absolute signal start times for the same spots
            signal_starts_abs = app.base.project.session.spot.signal_starts(app.all_CAL);

            % Calculate the matrix start and end times *relative* to the start of each signal
            relative_starts = matrix_starts_abs - signal_starts_abs;
            relative_ends = matrix_ends_abs - signal_starts_abs;

            % As per your request:
            % Find the minimum relative start time across all spots
            minimum_start_offset = min(relative_starts);
            xline(app.RatioAxes, minimum_start_offset, LineWidth=2);
            % Find the maximum relative end time across all spots
            maximum_end_offset = max(relative_ends);
            xline(app.RatioAxes, maximum_end_offset, LineWidth=2);

            % Turn hold off so future plots start fresh
            hold(app.RatioAxes, 'off');


            % --- Calculate and plot the average within the specified window ---
            
            % Define the window length
            window_len = maximum_end_offset - minimum_start_offset;

            % Create a matrix of NaNs to hold the data from the specified window for each signal
            % Rows: number of signals, Columns: length of the window
            app.ratio_matrix = NaN(length(collected_ratios), window_len + 1);

            % Extract the data from each signal that falls within the common window
            for j = 1:length(collected_ratios)
                signal = collected_ratios{j};
                
                % Determine the valid intersection between the signal and the window
                start_index = minimum_start_offset + 1;
                end_index = maximum_end_offset;

                % Find the portion of the signal that we can actually use
                actual_start = max(1, start_index);
                actual_end = min(length(signal), end_index);

                if actual_start <= actual_end
                    % Get the segment from the original signal
                    segment = signal(actual_start:actual_end);
                    
                    % Place this segment into the correct position in our matrix
                    % The starting column in windowed_matrix is calculated relative to the window
                    matrix_start_col = actual_start - minimum_start_offset;
                    matrix_end_col = actual_end - minimum_start_offset;
                    
                    app.ratio_matrix(j, matrix_start_col:matrix_end_col) = segment;
                end
            end
                        % Create the x-axis values corresponding to the window
            app.x_values = minimum_start_offset:maximum_end_offset;


        end

        function calculate_plot_mean_ratio(app)


            % Turn hold off so future plots start fresh
            hold(app.RatioAxes, 'on');

            % Compute the column-wise mean of the windowed data, skipping NaNs
            ratio_mean = mean(app.ratio_matrix, 1, 'omitnan');

            % Plot the average as a thick red line only over the calculated window
            plot(app.RatioAxes, app.x_values, ratio_mean, 'r-', 'LineWidth', 3);


            % Turn hold off so future plots start fresh
            hold(app.RatioAxes, 'off');


        end

        % Next things to do:
        % 1. Zoom in to segment checkbox
        % 1.1 Show Mean checkbox
        % 1.2 Log-scale checkbox
        % 2. Fit-stats (R2, formula etc) in panel
        % 3. Residual plot
        % 4. Histogram plot (how the data is spread around the fit?)

        function fit_downhole_correction(app)

            % 1. Create a matrix of X-values that exactly matches the size of ratio_matrix
            num_signals = size(app.ratio_matrix, 1);
            X_matrix = repmat(app.x_values, num_signals, 1);

            % 2. Flatten both arrays into 1D continuous vectors
            x_flat = X_matrix(:);
            y_flat = app.ratio_matrix(:);

            % 3. Filter out the NaNs
            valid_idx = ~isnan(y_flat) & ~isnan(x_flat);
            x_fit = x_flat(valid_idx);
            y_fit = y_flat(valid_idx);

            % 4. Generate smooth X values to plot the resulting curve cleanly
            x_curve = linspace(min(app.x_values), max(app.x_values), 200)';

            % Turn hold on for the axes
            hold(app.RatioAxes, 'on');

            if isequal(app.FitDropDown.Value, 'Linear')
                % Fit a 1st-order polynomial (linear)
                p = polyfit(x_fit, y_fit, 1);

                % Evaluate the linear model
                y_curve = polyval(p, x_curve);

                % Plot the fitted linear curve (e.g., green dashed line)
                plot(app.RatioAxes, x_curve, y_curve, 'g--', 'LineWidth', 3);

            elseif isequal(app.FitDropDown.Value, 'Exponential')
                try
                    % Fit using 'exp1' (Requires Curve Fitting Toolbox)
                    % Model: Y = a * exp(b * X)
                    exp_model = fit(x_fit, y_fit, 'exp1');

                    % Evaluate the exponential model
                    y_curve = exp_model(x_curve);

                    % Plot the fitted exponential curve (e.g., magenta dashed line)
                    plot(app.RatioAxes, x_curve, y_curve, 'm--', 'LineWidth', 3);
                catch ME
                    disp('Exponential fit failed:');
                    disp(ME.message);
                end

            elseif isequal(app.FitDropDown.Value, 'Polynomial (2)')
                % Fit a 2nd-order polynomial
                p = polyfit(x_fit, y_fit, 2);

                % Evaluate the polynomial model
                y_curve = polyval(p, x_curve);

                % Plot the fitted polynomial curve (e.g., blue dashed line)
                plot(app.RatioAxes, x_curve, y_curve, 'b--', 'LineWidth', 3);

            elseif isequal(app.FitDropDown.Value, 'Polynomial (3)')
                % Fit a 3rd-order polynomial (cubic)
                p = polyfit(x_fit, y_fit, 3);

                % Evaluate the polynomial model
                y_curve = polyval(p, x_curve);

                % Plot the fitted polynomial curve (e.g., cyan dashed line)
                plot(app.RatioAxes, x_curve, y_curve, 'c--', 'LineWidth', 3);

            end

            % Turn hold off so future plots start fresh
            hold(app.RatioAxes, 'off');

        end


    end


    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)


            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.downhole = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end
            

            app.base.project.session.config.module_status.downhole = true;

            update_downhole_module(app)
            update_progress_buttons(app)

            app.UIFigure.Tag = sprintf('%d_%d', app.project_id, app.session_id);
        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            app.selected_CAL = app.CalibratorDropDown.Value;

            update_downhole_module(app)
        end

        % Button pushed function: ProceedtoQuantificationModuleButton
        function ProceedtoQuantificationModuleButtonPushed2(app, event)
         update_downhole_module(app)
           plot_all_ratios(app)
            
            % here this is not necessary because nothing can be changed
         % anyways

            % try

                %app.base.project.session.config.module_status.downhole = true;

                % % uiconfirm ask user if finished
                % fig = app.UIFigure;
                % msg = "Do you want to save the data?";
                % title = "ICP-Base";
                % selection = uiconfirm(fig,msg,title, ...
                %     "Options",["Save","Don't save","Cancel"], ...
                %     "DefaultOption",1,"CancelOption",3);
                % switch selection
                %     case 'Save'
%                         [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
%                         Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
%                         delete(app)
            %         case "Don't save"
            %             Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
            %             delete(app)
            %         case 'Cancel'
            %             return
            %     end
            % 
            % catch ME
            %     return
            % end      
            % 
        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.drift = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketidDropDown.Value;

            update_downhole_module(app)
        end

        % Button pushed function: ClassificationButton, ImportButton, 
        % IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try

                % try
                %     % uiconfirm ask user if finished
                %     fig = app.UIFigure;
                %     msg = "Do you want to save the data?";
                %     title = "DIQES";
                %     selection = uiconfirm(fig,msg,title, ...
                %         "Options",["Save","Don't save","Cancel"], ...
                %         "DefaultOption",1,"CancelOption",3);
                %
                %     switch selection
                %         case 'Save'

                [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                    case 'Import'
                        Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Interval'
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Classification'
                        Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Drift'
                        Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Quantification'
                        Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Results'
                        Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                end

                delete(app)

                %         case "Don't save"
                %
                %             switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                %                 case 'Import'
                %                     Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Interval'
                %                     Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Classification'
                %                     Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Drift'
                %                     Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Quantification'
                %                     Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Results'
                %                     Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %             end
                %
                %             delete(app)
                %
                %         case 'Cancel'
                %
                %             return
                %     end
                %
                % catch ME
                %     return
                % end

            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Value changed function: RatioDropDown
        function RatioDropDownValueChanged(app, event)
            %value = app.RatioDropDown.Value;
            update_downhole_module(app)
        end

        % Value changed function: FitDropDown
        function FitDropDownValueChanged(app, event)

            plot_all_ratios(app)
            fit_downhole_correction(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ResidualAxes = uiaxes(app.GridLayout);
            zlabel(app.ResidualAxes, 'Z')
            app.RatioAxes = uiaxes(app.GridLayout);
            xlabel(app.RatioAxes, 'Sweeps')
            ylabel(app.RatioAxes, 'Ratio')
            zlabel(app.RatioAxes, 'Z')
            app.RatioAxes.YScale = 'log';
            app.ProceedtoQuantificationModuleButton = uibutton(app.GridLayout, 'push');
            app.ProceedtoQuantificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoQuantificationModuleButtonPushed2, true);
            app.ProceedtoQuantificationModuleButton.Text = 'Proceed to Quantification Module';
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.CalibratorDropDownLabel = uilabel(app.GridLayout);
            app.CalibratorDropDownLabel.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.GridLayout);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.GridLayout);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.GridLayout);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            app.RatioDropDownLabel = uilabel(app.GridLayout);
            app.RatioDropDownLabel.Text = 'Ratio:';
            app.RatioDropDown = uidropdown(app.GridLayout);
            app.RatioDropDown.Items = {};
            app.RatioDropDown.ValueChangedFcn = createCallbackFcn(app, @RatioDropDownValueChanged, true);
            app.RatioDropDown.Value = {};
            app.FitDropDownLabel = uilabel(app.GridLayout);
            app.FitDropDownLabel.Text = 'Fit:';
            app.FitDropDown = uidropdown(app.GridLayout);
            app.FitDropDown.Items = {'Linear', 'Exponential', 'Polynomial (2)', 'Polynomial (3)'};
            app.FitDropDown.ValueChangedFcn = createCallbackFcn(app, @FitDropDownValueChanged, true);
            app.FitDropDown.Value = 'Linear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Downhole_Correction_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end