classdef Drift_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BaseMenu                      matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu  matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        BracketidDropDown             matlab.ui.control.DropDown
        BracketidDropDownLabel        matlab.ui.control.Label
        CalibratorDropDown            matlab.ui.control.DropDown
        CalibratorDropDownLabel       matlab.ui.control.Label
        ProgressPanel                 matlab.ui.container.Panel
        ResultsButton                 matlab.ui.control.Button
        QuantificationButton          matlab.ui.control.Button
        DriftButton                   matlab.ui.control.Button
        ClassificationButton          matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        IntervalButton                matlab.ui.control.Button
        ProceedtoQuantificationModuleButton  matlab.ui.control.Button
        PreviousAnalyteButton         matlab.ui.control.Button
        PlotLabelsCheckBox            matlab.ui.control.CheckBox
        NextAnalyteButton             matlab.ui.control.Button
        ElementLegendListBox          matlab.ui.control.ListBox
        DriftAxes                     matlab.ui.control.UIAxes
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp

        all_CAL
        selected_CAL
        selected_bracket_id
        mean_analyte_cps_matrix


        selected_analytes

        %%%%%%%%
        initial_sensitivities
        sensitivities_to_fit
        drift_segments
        corrected_values_for_plotting


    end

    methods (Access = private)

        function update_drift_module(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");

            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown.Items = validCALs;
            app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketidDropDown.Items = string(uniqueBracketIds);
            app.BracketidDropDown.ItemsData = uniqueBracketIds;


            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown.Value = uniqueBracketIds(1);
            end

            % populate the elementlistbox
            app.ElementLegendListBox.Items = ['All'; app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}.Properties.VariableNames'];
            app.ElementLegendListBox.ItemsData = 0:numel(app.ElementLegendListBox.Items)-1;

            update_line_plot(app)
            update_scatter_plot(app)

        end


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            app.DriftButton.Enable = "on";
            app.DriftButton.BackgroundColor = green;
            app.DriftButton.FontWeight = 'bold';

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        function DisplayError(app, ME,WhichOperation)
            %DisplayError - Displays detailed error information
            % Description:
            %    This function displays detailed information about an error, including the
            %    time of occurrence, the operation during which the error occurred, and the
            %    stack trace of the error. If the WhichOperation parameter is not provided,
            %    it defaults to '?'. In deployed software the error message is written into the log_file.txt
            % Author: Nils B. Gies
            % Last edit: 2025-01-05
            % Inputs:
            %    ME - The MException object containing error information
            %    WhichOperation - A string describing the operation during which the error occurred
            %
            try
                if not(exist("WhichOperation","var"))
                    disp('Error! Missing ME!')
                    return
                end

                if not(exist("WhichOperation","var"))
                    WhichOperation='?';
                end

                disp(' ')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%   Error   %%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%')
                disp(['%%        ' char(datetime('now'))])
                disp('%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp(['Error occured during: ' WhichOperation])
                % for n=1
                disp('_________________________________________')
                k=1;
                for n=numel(ME.stack):-1:1
                    disp(['%%' num2str(k,'%03.f') '%%'])
                    k=1+k;
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
                disp(['Error in message: ' ME.message])
                disp('  ')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('  ')
                disp('  ')
            catch ME
                disp('Error occured during: DisplayError(app, ME,WhichOperation)')
                for n=numel(ME.stack):-1:1
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
            end
        end


        %%%%%% these functions actually do the drift correction %%%%%%%%

        function calculate_integer_time_points(app)

            % if the rolling average is used, it calcualtes the
            % integer_time_points already in the import module
            if strcmp(app.base.project.session.config.datatype, 'Continuous') && ~isfield(app.base.project.session.spot, 'integer_time_points')

                % If there are no duplicates, use the original signal_starts.
                app.base.project.session.spot.integer_time_points = app.base.project.session.spot.signal_starts;

            elseif strcmp(app.base.project.session.config.datatype, 'Single') && isfield(app.base.project.session.spot, 'acquisition_time')

                % Convert HH:MM format to ITP
                total_minutes = zeros(length(app.base.project.session.spot.acquisition_time), 1);

                for i = 1:length(app.base.project.session.spot.acquisition_time)
                    time_str = app.base.project.session.spot.acquisition_time{i};
                    parts = split(time_str, ':');
                    hours = str2double(parts{1});
                    minutes = str2double(parts{2});
                    total_minutes(i) = hours * 60 + minutes;
                end

                % Calculate relative time points (first point = 1)
                start_time = total_minutes(1);
                relative_times = total_minutes - start_time;
                integer_time_points = relative_times + 1;

                app.base.project.session.spot.integer_time_points = integer_time_points;

            end

        end
        
        function calculate_background_corrected_matrix_intensities(app)

            background_start = app.base.project.session.spot.background_starts;
            background_end = app.base.project.session.spot.background_ends;

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            % case of full measurement input
            if isequal(app.base.project.session.config.datatype, 'Continuous')
                signal = app.base.project.session.spot.signal_data;
            end

            % Initialize bg_corrected_cps_matrix as numeric array
            bg_corr_matrix = single(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)));

            for spot = 1:numel(app.base.project.session.spot.signal_starts)

                % case of single_spot input
                if isequal(app.base.project.session.config.datatype, 'Single')
                    signal = app.base.project.session.spot.signal_data{spot};
                end

                % calculate the average of the background of every UNK
                if ~isempty(background_start{spot}) || ~isempty(background_end{spot})
                    mean_analytes_cps_background = mean(table2array(signal(background_start{spot}:background_end{spot}, 2:end)), 1);

                else
                    continue
                end

                % Collect data from all matrix intervals
                all_matrix_data = [];
                if ~isempty(matrix_1_start{spot}) && ~isempty(matrix_1_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_1_start{spot}:matrix_1_end{spot}, 2:end))];
                end
                if ~isempty(matrix_2_start{spot}) && ~isempty(matrix_2_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_2_start{spot}:matrix_2_end{spot}, 2:end))];
                end
                if ~isempty(matrix_3_start{spot}) && ~isempty(matrix_3_end{spot})
                    all_matrix_data = [all_matrix_data; table2array(signal(matrix_3_start{spot}:matrix_3_end{spot}, 2:end))];
                end

                if ~isempty(all_matrix_data)
                    % Perform background correction
                    matrix_data_corrected = all_matrix_data - mean_analytes_cps_background;
                    matrix_data_corrected = max(matrix_data_corrected, 0);
                    % Calculate the simple mean
                    mean_analytes_cps_matrix = mean(matrix_data_corrected, 1);
                else
                    continue
                end

                bg_corr_matrix(spot, :) = mean_analytes_cps_matrix;

                % Convert to table again
                app.base.project.session.spot.bkgd_corrected_cps =  array2table(bg_corr_matrix, 'VariableNames', signal.Properties.VariableNames(2:end));

            end
        end

        function calculate_sensitivities_of_CALs(app)

            app.initial_sensitivities = single(zeros(numel(app.base.project.session.spot.type), numel(app.base.project.session.spot.analytes)));

            % let all_CAL be all spots which spot.type = all_CAL
            for i = 1:numel(app.base.project.session.spot.type)
                if strcmp(app.base.project.session.spot.type(i), "CAL")
                    app.all_CAL = [app.all_CAL,i];
                end
            end

            for i = 1:numel(app.all_CAL)

                bg_corrected_cps_matrix = app.base.project.session.spot.bkgd_corrected_cps(app.all_CAL(i) , :);

                %%%%%%%% Calculate the sensitivity %%%%%%%%%

                % get the name of the corresponding referencematerial that has been
                % chosen
                referencematerial = app.base.project.session.spot.referencematerial{app.all_CAL(i)};

                % find the index of the corresponding referencematerial
                idx = find(strcmp(app.base.project.session.referencematerials.names, referencematerial));

                if ~isempty(idx)
                    % get the table for the corresponding referencematerial
                    CAL_table = app.base.project.session.referencematerials.data{idx};

                    % get the column headers (elements) of the table
                    CAL_elements = CAL_table.Properties.VariableNames;

                    % Remove numbers from analytes
                    cleaned_analytes = cellfun(@(x) regexprep(x, '\d', ''), app.base.project.session.spot.analytes, 'UniformOutput', false);

                    for j = 1:numel(cleaned_analytes)
                        if ismember(cleaned_analytes{j}, CAL_elements)
                            % Store directly in the matrix instead of cell array
                            app.initial_sensitivities(app.all_CAL(i), j) = bg_corrected_cps_matrix.(j) / CAL_table.(cleaned_analytes{j});
                        end
                    end
                end
            end

            app.base.project.session.spot.cal_sensitivities = array2table(app.initial_sensitivities, "VariableNames", app.base.project.session.spot.analytes);

        end

        function group_CALs_and_bracket_ids(app)
            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");

            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            unique_names = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Get all unique bracket IDs
            all_bracket_ids = unique(app.base.project.session.spot.bracket_id);
            all_bracket_ids = all_bracket_ids(all_bracket_ids ~= 0);

            % Initialize storage structure
            app.sensitivities_to_fit = cell(max(all_bracket_ids), length(unique_names));

            % Iterate through each unique name
            for cal_idx = 1:length(unique_names)
                name = unique_names{cal_idx};
                indices = [];

                % Find indices matching current name
                for j = 1:length(app.base.project.session.spot.referencematerial)
                    if strcmp(app.base.project.session.spot.referencematerial{j}, name) && strcmp(app.base.project.session.spot.type{j}, "CAL")
                        indices(end+1) = j;
                    end
                end

                % Process brackets for this CAL
                bracket_ids = app.base.project.session.spot.bracket_id(indices);
                unique_bracket_ids = unique(bracket_ids);

                %Process each bracket
                for bracket_idx = 1:length(unique_bracket_ids)
                    bracket_id = unique_bracket_ids(bracket_idx);
                    bracket_indices = indices(bracket_ids == bracket_id);

                    % If no intervals for a given CAL were set it has to be
                    % disregarded here, otherwise the sensitivities are all
                    % just 0 which will destroy the average.
                    % Filter out indices where all sensitivities are zero
                    bracket_indices = bracket_indices(any(app.initial_sensitivities(bracket_indices, :), 2));

                    % Skip if no valid indices remain
                    if isempty(bracket_indices)
                        message = "Too many CAL intervals are missing to regress sensitivities. ICP-Base needs a bracket start and end to do the regression between them.";
                        uialert(app.UIFigure,message,"Warning", ...
                            "Icon","warning");
                        disp("Too many CAL intervals are missing for a bracket")
                        return
                    end

                    % Get sensitivities of all bracket_ids that do not have
                    % zero sensitivity
                    sensitivities_to_average = zeros(numel(bracket_indices), size(app.initial_sensitivities, 2));
                    for x = 1:numel(bracket_indices)
                        sensitivities_to_average(x, :) = app.initial_sensitivities(bracket_indices(x), :);
                    end

                    % Process groups within bracket
                    gaps = diff(bracket_indices);
                    group_breaks = find(gaps > 2);
                    group_breaks = [0; group_breaks(:); length(bracket_indices)];

                    average_sensitivities_to_fit = zeros(size(sensitivities_to_average));
                    average_sensitivities_to_fit_idx = zeros(length(bracket_indices), 1);

                    for group_idx = 1:length(group_breaks)-1
                        start_idx = group_breaks(group_idx) + 1;
                        end_idx = group_breaks(group_idx+1);
                        current_group = start_idx:end_idx;

                        % Calculate averages
                        group_sensitivities = sensitivities_to_average(current_group, :);
                        avg_sensitivity = mean(group_sensitivities, 1,'omitnan');
                        current_bracket_indices = bracket_indices(current_group);

                        % Calculate storage position
                        if mod(length(current_group), 2) == 0
                            mid_idx = length(current_group)/2 - 0.5;  % Subtract 0.5 to get the correct middle position
                            storage_idx = current_group(ceil(mid_idx));
                            plot_position = (current_bracket_indices(ceil(mid_idx)) + current_bracket_indices(ceil(mid_idx) + 1)) / 2;
                        else
                            mid_idx = ceil(length(current_group)/2);
                            storage_idx = current_group(mid_idx);
                            plot_position = current_bracket_indices(mid_idx);
                        end

                        average_sensitivities_to_fit(storage_idx, :) = avg_sensitivity;
                        average_sensitivities_to_fit_idx(storage_idx) = plot_position;
                    end

                    % Remove zero rows
                    non_zero_rows = ~all(average_sensitivities_to_fit == 0, 2);
                    average_sensitivities_to_fit = average_sensitivities_to_fit(non_zero_rows, :);
                    average_sensitivities_to_fit_idx = average_sensitivities_to_fit_idx(non_zero_rows);

                    %%%%%%% Store results in app structure %%%%%%%
                    app.sensitivities_to_fit{bracket_id, cal_idx} = struct(...
                        'sensitivities', average_sensitivities_to_fit, ...
                        'indices', average_sensitivities_to_fit_idx);
                end
            end
        end

        function linear_regression(app)
            % Initialize storage for drift coefficients
            num_columns = size(app.sensitivities_to_fit{1,1}.sensitivities, 2);

            for row = 1:size(app.sensitivities_to_fit, 1)
                for col = 1:size(app.sensitivities_to_fit, 2)
                    current_cell = app.sensitivities_to_fit{row, col};

                    % Skip empty cells
                    if isempty(current_cell)
                        continue
                    end
                    % this is triggered if e.g. a bracket CAL spots are
                    % 1,2,3,10,11,12 but spots 1,2,3 all have no Intervals
                    % set.Then it cannot regress
                    if isequal(numel(current_cell.indices), 0) || isequal(numel(current_cell.indices), 1)
                        message = "Drift calculation failed. Maybe too many Calibrator intervals are missing to regress sensitivities.";
                        uialert(app.UIFigure,message,"Warning", ...
                            "Icon","warning");
                        disp("Too many CAL intervals are missing for a bracket")
                        return
                    end

                    % Get x values (signal starts)
                    indices = current_cell.indices;
                    x_values = zeros(size(indices));

                    for idx = 1:length(indices)
                        if mod(indices(idx), 1) == 0
                            % For integer indices, get direct value
                            x_values(idx) = app.base.project.session.spot.integer_time_points(indices(idx));
                        else
                            % For an even number of CALs, interpolate
                            % between them to regress from the middle
                            % point
                            lower_idx = floor(indices(idx));
                            upper_idx = ceil(indices(idx));
                            lower_val = app.base.project.session.spot.integer_time_points(lower_idx);
                            upper_val = app.base.project.session.spot.integer_time_points(upper_idx);
                            x_values(idx) = (lower_val + upper_val) / 2;
                        end
                    end
                    sensitivities = current_cell.sensitivities;

                    % Initialize segment storage
                    num_segments = length(x_values) - 1;
                    slopes = zeros(num_segments, num_columns);
                    intercepts = zeros(num_segments, num_columns);

                    % Fit lines between consecutive points
                    for i = 1:num_segments
                        x_segment = x_values(i:i+1);

                        % Process each sensitivity column
                        for sens_col = 1:size(sensitivities, 2)
                            y_segment = sensitivities(i:i+1, sens_col);
                            coeffs = polyfit(x_segment, y_segment, 1);
                            slopes(i, sens_col) = coeffs(1);
                            intercepts(i, sens_col) = coeffs(2);
                        end
                    end

                    % Store results in app
                    app.drift_segments{row, col} = struct(...
                        'slopes', slopes, ...
                        'intercepts', intercepts, ...
                        'time_ranges', [x_values(1:end-1), x_values(2:end)]);
                end
            end
        end

        function unknown_fitting(app)
            % Get all signal starts
            all_integer_time_points = app.base.project.session.spot.integer_time_points;

            % Process each bracket and CAL combination
            for row = 1:size(app.drift_segments, 1)
                for col = 1:size(app.drift_segments, 2)
                    current_segments = app.drift_segments{row, col};

                    % Skip empty cells
                    if isempty(current_segments)
                        continue
                    end

                    % Initialize storage for this bracket-CAL combination
                    num_spots = length(all_integer_time_points);
                    num_columns = size(current_segments.slopes, 2);
                    corrected_values = single(zeros(num_spots, num_columns));

                    % Process each segment
                    for seg = 1:size(current_segments.time_ranges, 1)
                        % Get segment time range
                        start_time = current_segments.time_ranges(seg, 1);
                        end_time = current_segments.time_ranges(seg, 2);

                        % Find indices within this time range
                        in_range_idx = find(all_integer_time_points >= start_time & ...
                            all_integer_time_points <= end_time);

                        % Get corresponding x-values
                        x_values = all_integer_time_points(in_range_idx);

                        % Calculate corrected sensitivities for each column
                        for col_idx = 1:num_columns
                            slope = current_segments.slopes(seg, col_idx);
                            intercept = current_segments.intercepts(seg, col_idx);

                            % Apply line equation y = kx + d
                            corrected_values(in_range_idx, col_idx) = slope * x_values + intercept;
                        end
                    end

                    app.corrected_values_for_plotting{row, col} = corrected_values;
                    % Store results
                    % Find CAL indices
                    cal_indices = find(strcmp(app.base.project.session.spot.type, "CAL"));
                    % Set CAL rows to zero
                    corrected_values(cal_indices, :) = 0;

                    app.base.project.session.spot.sensitivities{row, col} = array2table(corrected_values, 'VariableNames', app.base.project.session.spot.analytes);

                    % Delete columns that contain only zeros from each table
                    data = table2array(app.base.project.session.spot.sensitivities{row, col});
                    zero_cols = all(data == 0, 1);  % Find columns that are all zero
                    app.base.project.session.spot.sensitivities{row, col}(:, zero_cols) = [];
                    app.base.project.session.spot.sensitivities{row, col}  = app.base.project.session.spot.sensitivities{row, col};

                end
            end
        end


        %%%%%%% functions for GUI plotting %%%%%%%%%%


        function update_scatter_plot(app)
            
            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));


            rm_idx = find(app.base.project.session.spot.referencematerial == validCALs(app.selected_CAL));
            bracket_idx = find(app.base.project.session.spot.bracket_id == app.BracketidDropDown.Value);
            valid_idx = intersect(rm_idx, bracket_idx);

            % Get time points for valid entries
            x_data = app.base.project.session.spot.integer_time_points(valid_idx);

            y_data = app.initial_sensitivities(valid_idx, :);

            % Remove columns with all zeros
            zero_cols = all(y_data == 0, 1);
            y_data = y_data(:, ~zero_cols);

            if isempty(y_data)

                return
            end

            % Extract data for selected analytes
            if ~isempty(app.selected_analytes)
                y_data = y_data(:, app.selected_analytes);
            else 
                y_data = y_data;
            end

            % Plot each analyte separately
            hold(app.DriftAxes, 'on');

            scatter(app.DriftAxes, x_data, y_data, 15, 'o', 'filled');
            
            hold(app.DriftAxes, 'off');

            % Set the x-limits and scale
            xlim(app.DriftAxes, [0 max(app.base.project.session.spot.integer_time_points)+100]);
            ylim(app.DriftAxes, [min(y_data(:))*0.7 max(y_data(:))*1.3]);
            set(app.DriftAxes, 'YScale', 'log');
            set(app.DriftAxes, 'yminorgrid', 'on')
            set(app.DriftAxes, 'MinorGridLIneStyle', '-')
            set(app.DriftAxes, 'MinorGridAlpha', 0.15)

            % % Create data cursor mode object
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on';
            dcm.UpdateFcn = @(obj, event_obj) createDataTip(app, obj, event_obj);

        end

        % originial plot_lines without labels
        % function plot_lines(app)
        %     cla(app.DriftAxes)
        %     hold(app.DriftAxes, 'on');
        %
        %     % Define fixed colors for change ranges
        %     blue = [0.7, 0.7, 1];  % 0-5% change
        %     orange = [1, 0.5, 0]; % 5-10% change
        %     red = [1, 0, 0];     % >10% change
        %
        %     for row = 1:size(app.sensitivities_to_fit, 1)
        %         for col = 1:size(app.sensitivities_to_fit, 2)
        %             current_cell = app.sensitivities_to_fit{row, col};
        %
        %             if isempty(current_cell) || col ~= app.selected_CAL
        %                 continue
        %             end
        %
        %             indices = current_cell.indices;
        %             x_values = zeros(size(indices));
        %
        %             for idx = 1:length(indices)
        %                 if mod(indices(idx), 1) == 0
        %                     x_values(idx) = app.base.project.session.spot.signal_starts(indices(idx));
        %                 else
        %                     lower_idx = floor(indices(idx));
        %                     upper_idx = ceil(indices(idx));
        %                     lower_val = app.base.project.session.spot.signal_starts(lower_idx);
        %                     upper_val = app.base.project.session.spot.signal_starts(upper_idx);
        %                     x_values(idx) = (lower_val + upper_val) / 2;
        %                 end
        %             end
        %
        %             y_values = current_cell.sensitivities;
        %             if ~isempty(app.selected_analytes)
        %                 y_values = y_values(:, app.selected_analytes);
        %             end
        %
        %
        %             for i = 1:size(y_values, 2)
        %                 for j = 1:length(x_values)-1
        %                     % Calculate absolute percentage change
        %                     change = abs((y_values(j+1,i) - y_values(j,i)) / y_values(j,i) * 100);
        %
        %                     % Assign color based on change percentage
        %                     if change <= 5
        %                         color = blue;
        %                     elseif change <= 10
        %                         color = orange;
        %                     else
        %                         color = red;
        %                     end
        %
        %                     % Plot line segment with calculated color
        %                     plot(app.DriftAxes, [x_values(j), x_values(j+1)], ...
        %                         [y_values(j,i), y_values(j+1,i)], ...
        %                         '-', 'Color', color, 'LineWidth', 1.5);
        %
        %                 end
        %             end
        %         end
        %     end
        %         hold(app.DriftAxes, 'off');
        % end

        % with labels - working but slow
        function update_line_plot(app)
            cla(app.DriftAxes)
            hold(app.DriftAxes, 'on');

            blue = [0.7, 0.7, 1];      % 0-5% change
            % turquoise = [0.7, 1, 0.9]; % 5-10% change
            % green = [0.9, 1, 0.3];     % 10-15% change
            orange = [1, 0.5, 0];      % 15-20% change
            red = [1, 0, 0];           % >20% change

            for row = 1:size(app.sensitivities_to_fit, 1)
                for col = 1:size(app.sensitivities_to_fit, 2)
                    current_cell = app.sensitivities_to_fit{row, col};

                    if isempty(current_cell) || col ~= app.selected_CAL
                        continue
                    end
                    if isempty(current_cell) || row ~= app.BracketidDropDown.Value
                        continue
                    end

                    indices = current_cell.indices;
                    x_values = zeros(size(indices));

                    for idx = 1:length(indices)
                        if mod(indices(idx), 1) == 0
                            x_values(idx) = app.base.project.session.spot.integer_time_points(indices(idx));
                        else
                            lower_idx = floor(indices(idx));
                            upper_idx = ceil(indices(idx));
                            lower_val = app.base.project.session.spot.integer_time_points(lower_idx);
                            upper_val = app.base.project.session.spot.integer_time_points(upper_idx);
                            x_values(idx) = (lower_val + upper_val) / 2;
                        end
                    end

                    y_values = current_cell.sensitivities;
                    % Find columns that are not all zeros
                    non_zero_cols = any(y_values ~= 0);
                    % Keep only non-zero columns
                    y_values = y_values(:, non_zero_cols);

                    if ~isempty(app.selected_analytes)
                        y_values = y_values(:, app.selected_analytes);
                    end

                    for i = 1:size(y_values, 2)

                        analytes = app.ElementLegendListBox.Items(2:end);

                        if isempty(app.selected_analytes)
                            %analyte_name = app.base.project.session.spot.analytes{i};
                            analyte_name = analytes{i};
                        else
                            analyte_name = analytes{app.selected_analytes(i)};
                        end

                        for j = 1:length(x_values)-1
                            change = abs((y_values(j+1,i) - y_values(j,i)) / y_values(j,i) * 100);

                            % if change <= 5
                            %     color = blue;
                            % elseif change <= 10
                            %     color = turquoise;
                            % elseif change <= 15
                            %     color = green;
                            % elseif change <= 20
                            %     color = orange;
                            % else
                            %     color = red;
                            % end

                            if change <= 5
                                color = blue;
                            elseif change <= 15
                                color = orange;
                            else
                                color = red;
                            end

                            plot(app.DriftAxes, [x_values(j), x_values(j+1)], ...
                                [y_values(j,i), y_values(j+1,i)], ...
                                '-', 'Color', color, 'LineWidth', 1.5, ...
                                'Tag', analyte_name);

                            % Label each segment
                            mid_x = (x_values(j) + x_values(j+1)) / 2;
                            mid_y = (y_values(j,i) + y_values(j+1,i)) / 2;
                            if app.PlotLabelsCheckBox.Value
                                text(app.DriftAxes, mid_x, mid_y, analyte_name, ...
                                    'HorizontalAlignment', 'center', ...
                                    'VerticalAlignment', 'middle', ...
                                    'FontSize', 8, ...
                                    'BackgroundColor', 'white', ...
                                    'EdgeColor', 'black', ...
                                    'Margin', 1,...
                                    'Clipping','on');
                            end
                        end
                    end
                end
            end
            hold(app.DriftAxes, 'off');
        end


    end


    methods (Access = public)

        %%%%%%% public drift correction %%%%%%%%%
        % basically just a wrapper

        function drift_correction(app)

            calculate_background_corrected_matrix_intensities(app)
            % 1.
            calculate_sensitivities_of_CALs(app)
            % 2.
            group_CALs_and_bracket_ids(app)
            % 3.
            calculate_integer_time_points(app)
            % 4.
            linear_regression(app)
            % 5.
            unknown_fitting(app)

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)


            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.drift = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end
            
            % here the drift_correction is done at startup
            drift_correction(app)

            app.base.project.session.config.module_status.drift = true;

            update_drift_module(app)
            update_progress_buttons(app)
        end

        % Button pushed function: NextAnalyteButton
        function NextAnalyteButtonPushed(app, event)

            if app.selected_analytes == numel(app.ElementLegendListBox.Items)-1
                return
            elseif isempty(app.selected_analytes)
                app.selected_analytes = 1;
                app.ElementLegendListBox.Value = 1;
            else
                app.selected_analytes=app.selected_analytes + 1;
                app.ElementLegendListBox.Value=app.ElementLegendListBox.Value + 1;
            end

            %update the element list and the plot
            update_drift_module(app)

        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            app.selected_CAL = app.CalibratorDropDown.Value;

            update_drift_module(app)
        end

        % Value changed function: ElementLegendListBox
        function ElementLegendListBoxValueChanged(app, event)
            chosen_analyte = app.ElementLegendListBox.Value;

            if chosen_analyte == 0  % When 'All' is selected (Value = 0)
                app.selected_analytes = [];
            else
                app.selected_analytes = chosen_analyte;
            end
            update_drift_module(app)
        end

        % Value changed function: PlotLabelsCheckBox
        function PlotLabelsCheckBoxValueChanged(app, event)

            update_drift_module(app)

        end

        % Button pushed function: PreviousAnalyteButton
        function PreviousAnalyteButtonPushed(app, event)
             if isempty(app.selected_analytes)
                return
             elseif app.selected_analytes == 1
                app.selected_analytes = [];
                app.ElementLegendListBox.Value = 0;
            else
                app.selected_analytes = app.selected_analytes - 1;
                app.ElementLegendListBox.Value = app.ElementLegendListBox.Value - 1;
             end 

            update_drift_module(app)
        end

        % Button pushed function: ProceedtoQuantificationModuleButton
        function ProceedtoQuantificationModuleButtonPushed2(app, event)
         % here this is not necessary because nothing can be changed
         % anyways

            % try

                app.base.project.session.config.module_status.drift = true;

                % % uiconfirm ask user if finished
                % fig = app.UIFigure;
                % msg = "Do you want to save the data?";
                % title = "ICP-Base";
                % selection = uiconfirm(fig,msg,title, ...
                %     "Options",["Save","Don't save","Cancel"], ...
                %     "DefaultOption",1,"CancelOption",3);
                % switch selection
                %     case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)
            %         case "Don't save"
            %             Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
            %             delete(app)
            %         case 'Cancel'
            %             return
            %     end
            % 
            % catch ME
            %     return
            % end      
            % 
        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.drift = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save","Don't save","Cancel"], ...
                    "DefaultOption",1,"CancelOption",3);
                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketidDropDown.Value;

            update_drift_module(app)
        end

        % Button pushed function: ClassificationButton, ImportButton, 
        % IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try

                % try
                %     % uiconfirm ask user if finished
                %     fig = app.UIFigure;
                %     msg = "Do you want to save the data?";
                %     title = "DIQES";
                %     selection = uiconfirm(fig,msg,title, ...
                %         "Options",["Save","Don't save","Cancel"], ...
                %         "DefaultOption",1,"CancelOption",3);
                %
                %     switch selection
                %         case 'Save'

                [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                    case 'Import'
                        Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Interval'
                        Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Classification'
                        Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Drift'
                        Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Quantification'
                        Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                    case 'Results'
                        Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                end

                delete(app)

                %         case "Don't save"
                %
                %             switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                %                 case 'Import'
                %                     Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Interval'
                %                     Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Classification'
                %                     Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Drift'
                %                     Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Quantification'
                %                     Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %                 case 'Results'
                %                     Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                %             end
                %
                %             delete(app)
                %
                %         case 'Cancel'
                %
                %             return
                %     end
                %
                % catch ME
                %     return
                % end

            catch ME
                DisplayError(app, ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DriftAxes = uiaxes(app.GridLayout);
            xlabel(app.DriftAxes, 'Integer Time Points')
            ylabel(app.DriftAxes, 'Sensitivity [cps/µg/g]')
            zlabel(app.DriftAxes, 'Z')
            app.DriftAxes.YScale = 'log';
            app.ElementLegendListBox = uilistbox(app.GridLayout);
            app.ElementLegendListBox.Items = {''};
            app.ElementLegendListBox.Multiselect = 'on';
            app.ElementLegendListBox.ValueChangedFcn = createCallbackFcn(app, @ElementLegendListBoxValueChanged, true);
            app.ElementLegendListBox.Value = {''};
            app.NextAnalyteButton = uibutton(app.GridLayout, 'push');
            app.NextAnalyteButton.ButtonPushedFcn = createCallbackFcn(app, @NextAnalyteButtonPushed, true);
            app.NextAnalyteButton.Text = 'Next Analyte';
            app.PlotLabelsCheckBox = uicheckbox(app.GridLayout);
            app.PlotLabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotLabelsCheckBoxValueChanged, true);
            app.PlotLabelsCheckBox.Text = 'Plot Labels';
            app.PreviousAnalyteButton = uibutton(app.GridLayout, 'push');
            app.PreviousAnalyteButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousAnalyteButtonPushed, true);
            app.PreviousAnalyteButton.Text = 'Previous Analyte';
            app.ProceedtoQuantificationModuleButton = uibutton(app.GridLayout, 'push');
            app.ProceedtoQuantificationModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoQuantificationModuleButtonPushed2, true);
            app.ProceedtoQuantificationModuleButton.Text = 'Proceed to Quantification Module';
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            app.CalibratorDropDownLabel = uilabel(app.GridLayout);
            app.CalibratorDropDownLabel.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.GridLayout);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.GridLayout);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.GridLayout);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drift_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end