classdef Results_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        SavetoBaseandExitMenu           matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        AdjustQCRMAxisFontsizeMenu      matlab.ui.container.Menu
        AdjustQCRMAxisMarkersizeMenu    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        AllResultsTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ShowCommentsCheckBox            matlab.ui.control.CheckBox
        EnddataprocessingButton         matlab.ui.control.Button
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        BracketidDropDown               matlab.ui.control.DropDown
        BracketidDropDownLabel          matlab.ui.control.Label
        CalibratorDropDown              matlab.ui.control.DropDown
        CalibratorDropDownLabel         matlab.ui.control.Label
        CreateOutputTableButton         matlab.ui.control.Button
        HostInclusionDropDown           matlab.ui.control.DropDown
        ResultsTable                    matlab.ui.control.Table
        QualityControlOptionalTab       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        QCReferenceMaterialsTab         matlab.ui.container.Tab
        LogscaleCheckBox_2              matlab.ui.control.CheckBox
        QualityControlReferenceMaterialDropDown  matlab.ui.control.DropDown
        QualityControlReferenceMaterialDropDownLabel  matlab.ui.control.Label
        BracketidDropDown_2             matlab.ui.control.DropDown
        BracketidDropDown_2Label        matlab.ui.control.Label
        CalibratorDropDown_2            matlab.ui.control.DropDown
        CalibratorDropDown_2Label       matlab.ui.control.Label
        GotoCorrectionModuleButton      matlab.ui.control.Button
        QCRMAxes                        matlab.ui.control.UIAxes
        MatrixSensitivityFactorMSFTab   matlab.ui.container.Tab
        ExportTableButton               matlab.ui.control.Button
        ShowLegendCheckBox              matlab.ui.control.CheckBox
        DenominatorListBox              matlab.ui.control.ListBox
        ComparetoDropDown               matlab.ui.control.DropDown
        ComparetoDropDownLabel          matlab.ui.control.Label
        RatioListBox                    matlab.ui.control.ListBox
        SensitivitybasedonDropDown      matlab.ui.control.DropDown
        SensitivitybasedonDropDownLabel  matlab.ui.control.Label
        MSFAxes                         matlab.ui.control.UIAxes
        AddMetadataOptionalTab          matlab.ui.container.Tab
        AddtextcolumnButton             matlab.ui.control.Button
        CustomcolumnnameEditField       matlab.ui.control.EditField
        CustomcolumnnameEditFieldLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        MetadataInputEditField          matlab.ui.control.EditField
        SpotnamecontainsEditField       matlab.ui.control.EditField
        SpotnamecontainsEditFieldLabel  matlab.ui.control.Label
        StringCompareDropDown           matlab.ui.control.DropDown
        ApplyButton                     matlab.ui.control.Button
        AddnumericcolumnButton          matlab.ui.control.Button
        MetadataTable                   matlab.ui.control.Table
        OutputTableTab                  matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        AnalyteCalibratorAssignmentTab  matlab.ui.container.Tab
        UpdateTablePreviewButton        matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CALTree                         matlab.ui.container.CheckBoxTree
        AnalyteTable                    matlab.ui.control.Table
        OutputTablePreview              matlab.ui.container.Tab
        AllvaluesinggunlessexpressedasoxideswtLabel  matlab.ui.control.Label
        SendtoVisModuleButton           matlab.ui.control.Button
        IncludeMetadataCheckBox         matlab.ui.control.CheckBox
        MajorsasOxideswtCheckBox        matlab.ui.control.CheckBox
        RemovemassesfromanalytenameCheckBox  matlab.ui.control.CheckBox
        CreateOutputTableButton_2       matlab.ui.control.Button
        EnddataprocessingButton_2       matlab.ui.control.Button
        HostInclusionDropDown_2         matlab.ui.control.DropDown
        AssignedTable                   matlab.ui.control.Table
        ProgressPanel                   matlab.ui.container.Panel
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        IntervalButton                  matlab.ui.control.Button
        ContextMenu2                    matlab.ui.container.ContextMenu
        OutputallmatrixmassfractionsggMenu  matlab.ui.container.Menu
        OutputallinclusionmassfractionsggMenu  matlab.ui.container.Menu
        OutputallmatrixLODsggMenu       matlab.ui.container.Menu
        OutputallinclusionLODsggMenu    matlab.ui.container.Menu
    end

   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp
        selected_CAL
        selected_bracket_id
        selected_host_inclusion
        selected_QCRM
        selected_analytes

        MSF_ratios

        folderPath

        editstatus = false
    end


    methods (Access = public)


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            app.ResultsButton.Enable = "on";
            app.ResultsButton.BackgroundColor = orange;
            app.ResultsButton.FontWeight = 'bold';

        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        function d = defaultStartDir(app)
            % Prefer the app's working directory, else fall back to pwd
            d = pwd;
            try
                if isfolder(app.sourceapp.workingdirectory)
                    d = app.sourceapp.workingdirectory;
                end
            catch
                % ignore and keep pwd
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_results_module_GUI(app)

            % % Get unique reference materials
            % uniqueCALs = unique(app.base.project.session.spot.referencematerial);
            % validCALs = uniqueCALs(strcmp(uniqueCALs, 'CAL'));
            %
            % % Populate CAL dropdown
            % app.CalibratorDropDown.Items = validCALs;
            % app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown.Items = validCALs;
            app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketidDropDown.Items = string(uniqueBracketIds);
            app.BracketidDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown.Value = uniqueBracketIds(1);
            end

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')
                app.HostInclusionDropDown.Items = ["Host", "Inclusion"];
                app.HostInclusionDropDown_2.Items = app.HostInclusionDropDown.Items;

                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
                app.HostInclusionDropDown_2.ItemsData = app.HostInclusionDropDown.Items;

            elseif isequal(app.base.project.session.config.reductiontype, 'Matrix')
                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';
                app.HostInclusionDropDown.Items = "Host";
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;

                app.HostInclusionDropDown_2.Visible = 'off';
                app.HostInclusionDropDown_2.Items = app.HostInclusionDropDown.Items;
                app.HostInclusionDropDown_2.ItemsData = app.HostInclusionDropDown.Items;
            end

            if isempty(app.selected_host_inclusion)
                app.selected_host_inclusion = app.HostInclusionDropDown.ItemsData{1};
            end

            app.BracketidDropDown.Visible = 'on';
            app.BracketidDropDown.Enable = 'on';
            app.BracketidDropDownLabel.Visible = 'on';
            app.CalibratorDropDown.Visible = 'on';
            app.CalibratorDropDown.Enable = 'on';
            app.CalibratorDropDownLabel.Visible = 'on';

            if isequal(app.ShowDropDown.Value,'CPS')

                app.HostInclusionDropDown.Visible = 'on';
                app.HostInclusionDropDown.Enable = 'on';
                app.BracketidDropDown.Visible = 'off';
                app.BracketidDropDown.Enable = 'off';
                app.BracketidDropDownLabel.Visible = 'off';
                app.CalibratorDropDown.Visible = 'off';
                app.CalibratorDropDown.Enable = 'off';
                app.CalibratorDropDownLabel.Visible = 'off';

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';

            end


            %%%%%%%%%%%%%%%%%%%%
            update_results_table(app)
            update_progress_buttons(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%% Quality Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%% Quality Control Standard Plots
            qcrm_indices = strcmp(app.base.project.session.spot.type, "QCRM");

            % populate the CAL and bracket_id dropdown
            app.CalibratorDropDown_2.Items = app.CalibratorDropDown.Items;
            app.CalibratorDropDown_2.ItemsData = app.CalibratorDropDown.ItemsData;
            app.BracketidDropDown_2.Items = string(uniqueBracketIds);
            app.BracketidDropDown_2.ItemsData = uniqueBracketIds;

            if any(qcrm_indices)
                % Get reference materials only for QCRM spots
                qcrm_materials = app.base.project.session.spot.referencematerial(qcrm_indices);
                % Get unique names, excluding "None" and "Select Reference Material"
                unique_names = unique(qcrm_materials);
                unique_names = unique_names(~strcmp(unique_names, 'None'));
                validQCRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

                % Populate QCRM dropdown
                app.QualityControlReferenceMaterialDropDown.Items = validQCRMs;
                app.QualityControlReferenceMaterialDropDown.ItemsData = app.QualityControlReferenceMaterialDropDown.Items;

                update_qcrm_plot(app)
            else
                app.QualityControlReferenceMaterialDropDown.Visible = false;
            end


            %%% Matrix Sensitivity Factor
            refmat = unique(app.base.project.session.spot.referencematerial);
            app.ComparetoDropDown.Items = refmat(refmat ~= "None");
            app.ComparetoDropDown.ItemsData = app.ComparetoDropDown.Items;

            app.DenominatorListBox.Items = app.base.project.session.spot.analytes;
            app.DenominatorListBox.ItemsData = app.DenominatorListBox.Items;


            update_metadata_table(app)
        end

        function update_results_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            % Add comments if checkbox is checked
            if app.ShowCommentsCheckBox.Value
                if isfield(app.base.project.session.spot, 'comment')
                    comments = app.base.project.session.spot.comment;
                    % Convert nested cells to strings, empty cells become empty strings
                    commentStrings = cellfun(@(x) char(x{1}), comments(~cellfun(@isempty, comments)), 'UniformOutput', false);
                    emptyIdx = cellfun(@isempty, comments);
                    comments(~emptyIdx) = commentStrings;
                    comments(emptyIdx) = {''};
                    T = [T, table(comments, 'VariableNames', {'Comments'})];
                else
                    app.ShowCommentsCheckBox.Value = false;
                    message={'No Comments'};
                    uialert(app.UIFigure,message,' Warning');

                end
            end

            if isequal(app.ShowDropDown.Value,'CPS')

                if isequal(app.selected_host_inclusion,'Host')

                    cps = app.base.project.session.spot.bkgd_corrected_cps;

                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    cps = app.base.project.session.spot.bkgd_corrected_cps_incl;

                end

                % Convert cps to array for logical indexing
                cps_array = table2array(cps);

                % Create mask for rows where at least one value is non-zero
                non_zero_row_mask = any(cps_array ~= 0, 2);

                % Filter T and cps to keep only non-zero rows
                T = T(non_zero_row_mask, :);
                cps = cps(non_zero_row_mask, :);

                % Concatenate filtered cps to T
                T = [T, cps];

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions [µg/g]')

                if isequal(app.selected_host_inclusion,'Host')

                    compositions = app.base.project.session.spot.matrix_composition{app.BracketidDropDown.Value, app.selected_CAL};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};

                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL})

                        lod_table = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};
                        comp_table = app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL};

                        zero_rows = all(lod_table{:,:} == 0, 2); % Find rows where all LODs are zero
                        comp_table{zero_rows, :} = 0;            % Set those composition rows to zero

                        compositions = comp_table;
                        limits_of_detection = lod_table;

                        if all(lod_table{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this CAL.'};
                            uialert(app.UIFigure, message, 'Warning');
                            compositions = table();
                            limits_of_detection = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end

                end

                % Convert tables to arrays for manipulation
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);

                % Create logical mask for values below detection limit
                below_lod = comp_array < lod_array;

                % Create cell array for display values
                display_values = num2cell(comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with original variable names
                display_compositions = array2table(display_values, 'VariableNames', compositions.Properties.VariableNames);

                % Combine with modified composition data
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep only non-zero composition columns, while keeping all metadata columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);               % 1 x nComp
                nMetaCols = width(T) - width(display_compositions);          % dynamic prefix length
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];        % 1 x width(T)

                T = T(:, col_mask);

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions [µg/g] unfiltered')

                if isequal(app.selected_host_inclusion,'Host')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketidDropDown.Value, app.selected_CAL};

                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL})
                        compositions = app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL};

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Combine with composition data
                T = [T, compositions];

                comp_array = table2array(compositions);

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);      % 1 x nComp
                nMetaCols = width(T) - width(compositions);        % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];

                T = T(:, col_mask);


            elseif isequal(app.ShowDropDown.Value,'Majors as Oxides')

                if isequal(app.selected_host_inclusion,'Host')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketidDropDown.Value, app.selected_CAL};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};
                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL})


                        lod_table = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};
                        comp_table = app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL};

                        zero_rows = all(lod_table{:,:} == 0, 2); % Find rows where all LODs are zero
                        comp_table{zero_rows, :} = 0;            % Set those composition rows to zero

                        compositions = comp_table;
                        limits_of_detection = lod_table;

                        if all(lod_table{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this CAL.'};
                            uialert(app.UIFigure, message, 'Warning');
                            compositions = table();
                            limits_of_detection = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Get list of elements to convert to oxides
                element_names = app.base.project.session.oxides.element_names;
                oxide_names = app.base.project.session.oxides.oxide_names;
                considered_major = app.base.project.session.oxides.considered_major;
                oxide_factors = app.base.project.session.oxides.conversion_factors;

                % Get the variable names from compositions table
                var_names = compositions.Properties.VariableNames;
                new_var_names = var_names; % Start with original names, will update for oxides

                % Create new arrays for the oxide values
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);
                oxide_comp_array = comp_array;
                oxide_lod_array = lod_array;

                % Keep track of which columns were converted to oxides
                converted_columns = false(1, length(var_names));

                % For each column in the composition table
                for j = 1:length(var_names)
                    % Extract the base element from the column name (e.g., 'Fe' from 'Fe57')
                    base_element = regexp(var_names{j}, '^[A-Za-z]+', 'match');

                    if ~isempty(base_element)
                        element = base_element{1};

                        % Check if this element is a major element that should be converted to oxide
                        element_idx = find(strcmp(element_names, element));

                        if ~isempty(element_idx) && strcmp(considered_major{element_idx}, 'Yes') && isKey(oxide_factors, element)
                            % Get the conversion factor and oxide name
                            factor = oxide_factors(element);
                            oxide_name = oxide_names{element_idx};

                            % Convert values to oxide
                            oxide_comp_array(:, j) = comp_array(:, j) * factor;
                            oxide_lod_array(:, j) = lod_array(:, j) * factor;

                            % Mark this column as converted
                            converted_columns(j) = true;

                            % Update column name to show oxide
                            isotope_part = regexp(var_names{j}, '\d+', 'match');
                            if ~isempty(isotope_part)
                                new_var_names{j} = [oxide_name,'_', isotope_part{1}];
                            else
                                new_var_names{j} = oxide_name;
                            end
                        end
                    end
                end

                % Convert only oxide columns from µg/g to wt%
                oxide_comp_array(:, converted_columns) = oxide_comp_array(:, converted_columns) / 10000;
                oxide_lod_array(:, converted_columns) = oxide_lod_array(:, converted_columns) / 10000;

                % Create logical mask for values below detection limit
                below_lod = oxide_comp_array < oxide_lod_array;

                % Create cell array for display values
                display_values = num2cell(oxide_comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], oxide_lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with updated variable names for oxides
                display_compositions = array2table(display_values, 'VariableNames', new_var_names);

                % Combine with T as before
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(oxide_comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(oxide_comp_array ~= 0, 1);         % 1 x nComp
                nMetaCols = width(T) - width(display_compositions);         % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];        % 1 x width(T)

                T = T(:, col_mask);

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                sensitivities = app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL};

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(sensitivities) ~= 0, 2);

                % Filter table to keep only non-zero rows
                sensitivities = sensitivities(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, sensitivities];

            elseif isequal(app.ShowDropDown.Value,'CAL Sensitivities')

                cal_sens = app.base.project.session.spot.cal_sensitivities;

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(cal_sens) ~= 0, 2);

                % Filter table to keep only non-zero rows
                cal_sens = cal_sens(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, cal_sens];


            elseif isequal(app.ShowDropDown.Value,'Limits of detection')

                if isequal(app.selected_host_inclusion,'Host')
                    lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};
                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketidDropDown.Value, app.selected_CAL}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL})

                        lods = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketidDropDown.Value, app.selected_CAL};

                        if all(lods{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this CAL.'};
                            uialert(app.UIFigure, message, 'Warning');
                            lods = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Convert LOD table to array for processing
                lod_array = table2array(lods);

                % Create mask for non-zero rows (at least one non-zero in the row)
                non_zero_row_mask = any(lod_array ~= 0, 2);

                % Create mask for non-zero columns (at least one non-zero in the column)
                non_zero_col_mask = any(lod_array ~= 0, 1);

                % Filter rows and columns in T and lods
                T = T(non_zero_row_mask, :);
                lods = lods(non_zero_row_mask, non_zero_col_mask);

                % Add filtered LODs to T
                T = [T, lods];


            elseif isequal(app.ShowDropDown.Value,'Fe2+ and Fe3+')

                if ~isfield(app.base.project.session.spot, 'Fe_2_3')
                    message={'There is no Fe2+ and Fe3+ data'};
                    uialert(app.UIFigure,message,' Warning');
                    app.ShowDropDown.Value = app.ShowDropDown.Items(1);
                    update_results_module_GUI(app)
                    return
                end

                if isequal(app.selected_host_inclusion,'Host')

                    compositions = app.base.project.session.spot.Fe_2_3{app.BracketidDropDown.Value, app.selected_CAL};

                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if isfield(app.base.project.session.spot, 'Fe_2_3_incl') && ~isempty(app.base.project.session.spot.Fe_2_3_incl)
                        compositions = app.base.project.session.spot.Fe_2_3_incl{app.BracketidDropDown.Value, app.selected_CAL};
                    else
                        message={'There is no Fe2+ and Fe3+ data'};
                        uialert(app.UIFigure,message,' Warning');
                        app.ShowDropDown.Value = app.ShowDropDown.Items(1);
                        update_results_module_GUI(app)
                        return
                    end
                end

                % Combine with composition data
                T = [T, compositions];

                comp_array = table2array(compositions);

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);   % 1 x nComp
                nMetaCols = width(T) - width(compositions);     % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];

                T = T(:, col_mask);
            end

            % Update the displayed table
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;


        end

        %%%%%%%%%%%%%%%%%%%%%%%%%% Export %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function export_displayed_table(app, outputpath)
            % Get the currently displayed table
            displayed_table = app.ResultsTable.Data;

            % Get column names
            column_names = app.ResultsTable.ColumnName;

            % Convert to table if it's not already
            if ~istable(displayed_table)
                displayed_table = array2table(displayed_table, 'VariableNames', column_names);
            end

            % Create sheet name based on current selection
            sheet_name = sprintf('%s - ID%d', app.CalibratorDropDown.Items{app.selected_CAL}, app.BracketidDropDown.Value);

            % Write the table to Excel
            writetable(displayed_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        end

        function export_all_matrix_lods(app, outputpath)
            % Get Bracket IDs and CALs
            bracket_ids = app.BracketidDropDown.Items;
            cals = app.CalibratorDropDown.Items;

            data_to_output = app.base.project.session.spot.matrix_limits_of_detection;
            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over CALs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(cals)
                    % Get the table
                    tbl = data_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);

                    % Add spot number and spot names to tbl_clean
                    row_idx = find(nonzero_rows);
                    tbl_clean = addvars(tbl_clean, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', cals{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(tbl_clean, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_inclusion_lods(app, outputpath)
            % Get Bracket IDs and CALs
            bracket_ids = app.BracketidDropDown.Items;
            cals = app.CalibratorDropDown.Items;

            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                data_to_output = app.base.project.session.spot.inclusion_limits_of_detection;
            else
                message={'No Inclusion was quantified'};
                uialert(app.UIFigure,message,' Warning');
                return
            end

            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over CALs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(cals)
                    % Get the table
                    tbl = data_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);

                    % Add spot number and spot names to tbl_clean
                    row_idx = find(nonzero_rows);
                    tbl_clean = addvars(tbl_clean, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', cals{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(tbl_clean, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_matrix_mass_fractions(app, outputpath)
            % Get Bracket IDs and CALs
            bracket_ids = app.BracketidDropDown.Items;
            cals = app.CalibratorDropDown.Items;

            data_to_output = app.base.project.session.spot.matrix_composition;
            lod_to_output = app.base.project.session.spot.matrix_limits_of_detection;
            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over CALs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(cals)
                    % Get the table and LOD table
                    tbl = data_to_output{i, j};
                    lod_tbl = lod_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);
                    lod_clean = lod_tbl(nonzero_rows, nonzero_cols);

                    % Apply LOD formatting
                    comp_array = table2array(tbl_clean);
                    lod_array = table2array(lod_clean);

                    below_lod = comp_array < lod_array;
                    display_values = num2cell(comp_array);
                    display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                    display_tbl = array2table(display_values, 'VariableNames', tbl_clean.Properties.VariableNames);

                    % Add spot number and spot names to display_tbl
                    row_idx = find(nonzero_rows);
                    display_tbl = addvars(display_tbl, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', cals{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(display_tbl, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_inclusion_mass_fractions(app, outputpath)
            % Get Bracket IDs and CALs
            bracket_ids = app.BracketidDropDown.Items;
            cals = app.CalibratorDropDown.Items;

            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                data_to_output = app.base.project.session.spot.inclusion_composition;
                lod_to_output = app.base.project.session.spot.inclusion_limits_of_detection;
            else
                message={'No Inclusion was quantified'};
                uialert(app.UIFigure,message,' Warning');
                return
            end

            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over CALs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(cals)
                    % Get the table and LOD table
                    tbl = data_to_output{i, j};
                    lod_tbl = lod_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);
                    lod_clean = lod_tbl(nonzero_rows, nonzero_cols);

                    % Apply LOD formatting
                    comp_array = table2array(tbl_clean);
                    lod_array = table2array(lod_clean);

                    below_lod = comp_array < lod_array;
                    display_values = num2cell(comp_array);
                    display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                    display_tbl = array2table(display_values, 'VariableNames', tbl_clean.Properties.VariableNames);

                    % Add spot number and spot names to display_tbl
                    row_idx = find(nonzero_rows);
                    display_tbl = addvars(display_tbl, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', cals{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(display_tbl, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% Add Metadata %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function update_metadata_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers
            nRows = numel(spotnames);

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            % Dynamically add columns from the meta struct
            if isfield(app.base.project.session, 'meta') && isstruct(app.base.project.session.meta)
                metaStruct = app.base.project.session.meta;
                metaFields = fieldnames(metaStruct);

                for i = 1:numel(metaFields)
                    fieldName = metaFields{i};
                    fieldValue = metaStruct.(fieldName);

                    % Add field as a column if it's a cell array with the correct number of rows
                    if iscell(fieldValue) && size(fieldValue, 1) == nRows
                        % Ensure the field value is a column vector
                        T.(fieldName) = fieldValue(:);
                    end
                end
            end

            app.MetadataTable.Data = T;
            app.MetadataTable.ColumnName = T.Properties.VariableNames;

                        % Make all columns editable except the first two
            numCols = width(T);
            if numCols > 2
                app.MetadataTable.ColumnEditable = [false, false, true(1, numCols - 2)];
            else
                app.MetadataTable.ColumnEditable = false(1, numCols);
            end

            % Populate the dropdown with editable column headers
            columnNames = T.Properties.VariableNames;
            if numel(columnNames) > 2
                app.StringCompareDropDown.Items = columnNames(3:end);
            else
                app.StringCompareDropDown.Items = {};
            end


        end

        function data = convertToNumericIfPossible(~, input)
            % Tries to convert a string to a number. If it fails, returns the original input.
            [num, status] = str2num(input);
            if status && isscalar(num)
                data = num; % It's a number
            else
                data = input; % It's not a number, keep as is
            end
        end

        function [colType, isDetermined] = getColumnInfo(~, columnData)
            % Determines the data type of a column ('numeric' or 'char').
            % It ignores 'Unknown' and empty values when deciding.

            isDetermined = false;
            colType = 'char'; % Default to char if no data is found

            % Find the first element that is not 'Unknown' and not empty
            firstValidIdx = find(~strcmp(columnData, 'Unknown') & ~cellfun('isempty', columnData), 1);

            if ~isempty(firstValidIdx)
                firstValidValue = columnData{firstValidIdx};
                isDetermined = true;
                if isnumeric(firstValidValue)
                    colType = 'numeric';
                else
                    colType = 'char';
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% Quality Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function update_qcrm_plot(app)

            % get the Indices of the chosen QCRM from the dropdown
            % from spot.referencematerials
            refMaterial = app.QualityControlReferenceMaterialDropDown.Value;

            if isempty(refMaterial)
                return
            end

            indices = find(strcmp(app.base.project.session.spot.referencematerial, refMaterial));

            % Get the corresponding time points
            time_points = app.base.project.session.spot.integer_time_points(indices);


            %%%%%%%%%% calculate the ratios %%%%%%%
            % of the measured values vs the published values

            values = app.base.project.session.spot.matrix_composition{app.BracketidDropDown_2.Value, app.CalibratorDropDown_2.Value}(indices,:);
            lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketidDropDown_2.Value, app.CalibratorDropDown_2.Value}(indices,:);

            if all(table2array(values) == 0, 'all')
                % if the values contain all zeros means that the CAL was
                % not used
                %message={'Primary Reference Material was not used for quantification'};
                %uialert(app.UIFigure,message,' Warning');
                cla(app.QCRMAxes)
                return
            end

            % Find indices of rows and columns that have at least one non-zero value
            nonZeroRows = any(table2array(values) ~= 0, 2);  % check rows
            nonZeroCols = any(table2array(values) ~= 0, 1);     % check columns

            % Keep only rows and columns that have non-zero values
            qcrm_massfractions = values(nonZeroRows, nonZeroCols);

            qcrm_pub_idx = find(strcmp(app.base.project.session.referencematerials.names, refMaterial));
            qcrm_pub_data = app.base.project.session.referencematerials.data{qcrm_pub_idx};
            qcrm_pub_unc = app.base.project.session.referencematerials.uncertainty{qcrm_pub_idx};

            % Get base element names by removing numbers from mass_fraction column names
            base_elements = regexprep(qcrm_massfractions.Properties.VariableNames, '\d+', '');

            % Find matching elements in pub_data
            [~, pub_indices] = ismember(base_elements, qcrm_pub_data.Properties.VariableNames);

            % Get only the columns where we found matches
            valid_matches = pub_indices > 0;
            mass_cols = qcrm_massfractions(:, valid_matches);
            pref_vals = repmat(table2array(qcrm_pub_data(:, pub_indices(valid_matches))), size(mass_cols, 1), 1);

            % Calculate ratios
            ratio_values = table2array(mass_cols) ./ pref_vals;

            % Create results table with original isotope names
            ratios = array2table(ratio_values, 'VariableNames', qcrm_massfractions.Properties.VariableNames(valid_matches));

            % these ratios are that I would need for the secondary standard
            % correction

            %%%%%%%%%% plot that shit %%%%%%%%

            % Get the lods corresponding to the ratio values
            lods_filtered = lods(nonZeroRows, nonZeroCols);
            lods_for_ratios = lods_filtered(:, valid_matches);

            % Create a logical matrix where true means the value is below LOD
            is_below_lod = table2array(mass_cols) < table2array(lods_for_ratios);

            if ~isempty(qcrm_pub_unc)
                pref_vals_for_calc = table2array(qcrm_pub_data(:, pub_indices(valid_matches)));
                max_vals = table2array(qcrm_pub_data(:, pub_indices(valid_matches))) + table2array(qcrm_pub_unc(:, pub_indices(valid_matches)));
                min_vals = table2array(qcrm_pub_data(:, pub_indices(valid_matches))) - table2array(qcrm_pub_unc(:, pub_indices(valid_matches)));

                ratio_max_values = max_vals ./ pref_vals_for_calc;
                ratio_min_values = min_vals ./ pref_vals_for_calc;

                ratio_min_values(isnan(ratio_min_values)) = 0.9;
                ratio_max_values(isnan(ratio_max_values)) = 1.1;

                cla(app.QCRMAxes)
                hold(app.QCRMAxes, 'on');

                % Set y-axis to log scale
                if app.LogscaleCheckBox_2.Value
                    app.QCRMAxes.YScale = 'log';
                else
                    app.QCRMAxes.YScale = 'linear';
                end

                %%%%%%%

                xVals = 1:size(ratio_min_values, 2);

                % Extend x-values for full coverage
                xExtended = [0.5, xVals, max(xVals) + 0.5];

                % Build band bounds
                L50 = min(ratio_min_values*0.01, [], 1);
                U50 = max(ratio_max_values*10,   [], 1);

                L20 = min(ratio_min_values*0.8,  [], 1);
                U20 = max(ratio_max_values*1.2,  [], 1);

                L10 = min(ratio_min_values*0.9,  [], 1);
                U10 = max(ratio_max_values*1.1,  [], 1);

                L00 = min(ratio_min_values,      [], 1);
                U00 = max(ratio_max_values,      [], 1);

                % Extend bounds to edges
                L50_ext = [L50(1), L50, L50(end)];
                U50_ext = [U50(1), U50, U50(end)];
                L20_ext = [L20(1), L20, L20(end)];
                U20_ext = [U20(1), U20, U20(end)];
                L10_ext = [L10(1), L10, L10(end)];
                U10_ext = [U10(1), U10, U10(end)];
                L00_ext = [L00(1), L00, L00(end)];
                U00_ext = [U00(1), U00, U00(end)];

                % Ensure positivity for log-scale
                mask50 = (L50_ext>0) & (U50_ext>0);
                mask20 = (L20_ext>0) & (U20_ext>0);
                mask10 = (L10_ext>0) & (U10_ext>0);
                mask00 = (L00_ext>0) & (U00_ext>0);

                L50_ext(~mask50) = NaN; U50_ext(~mask50) = NaN;
                L20_ext(~mask20) = NaN; U20_ext(~mask20) = NaN;
                L10_ext(~mask10) = NaN; U10_ext(~mask10) = NaN;
                L00_ext(~mask00) = NaN; U00_ext(~mask00) = NaN;

                % Helper to make a band polyshape from lower/upper arrays
                mkPoly = @(lo,hi) polyshape([xExtended, fliplr(xExtended)], [lo, fliplr(hi)], 'Simplify', true);

                % Full bands
                p50 = mkPoly(L50_ext, U50_ext);
                p20 = mkPoly(L20_ext, U20_ext);
                p10 = mkPoly(L10_ext, U10_ext);
                p00 = mkPoly(L00_ext, U00_ext);

                % Non-overlapping rings (outer minus inner)
                r50 = subtract(p50, p20);
                r20 = subtract(p20, p10);
                r10 = subtract(p10, p00);
                r00 = p00;

                % Plot rings (no overlap => no alpha stacking)
                plot(app.QCRMAxes, r50, 'FaceColor', [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.QCRMAxes, r20, 'FaceColor', [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.QCRMAxes, r10, 'FaceColor', [1 1 0.8],   'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.QCRMAxes, r00, 'FaceColor', [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Get current y-limits with padding
                ymin = min(min(table2array(ratios)));
                ymax = max(max(table2array(ratios)));

                % Calculate symmetric limits in log space around y=1 with 10% padding
                maxDistance = max(abs(log10(ymin)), abs(log10(ymax))) * 1.1;
                app.QCRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];

                % Plot horizontal line at y=1
                yline(app.QCRMAxes, 1, 'k--', 'LineWidth', 1);

                % Create a colormap based on time points
                cmap = jet(256);
                min_time = min(time_points);
                max_time = max(time_points);

                % Plot data with colors based on time
                for i = 1:height(ratios)
                    % Normalize time to map to colormap
                    if max_time > min_time
                        norm_time = (time_points(i) - min_time) / (max_time - min_time);
                    else
                        norm_time = 0.5; % All points have the same time
                    end
                    color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
                    point_color = cmap(color_idx, :);

                    x_data = 1:width(ratios);
                    y_data = table2array(ratios(i,:));

                    % Plot line connecting all points
                    plot(app.QCRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);

                    % Separate points based on LOD
                    is_below = is_below_lod(i, :);
                    is_above = ~is_below;

                    % Plot points below LOD in grey
                    if any(is_below)
                        plot(app.QCRMAxes, x_data(is_below), y_data(is_below), 'o', ...
                            'MarkerFaceColor', [0.5 0.5 0.5], ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end

                    % Plot points above LOD with time-based color
                    if any(is_above)
                        plot(app.QCRMAxes, x_data(is_above), y_data(is_above), 'o', ...
                            'MarkerFaceColor', point_color, ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end
                end

                % Add grids with solid lines
                app.QCRMAxes.YGrid = 'on';
                app.QCRMAxes.XGrid = 'on';
                app.QCRMAxes.GridAlpha = 0.15;
                app.QCRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
                app.QCRMAxes.LineWidth = 1;      % Thin lines

                % Customize the plot
                app.QCRMAxes.XTick = 1:width(ratios);
                app.QCRMAxes.XTickLabel = ratios.Properties.VariableNames;
                app.QCRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
                app.QCRMAxes.XLim = [0.5, width(ratios) + 0.5];

                hold(app.QCRMAxes, 'off');


            else
                cla(app.QCRMAxes)
                hold(app.QCRMAxes, 'on');

                % Set y-axis to log scale
                if app.LogscaleCheckBox_2.Value
                    app.QCRMAxes.YScale = 'log';
                else
                    app.QCRMAxes.YScale = 'linear';
                end

                % Add shaded regions around y=1
                xCoord = [0.5 width(ratios)+0.5 width(ratios)+0.5 0.5];

                % Light green region (±10%)
                yCoord = [0.9 0.9 1.1 1.1];
                fill(app.QCRMAxes, xCoord, yCoord, [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Yellow regions (10-20%)
                yCoordLower = [0.8 0.8 0.9 0.9];
                yCoordUpper = [1.1 1.1 1.2 1.2];
                fill(app.QCRMAxes, xCoord, yCoordLower, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                fill(app.QCRMAxes, xCoord, yCoordUpper, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Light orange regions (20-30%)
                yCoordLower = [0.7 0.7 0.8 0.8];
                yCoordUpper = [1.2 1.2 1.3 1.3];
                fill(app.QCRMAxes, xCoord, yCoordLower, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                fill(app.QCRMAxes, xCoord, yCoordUpper, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Light red regions (>30% and <70%)
                yCoordLower = [0.00001 0.00001 0.7 0.7];
                yCoordUpper = [1.3 1.3 10000 10000];
                fill(app.QCRMAxes, xCoord, yCoordLower, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                fill(app.QCRMAxes, xCoord, yCoordUpper, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);

                % Get current y-limits with padding
                ymin = min(min(table2array(ratios)));
                ymax = max(max(table2array(ratios)));

                % Calculate symmetric limits in log space around y=1 with 10% padding
                maxDistance = max(abs(log10(ymin)), abs(log10(ymax))) * 1.1;
                app.QCRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];

                % Plot horizontal line at y=1
                yline(app.QCRMAxes, 1, 'k--', 'LineWidth', 1);

                % Create a colormap based on time points
                cmap = jet(256);
                min_time = min(time_points);
                max_time = max(time_points);

                % Plot data with colors based on time
                for i = 1:height(ratios)
                    % Normalize time to map to colormap
                    if max_time > min_time
                        norm_time = (time_points(i) - min_time) / (max_time - min_time);
                    else
                        norm_time = 0.5; % All points have the same time
                    end
                    color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
                    point_color = cmap(color_idx, :);

                    x_data = 1:width(ratios);
                    y_data = table2array(ratios(i,:));

                    % Plot line connecting all points
                    plot(app.QCRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);

                    % Separate points based on LOD
                    is_below = is_below_lod(i, :);
                    is_above = ~is_below;

                    % Plot points below LOD in grey
                    if any(is_below)
                        plot(app.QCRMAxes, x_data(is_below), y_data(is_below), 'o', ...
                            'MarkerFaceColor', [0.5 0.5 0.5], ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end

                    % Plot points above LOD with time-based color
                    if any(is_above)
                        plot(app.QCRMAxes, x_data(is_above), y_data(is_above), 'o', ...
                            'MarkerFaceColor', point_color, ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end
                end

                % Add grids with solid lines
                app.QCRMAxes.YGrid = 'on';
                app.QCRMAxes.XGrid = 'on';
                app.QCRMAxes.GridAlpha = 0.15;
                app.QCRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
                app.QCRMAxes.LineWidth = 1;      % Thin lines

                % Customize the plot
                app.QCRMAxes.XTick = 1:width(ratios);
                app.QCRMAxes.XTickLabel = ratios.Properties.VariableNames;
                app.QCRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
                app.QCRMAxes.XLim = [0.5, width(ratios) + 0.5];

                hold(app.QCRMAxes, 'off');
            end
        end

        function update_MSF_plot(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers
            ref_mat = app.base.project.session.spot.referencematerial(:);

            if numel(unique(setdiff(ref_mat, "None"))) == 1
                message={'At least two reference materials (CAL or QCRM) are needed for calculation of the MSF.'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end

            % Generate initial table with spot numbers and names
            all_sensitivities = table(spotNumbers, spotnames,ref_mat, 'VariableNames', {'#', 'Spotname', 'Reference Material'});

            initial_sensitivities = zeros(numel(app.base.project.session.spot.type), numel(app.base.project.session.spot.analytes));
            all_RM = [];

            % let all_RM be all spots which spot.type = all_RM
            for i = 1:numel(app.base.project.session.spot.type)
                if strcmp(app.base.project.session.spot.type(i), "CAL") || strcmp(app.base.project.session.spot.type(i), "QCRM")
                    all_RM = [all_RM,i];
                end
            end

            for i = 1:numel(all_RM)

                bg_corrected_cps_matrix = app.base.project.session.spot.bkgd_corrected_cps(all_RM(i) , :);

                %%%%%%%% Calculate the sensitivity %%%%%%%%%

                % get the name of the corresponding referencematerial that has been
                % chosen
                referencematerial = app.base.project.session.spot.referencematerial{all_RM(i)};

                % find the index of the corresponding referencematerial
                idx = find(strcmp(app.base.project.session.referencematerials.names, referencematerial));

                if ~isempty(idx)
                    % get the table for the corresponding referencematerial
                    RM_table = app.base.project.session.referencematerials.data{idx};

                    % get the column headers (elements) of the table
                    CAL_elements = RM_table.Properties.VariableNames;

                    % Remove numbers from analytes
                    cleaned_analytes = cellfun(@(x) regexprep(x, '\d', ''), app.base.project.session.spot.analytes, 'UniformOutput', false);

                    for j = 1:numel(cleaned_analytes)
                        if ismember(cleaned_analytes{j}, CAL_elements)
                            % Store directly in the matrix instead of cell array
                            initial_sensitivities(all_RM(i), j) = bg_corrected_cps_matrix.(j) / RM_table.(cleaned_analytes{j});
                        end
                    end
                end
            end

            initial_sensitivities = array2table(initial_sensitivities, "VariableNames", app.base.project.session.spot.analytes);

            all_sensitivities = [all_sensitivities, initial_sensitivities];

            %%%%%%%%%%%%%

            % Extract unique reference materials
            referenceMaterials = unique(all_sensitivities.("Reference Material"));

            % Initialize a new table to store results
            processed_sensitivities = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = all_sensitivities(strcmp(all_sensitivities.("Reference Material"), refMaterial), :);

                % Calculate min, max, and mean for each element (numeric columns only)
                numericData = rows{:, 4:end}; % Assuming element data starts from the 4th column
                minValues = min(numericData, [], 1);
                maxValues = max(numericData, [], 1);
                meanValues = mean(numericData, 1, 'omitnan');

                % Create a temporary table for the current reference material
                tempTable = table(minValues', maxValues', meanValues', ...
                    'VariableNames', {'Min', 'Max', 'Mean'});
                tempTable.Element = rows.Properties.VariableNames(4:end)'; % Add element names
                tempTable.ReferenceMaterial = repmat({refMaterial}, size(tempTable, 1), 1);

                % Append to the results table
                processed_sensitivities = [processed_sensitivities; tempTable];
            end

            % Reorder columns for better readability
            processed_sensitivities = processed_sensitivities(:, {'ReferenceMaterial', 'Element', 'Min', 'Max', 'Mean'});

            %%%%%%%%%%%%%
            % Identify rows where all numeric columns are zero
            emptyRows = all(processed_sensitivities{:, 3:end} == 0, 2);

            % Remove those rows
            processed_sensitivities = processed_sensitivities(~emptyRows, :);

            denominators = app.DenominatorListBox.Value;
            % Initialize an empty cell array to store the ratios
            ratios = {};
            elements = unique(processed_sensitivities.Element);
            % Generate ratios with the chosen denominators
            for i = 1:length(elements)
                for j = 1:length(denominators)
                    if ~strcmp(elements{i}, denominators{j}) % Avoid self-ratios
                        ratios{end+1} = [elements{i} '/' denominators{j}];
                    end
                end
            end

            app.RatioListBox.Items = ratios;
            app.RatioListBox.ItemsData = app.RatioListBox.Items;

            %%%%%%%%%%%%

            sensitivityType = app.SensitivitybasedonDropDown.Value;

            % Get the selected ratios from the RatioListBox
            selected_ratios = app.RatioListBox.Value;

            if isempty(selected_ratios)
                return
            end

            % Initialize a new table to store the calculated ratios
            calculated_ratios = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = processed_sensitivities(strcmp(processed_sensitivities.ReferenceMaterial, refMaterial), :);

                % Loop through each selected ratio
                for j = 1:length(selected_ratios)
                    % Split the ratio into numerator and denominator
                    elements = split(selected_ratios{j}, '/');
                    numerator = elements{1};
                    denominator = elements{2};

                    % Check if both elements exist in the current reference material
                    if any(strcmp(rows.Element, numerator)) && any(strcmp(rows.Element, denominator))
                        % Get the mean values for the numerator and denominator
                        numeratorValue = rows.(sensitivityType)(strcmp(rows.Element, numerator));
                        denominatorValue = rows.(sensitivityType)(strcmp(rows.Element, denominator));

                        % Calculate the ratio
                        ratioValue = numeratorValue / denominatorValue;

                        % Append the result to the calculated_ratios table
                        calculated_ratios = [calculated_ratios; table({refMaterial}, {selected_ratios{j}}, ratioValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'Value'})];
                    end
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%

            compareToMaterial = app.ComparetoDropDown.Value;

            % Filter rows for the reference material to compare to
            referenceRows = calculated_ratios(strcmp(calculated_ratios.ReferenceMaterial, compareToMaterial), :);

            % Extract the list of ratios available in the reference material
            availableRatios = referenceRows.Ratio;

            % Filter the calculated_ratios table to keep only rows with these ratios
            filtered_ratios = calculated_ratios(ismember(calculated_ratios.Ratio, availableRatios), :);

            if isempty(filtered_ratios)
                return
            end

            %%%%%%
            % Filter rows for the chosen reference material
            referenceRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, compareToMaterial), :);

            % Initialize a new table to store the normalized ratios
            app.MSF_ratios = table();

            % Loop through each reference material in the filtered_ratios table
            uniqueMaterials = unique(filtered_ratios.ReferenceMaterial);

            for i = 1:length(uniqueMaterials)
                currentMaterial = uniqueMaterials{i};

                % Skip the chosen reference material itself
                if strcmp(currentMaterial, compareToMaterial)
                    continue;
                end

                % Filter rows for the current reference material
                currentRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, currentMaterial), :);

                % Initialize a temporary table to store the ratios for this material
                tempTable = table();

                % Loop through each ratio in the current material
                for j = 1:height(currentRows)
                    currentRatio = currentRows.Ratio{j};

                    % Find the corresponding ratio in the chosen reference material
                    refRow = referenceRows(strcmp(referenceRows.Ratio, currentRatio), :);

                    if ~isempty(refRow)
                        % Calculate the normalized ratio
                        normalizedValue = currentRows.Value(j) / refRow.Value;

                        % Append the result to the temporary table
                        tempTable = [tempTable; table({currentMaterial}, {currentRatio}, normalizedValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'NormalizedValue'})];
                    end
                end

                % Append the temporary table to the app.MSF_ratios table
                app.MSF_ratios = [app.MSF_ratios; tempTable];
            end

            app.MSF_ratios = addvars(app.MSF_ratios, ...
                repmat({char(compareToMaterial)}, height(app.MSF_ratios), 1), ...
                'NewVariableNames','ComparedTo', ...
                'After','NormalizedValue');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isempty(app.MSF_ratios)
                return
            end

            % Get the unique ratios and reference materials
            uniqueRatios = unique(app.MSF_ratios.Ratio);
            uniqueMaterials = unique(app.MSF_ratios.ReferenceMaterial);

            % Prepare the figure
            cla(app.MSFAxes); % Clear the axes
            hold(app.MSFAxes, 'on'); % Hold on to plot multiple points

            % Define a colormap for different ratios
            colors = lines(length(uniqueRatios)); % Generate distinct colors for each ratio

            % Loop through each ratio and plot the points
            for i = 1:length(uniqueRatios)
                currentRatio = uniqueRatios{i};

                % Filter rows for the current ratio
                ratioRows = app.MSF_ratios(strcmp(app.MSF_ratios.Ratio, currentRatio), :);

                % Get the x and y values
                xValues = find(ismember(uniqueMaterials, ratioRows.ReferenceMaterial)); % Map materials to x-axis indices
                yValues = ratioRows.NormalizedValue;

                % Plot the points for the current ratio
                scatter(app.MSFAxes, xValues, yValues, 60, 'filled', 'MarkerFaceColor', colors(i, :), ...
                    'DisplayName', currentRatio, 'MarkerEdgeColor', 'black'); % Add legend entry
            end

            % Plot the horizontal dashed line at y = 1 (exclude from legend)
            yline(app.MSFAxes, 1, '--', 'LineWidth', 1.5, 'Color', 'k', 'HandleVisibility', 'off');

            % Set x-axis labels to the reference materials
            set(app.MSFAxes, 'XTick', 1:length(uniqueMaterials), 'XTickLabel', uniqueMaterials, 'TickLabelInterpreter', 'none');

            % Add space between the first/last x-axis entries and the plot borders
            xlim(app.MSFAxes, [0.5, length(uniqueMaterials) + 0.5]);

            % Add labels
            xlabel(app.MSFAxes, 'Reference Materials', 'Interpreter', 'none');
            ylabel(app.MSFAxes, compareToMaterial, 'Interpreter', 'none'); % Use the chosen reference material as the y-axis label

            % Show or delete the legend based on the checkbox value
            if app.ShowLegendCheckBox.Value
                legend(app.MSFAxes, 'show', 'Location', 'northeast', 'Interpreter', 'none'); % Show the legend
            else
                legend(app.MSFAxes, 'off'); % Delete the legend
            end

            % Add a grid
            grid(app.MSFAxes, 'on');

            hold(app.MSFAxes, 'off');


        end

        %%%%%%%%%%%%%%%%%%%%%%%%%% Analyte-Calibrator Assignment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function update_CAL_tree(app)

            % 1. Clear existing tree nodes to prevent duplicates
            delete(app.CALTree.Children);

            % 2. Extract spot data and filter to get validCALs
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);

            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % 3. Get the cell array containing the data tables
            matrix_comp = app.base.project.session.spot.matrix_composition;

            % Get dimensions (rows = brackets, cols = CALs)
            [numRows, ~] = size(matrix_comp);

            % 4. Loop through each valid CAL (corresponds to columns in matrix_comp)
            for c = 1:length(validCALs)
                calName = validCALs(c);

                % Create the top-level parent node for the CAL
                parentNode = uitreenode(app.CALTree, 'Text', calName);

                % Get the variable names (Analytes) from the first table in this column
                % (Assuming all tables have the same column headers)
                firstTable = matrix_comp{1, c};
                if isempty(firstTable)
                    continue; % Safety check in case the cell is completely empty
                end
                allAnalytes = firstTable.Properties.VariableNames;

                % Initialize a logical array to track non-zero columns across all rows (brackets)
                % Starts as all false: [0 0 0 ... 0]
                isValidAnalyteTracker = false(1, length(allAnalytes));

                % 5. Loop through all rows (brackets) for this specific CAL
                for r = 1:numRows
                    calTable = matrix_comp{r, c};

                    % If the table isn't empty, check for non-zero values
                    if ~isempty(calTable)
                        % Convert table to numeric array for faster evaluation
                        % (Using Variables or table2array gets the underlying numbers)
                        numericData = calTable{:, :};

                        % Check which columns in THIS table have at least one non-zero
                        hasNonZero = any(numericData ~= 0, 1);

                        % Accumulate the logical tracker (Logical OR)
                        % If it was true in a previous row, or true in this row, it stays true
                        isValidAnalyteTracker = isValidAnalyteTracker | hasNonZero;
                    end
                end

                % 6. Filter the analytes using our accumulated tracker
                validAnalytes = allAnalytes(isValidAnalyteTracker);

                % 7. Create the child nodes for each valid Analyte under the current CAL node
                for j = 1:length(validAnalytes)
                    uitreenode(parentNode, 'Text', validAnalytes{j});
                end
            end

            if ~isfield(app.base.project.session.spot, 'analyte_calibrator_assignment')
                % Put this in your startup function or data import function:
                analytes = app.base.project.session.spot.analytes;
                numAnalytes = length(analytes);
                emptyCalibrators = repmat({''}, numAnalytes, 1);
                tableData = [analytes, emptyCalibrators];

                % Update UI
                app.AnalyteTable.Data = tableData;

                % STORE IT IN THE SPOT DATA
                app.base.project.session.spot.analyte_calibrator_assignment = tableData;

                % Call the color update ONCE after initializing so they start orange
                update_analyte_table(app);
            else
                  % 1. Load the saved table data to the UI
                tableData = app.base.project.session.spot.analyte_calibrator_assignment;
                app.AnalyteTable.Data = tableData;
                update_analyte_table(app);
                update_assigned_table(app)

                % 2. Re-check the nodes in the tree based on the saved table
                nodesToRecheck = [];
                allParentNodes = app.CALTree.Children; % The CAL nodes we just created
                
                % Loop through the saved table assignments
                for r = 1:size(tableData, 1)
                    analyteName = char(tableData{r, 1});
                    calName = char(tableData{r, 2});
                    
                    % If a calibrator was assigned to this analyte
                    if ~isempty(calName) && strlength(string(calName)) > 0
                        
                        % Search for the matching Parent Node (the CAL)
                        for p = 1:numel(allParentNodes)
                            if strcmp(char(allParentNodes(p).Text), calName)
                                
                                % Search for the matching Child Node (the Analyte)
                                childNodes = allParentNodes(p).Children;
                                for c = 1:numel(childNodes)
                                    if strcmp(char(childNodes(c).Text), analyteName)
                                        % Match found! Add it to our list of nodes to check
                                        nodesToRecheck = [nodesToRecheck, childNodes(c)];
                                        break; % Found the matching analyte, stop searching children
                                    end
                                end
                                break; % Found the matching CAL, stop searching parents
                            end
                        end
                    end
                end
                
                % 3. Apply the checked state to the tree!
                % (Doing this programmatically does NOT trigger the Changed callback,
                % which is good because our table is already populated).
                app.CALTree.CheckedNodes = nodesToRecheck;
            end

        end

        function update_analyte_table(app)

            % 1. Get current data from the AnalyteTable (do NOT overwrite it here)
            analyteData = app.AnalyteTable.Data;
            nRowsAnalyte = size(analyteData, 1);

            % 2. Initialize all to white
            bg_analyte = ones(nRowsAnalyte, 3);

            % 3. Find logical indices for empty cells in the second column
            idx_empty = cellfun(@isempty, analyteData(:, 2));
            idx_not_empty = ~idx_empty;

            % 4. Apply colors
            % Orange for empty
            if any(idx_empty)
                bg_analyte(idx_empty, :) = repmat([1, 229/255, 204/255], sum(idx_empty), 1);
            end

            % Green for not empty
            if any(idx_not_empty)
                bg_analyte(idx_not_empty, :) = repmat([204/255, 1, 204/255], sum(idx_not_empty), 1);
            end

            % 5. Set background color on the table
            app.AnalyteTable.BackgroundColor = bg_analyte;

        end

        function update_assigned_table(app)

            % 1. Extract base metadata
            spotnames = app.base.project.session.spot.name(:);
            numSpots = numel(spotnames);

            % Extract unique Bracket IDs (which correspond to the rows in matrix_composition)
            rawBracketIDs = app.base.project.session.spot.bracket_id(:);
            uniqueBracketIds = unique(rawBracketIDs);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
            numBrackets = length(uniqueBracketIds);

            if numBrackets == 0
                % Fallback safety check if no brackets are defined yet
                numBrackets = 1;
                uniqueBracketIds = 1;
            end

            % 2. Preallocate the stacked Setup Columns
            rep_spotNumbers = repmat((1:numSpots)', numBrackets, 1);
            rep_spotNumbers = rep_spotNumbers(:);

            rep_spotnames = repmat(spotnames(:), numBrackets, 1);
            rep_spotnames = rep_spotnames(:);

            rep_bracketIDs = repelem(uniqueBracketIds(:), numSpots);
            % Convert the numeric bracket IDs to a cell array of character vectors (strings)
            rep_bracketIDs = cellstr(string(rep_bracketIDs(:)));

            % --- OXIDE SETUP ---
            is_oxide_mode = app.MajorsasOxideswtCheckBox.Value;

            if is_oxide_mode
                element_names = app.base.project.session.oxides.element_names;
                oxide_names = app.base.project.session.oxides.oxide_names;
                considered_major = app.base.project.session.oxides.considered_major;
                oxide_factors = app.base.project.session.oxides.conversion_factors;
            end

            % Generate the base table frame (3 metadata columns)
            T = table(rep_spotNumbers, rep_spotnames, rep_bracketIDs, ...
                'VariableNames', {'#', 'Spotname', 'Bracket_id'});

            % 3. Retrieve assignments
            if ~isfield(app.base.project.session.spot, 'analyte_calibrator_assignment') || ...
                    isempty(app.base.project.session.spot.analyte_calibrator_assignment)
                app.AssignedTable.Data = T;
                return;
            end

            assignments = app.base.project.session.spot.analyte_calibrator_assignment;

            % Get valid CAL list
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            comp_array_all = []; % To store raw numeric columns for zero-filtering later

            % 4. Build data columns from assignments
            for i = 1:size(assignments, 1)
                analyteName = char(assignments{i, 1});
                calName = char(assignments{i, 2});

                if isempty(calName) || strlength(string(calName)) == 0
                    continue;
                end

                calIdx = find(strcmp(validCALs, calName));
                if isempty(calIdx)
                    continue;
                end

                comp_col = zeros(numSpots * numBrackets, 1);
                lod_col = zeros(numSpots * numBrackets, 1);

                for b = 1:numBrackets
                    if isequal(app.selected_host_inclusion, 'Host')
                        compTab = app.base.project.session.spot.matrix_composition{b, calIdx};
                        lodTab = app.base.project.session.spot.matrix_limits_of_detection{b, calIdx};
                    elseif isequal(app.selected_host_inclusion, 'Inclusion')
                        compTab = app.base.project.session.spot.inclusion_composition{b, calIdx};
                        lodTab = app.base.project.session.spot.inclusion_limits_of_detection{b, calIdx};
                    end

                    startIdx = (b - 1) * numSpots + 1;
                    endIdx = b * numSpots;

                    if ~isempty(compTab) && ismember(analyteName, compTab.Properties.VariableNames)
                        comp_col(startIdx:endIdx) = compTab.(analyteName);
                     
                        if ismember(analyteName, lodTab.Properties.VariableNames)
                            lod_col(startIdx:endIdx) = lodTab.(analyteName);
                        end

                    end
                end

                % --- OXIDE MATH CONVERSION ---
                if is_oxide_mode
                    base_element = regexp(analyteName, '^[A-Z][a-z]?', 'match', 'once');
                    if ~isempty(base_element)
                        element_idx = find(strcmp(element_names, base_element));
                        if ~isempty(element_idx) && strcmp(considered_major{element_idx}, 'Yes') && isKey(oxide_factors, base_element)
                            factor = oxide_factors(base_element);

                            % Multiply by factor and divide by 10000 iteratively
                            comp_col = comp_col .* factor ./ 10000;
                            lod_col = lod_col .* factor ./ 10000;
                        end
                    end
                end

                % Apply limits of detection string formatting over the stacked column
                below_lod = comp_col < lod_col;
                display_values = num2cell(comp_col);

                if any(below_lod)
                    display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_col(below_lod), 'UniformOutput', false);
                end

                T.(analyteName) = display_values;
                comp_array_all = [comp_array_all, comp_col];
            end

            % 5. Clean up: Strip out the empty "zero" spots globally
            if ~isempty(comp_array_all)
                non_zero_row_mask = any(comp_array_all ~= 0, 2);
                T = T(non_zero_row_mask, :);
                comp_array_all = comp_array_all(non_zero_row_mask, :);

                if ~isempty(T)
                    non_zero_comp_cols = any(comp_array_all ~= 0, 1);
                    col_mask = [true(1, 3), non_zero_comp_cols];
                    T = T(:, col_mask);
                end
            end

            % Track how many metadata columns we have (Starts with 3: #, Spotname, Bracket_id)
            num_meta_cols = 3;

            % --- 5.1 OPTIONAL METADATA INJECTION ---
            if app.IncludeMetadataCheckBox.Value && isfield(app.base.project.session, 'meta')
                metaStruct = app.base.project.session.meta;
                metaFields = fieldnames(metaStruct);

                % Reference spot numbers from our filtered table
                current_spot_numbers = T.('#');

                % Create a temporary table just for the successfully extracted metadata
                metaTableToInsert = table();

                for f = 1:length(metaFields)
                    fieldName = metaFields{f};
                    fieldData = metaStruct.(fieldName);

                    % We only want dynamically-sized cell arrays that match the TOTAL spot count
                    if iscell(fieldData) && length(fieldData) == numSpots
                        % Extract exclusively the data for the spots currently sitting in our table!
                        selectedData = fieldData(current_spot_numbers);
                        metaTableToInsert.(fieldName) = selectedData;
                    end
                end

                % If we found valid metadata, insert it into the main table
                if width(metaTableToInsert) > 0
                    % Slice main table open, insert metaTable, and glue back together
                    T = [T(:, 1:3), metaTableToInsert, T(:, 4:end)];
                    % Update our offset so Isotope Filtering and Formatting know where the chemistry actually starts!
                    num_meta_cols = 3 + width(metaTableToInsert);
                end
            end

            % --- 6. OPTIONAL ISOTOPE FILTERING ---
            if app.RemovemassesfromanalytenameCheckBox.Value && width(T) > num_meta_cols
                colNames = T.Properties.VariableNames;
                analyteCols = colNames(num_meta_cols + 1:end);

                preferred_isotopes = app.base.project.session.oxides.preferred_isotopes;
                prefStruct = struct();
                if ~isempty(preferred_isotopes)
                    toks_pref = regexp(preferred_isotopes, '^([A-Z][a-z]?)(\d+)$', 'tokens', 'once');
                    for idx = 1:numel(toks_pref)
                        if ~isempty(toks_pref{idx})
                            prefStruct.(toks_pref{idx}{1}) = toks_pref{idx}{2};
                        end
                    end
                end

                toks_cols = regexp(analyteCols, '^([A-Z][a-z]?)(\d+)(.*)$', 'tokens', 'once');
                keepColsLogical = true(1, width(T));
                newColNames = colNames;
                seenGroups = struct();

                for idx = 1:numel(analyteCols)
                    cIdx = idx + num_meta_cols; % Dynamic Offset applied here
                    t = toks_cols{idx};
                    if isempty(t), continue; end

                    elem = t{1}; mass = t{2}; suffix = t{3};
                    groupKey = matlab.lang.makeValidName([elem, '_', suffix]);
                    isPref = isfield(prefStruct, elem) && strcmp(prefStruct.(elem), mass);

                    if ~isfield(seenGroups, groupKey)
                        seenGroups.(groupKey) = struct('bestColIdx', cIdx, 'isPref', isPref, 'newBaseName', [elem, suffix]);
                    else
                        prev = seenGroups.(groupKey);
                        if isPref && ~prev.isPref
                            keepColsLogical(prev.bestColIdx) = false;
                            seenGroups.(groupKey).bestColIdx = cIdx;
                            seenGroups.(groupKey).isPref = true;
                        else
                            keepColsLogical(cIdx) = false;
                        end
                    end
                end

                groupKeys = fieldnames(seenGroups);
                for k = 1:numel(groupKeys)
                    info = seenGroups.(groupKeys{k});
                    if keepColsLogical(info.bestColIdx)
                        newColNames{info.bestColIdx} = info.newBaseName;
                    end
                end

                T = T(:, keepColsLogical);
                T.Properties.VariableNames = newColNames(keepColsLogical);
            end

            % --- 7. OXIDE RENAMING ---
            if is_oxide_mode && width(T) > num_meta_cols
                colNames = T.Properties.VariableNames;
                for c = (num_meta_cols + 1):length(colNames)
                    base_element = regexp(colNames{c}, '^[A-Z][a-z]?', 'match', 'once');

                    if ~isempty(base_element)
                        element_idx = find(strcmp(element_names, base_element));

                        if ~isempty(element_idx) && strcmp(considered_major{element_idx}, 'Yes') && isKey(oxide_factors, base_element)
                            oxide_name = oxide_names{element_idx};

                            isotope_part = regexp(colNames{c}, '\d+', 'match', 'once');

                            if ~isempty(isotope_part)
                                T.Properties.VariableNames{c} = [oxide_name, '_', isotope_part];
                            else
                                T.Properties.VariableNames{c} = oxide_name;
                            end
                        end
                    end
                end
            end

            % --- 8. Replace 0 with empty for clean UI display ---
            for col = (num_meta_cols + 1):width(T)
                colData = T{:, col};
                isZeroCell = cellfun(@(x) isnumeric(x) && isscalar(x) && x == 0, colData);
                colData(isZeroCell) = {[]};
                T{:, col} = colData;
            end

            % 9. Push formatted table to the UI
            app.AssignedTable.Data = T;
            app.AssignedTable.ColumnName = T.Properties.VariableNames;

        end

        function export_displayed_assigned_table(app, outputpath)
            % Get the currently displayed table
            displayed_table = app.AssignedTable.Data;


            % Write the table to Excel
            writetable(displayed_table, outputpath);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)

            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.results = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.results = true;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);


            if ~isfield(app.base.project.session.meta, 'sample') || isempty(app.base.project.session.meta.sample)
                app.base.project.session.meta.sample = repmat({''}, size(app.base.project.session.spot.name));
            end

            if ~isfield(app.base.project.session.meta, 'material') || isempty(app.base.project.session.meta.material)
                app.base.project.session.meta.material = repmat({''}, size(app.base.project.session.spot.name));
            end
            if ~isfield(app.base.project.session.meta, 'location') || isempty(app.base.project.session.meta.location)
                app.base.project.session.meta.location = repmat({''}, size(app.base.project.session.spot.name));
            end
            
            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end

            update_results_module_GUI(app)
            update_CAL_tree(app)
        end

        % Menu selected function: SavetoBaseandExitMenu
        function SavetoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end

        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            app.CalibratorDropDown.Value = event.Source.Value;
            app.selected_CAL = app.CalibratorDropDown.Value;

            app.CalibratorDropDown_2.Value = event.Source.Value;

            update_results_module_GUI(app)


        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)

            app.BracketidDropDown.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketidDropDown.Value;

            app.BracketidDropDown_2.Value = event.Source.Value;

            update_results_module_GUI(app)

        end

        % Button pushed function: EnddataprocessingButton
        function EnddataprocessingButtonPushed(app, event)
            app.base.project.session.config.module_status.results = true;

            settings.ICP_Base_GUI_update_state = true;
            [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
            delete(app)


        end

        % Value changed function: HostInclusionDropDown
        function HostInclusionDropDownValueChanged(app, event)
            
            app.HostInclusionDropDown.Value = event.Source.Value;
            app.selected_host_inclusion = app.HostInclusionDropDown.Value;

            update_results_module_GUI(app)
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton
        function QuantificationButtonPushed(app, event)
            try

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to jump modules?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Yes","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Button pushed function: CreateOutputTableButton
        function CreateOutputTableButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            %outputpath = 'C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\Test Data\Output Test\Gana_Rossa.xlsx';
            %app.base.project.session.outputpath = fullfile(pathname, filename);
            export_displayed_table(app, outputpath)


        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            update_results_module_GUI(app)

        end

        % Button pushed function: GotoCorrectionModuleButton
        function GotoCorrectionModuleButtonPushed(app, event)

            app.base.project.session.config.module_status.results = true;

            Correction_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
            %delete(app)


        end

        % Callback function
        function RefreshMenuSelected(app, event)
            assign_sample_to_spot(app)
            assign_material_to_spot(app)
            assign_location_to_spot(app)
            
            update_metadata_table(app)
        end

        % Value changed function: QualityControlReferenceMaterialDropDown
        function QualityControlReferenceMaterialDropDownValueChanged(app, event)
            app.selected_QCRM = app.QualityControlReferenceMaterialDropDown.Value;

            update_qcrm_plot(app)
        end

        % Value changed function: CalibratorDropDown_2
        function CalibratorDropDown_2ValueChanged(app, event)

            app.CalibratorDropDown.Value = event.Source.Value;
            app.selected_CAL = app.CalibratorDropDown.Value;

            app.CalibratorDropDown_2.Value = event.Source.Value;

            update_qcrm_plot(app)
        end

        % Value changed function: BracketidDropDown_2
        function BracketidDropDown_2ValueChanged(app, event)
            app.BracketidDropDown.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketidDropDown.Value;

            app.BracketidDropDown_2.Value = event.Source.Value;

            update_qcrm_plot(app)
        end

        % Value changed function: ShowCommentsCheckBox
        function ShowCommentsCheckBoxValueChanged(app, event)
            %value = app.ShowCommentsCheckBox.Value;
            update_results_table(app)
        end

        % Callback function
        function CalculateButtonPushed(app, event)

            update_MSF_plot(app)

        end

        % Value changed function: ComparetoDropDown
        function ComparetoDropDownValueChanged(app, event)
            update_MSF_plot(app)

        end

        % Value changed function: SensitivitybasedonDropDown
        function SensitivitybasedonDropDownValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: RatioListBox
        function RatioListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: DenominatorListBox
        function DenominatorListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Menu selected function: OutputallmatrixmassfractionsggMenu
        function OutputallmatrixmassfractionsggMenuSelected(app, event)

            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_matrix_mass_fractions(app,outputpath)


        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)


            if isempty(app.MSF_ratios)
                message={'No Data to be exported'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end


            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            writetable(app.MSF_ratios, outputpath, 'Sheet', 'MSF ratios', 'WriteRowNames', false);


        end

        % Value changed function: LogscaleCheckBox_2
        function LogscaleCheckBox_2ValueChanged(app, event)

            update_qcrm_plot(app)
        end

        % Menu selected function: OutputallinclusionmassfractionsggMenu
        function OutputallinclusionmassfractionsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_inclusion_mass_fractions(app, outputpath);
        end

        % Menu selected function: OutputallmatrixLODsggMenu
        function OutputallmatrixLODsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_matrix_lods(app,outputpath)
        end

        % Menu selected function: OutputallinclusionLODsggMenu
        function OutputallinclusionLODsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_inclusion_lods(app,outputpath)
        end

        % Button pushed function: AddnumericcolumnButton
        function AddnumericcolumnButtonPushed(app, event)
              
                
            % Get the new column name from the Edit Field
            requestedName = strtrim(app.CustomcolumnnameEditField.Value);

            % Check if the name is empty
            if requestedName == ""
                uialert(app.UIFigure, 'Column name cannot be empty.', 'Invalid Name');
                return;
            end

            % Current table data
            T = app.MetadataTable.Data;
            if ~istable(T)
                uialert(app.UIFigure, 'MetadataTable.Data is not a table.', 'Type Error');
                return;
            end

            % Ensure meta struct exists
            if ~isfield(app.base.project.session, 'meta') || ~isstruct(app.base.project.session.meta)
                app.base.project.session.meta = struct();
            end

            % Make valid + unique field/column name (check both table and meta fields)
            validName = matlab.lang.makeValidName(requestedName);
            usedNames = [T.Properties.VariableNames, fieldnames(app.base.project.session.meta)'];
            validName = matlab.lang.makeUniqueStrings(validName, usedNames);

            % Row count
            nRows = height(T);

            % 1) Add field in app structure with empty cells for a neutral state
            app.base.project.session.meta.(validName) = cell(nRows, 1);

            % 2) Add column in UI table from structure field
            T.(validName) = app.base.project.session.meta.(validName);

            % Update table
            app.MetadataTable.Data = T;
            app.MetadataTable.ColumnName = T.Properties.VariableNames;

            % Make all columns editable except the first two
            numCols = width(T);
            if numCols > 2
                app.MetadataTable.ColumnEditable = [false, false, true(1, numCols - 2)];
            else
                app.MetadataTable.ColumnEditable = false(1, numCols);
            end

            % Clear the input field after adding the column
            app.CustomcolumnnameEditField.Value = '';

            update_metadata_table(app)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

             
             selected_string = app.SpotnamecontainsEditField.Value;

           % ... existing code for finding selected_spot_indices ...
           if contains(selected_string, '&&')
               substrings = strtrim(strsplit(selected_string, '&&'));
               selected_spot_indices = find(all(contains(app.base.project.session.spot.name, substrings), 2));
           elseif contains(selected_string, '||')
               substrings = strtrim(strsplit(selected_string, '||'));
               selected_spot_indices = find(any(contains(app.base.project.session.spot.name, substrings), 2));
           else
                selected_spot_indices = find(contains(app.base.project.session.spot.name, selected_string));
           end

           if isempty(selected_spot_indices)
               return; % No spots matched, do nothing
           end

           % --- Validation Logic ---
            targetColumn = app.StringCompareDropDown.Value;
            inputValue = app.MetadataInputEditField.Value;

            % Get the column's current data
            currentColumnData = app.base.project.session.meta.(targetColumn);
            [colType, isDetermined] = app.getColumnInfo(currentColumnData);

            % Try to convert input to numeric
            [num, isNumericInput] = str2num(inputValue);

            if isDetermined
                if strcmp(colType, 'numeric') && ~isNumericInput
                    uialert(app.UIFigure, ['Column "' targetColumn '" requires a numeric value.'], 'Invalid Data Type');
                    return
                elseif strcmp(colType, 'char') && isNumericInput
                    uialert(app.UIFigure, ['Column "' targetColumn '" requires a text value.'], 'Invalid Data Type');
                    return
                end
            end

            % --- Process and Store Data ---
            if isNumericInput
                processedValue = num;
            else
                processedValue = inputValue;
            end
            
            % Update the underlying data structure
            app.base.project.session.meta.(targetColumn)(selected_spot_indices) = {processedValue};

            update_metadata_table(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            % Check if the meta struct exists and is a struct
            if isfield(app.base.project.session, 'meta') && isstruct(app.base.project.session.meta)

                metaStruct = app.base.project.session.meta;
                metaFields = fieldnames(metaStruct);

                % Get the number of spots, which corresponds to the required number of rows for a column
                nRows = numel(app.base.project.session.spot.name);

                % Iterate through each metadata field
                for i = 1:numel(metaFields)
                    fieldName = metaFields{i};
                    fieldValue = metaStruct.(fieldName);

                    % Reset the field only if it's a cell array with the same number of rows as spots
                    % This mirrors the logic in update_metadata_table
                    if iscell(fieldValue) && size(fieldValue, 1) == nRows
                        % Use dynamic field naming to reset the field
                        app.base.project.session.meta.(fieldName) = repmat({'Unknown'}, nRows, 1);
                    end
                end
            end

            % Refresh the table to reflect the reset values
            update_metadata_table(app);

        end

        % Cell edit callback: MetadataTable
        function MetadataTableCellEdit(app, event)
         indices = event.Indices;
            newData = event.NewData;
            
            % Get the name of the column that was edited
            col = indices(2);
            columnName = app.MetadataTable.ColumnName{col};

            % Get the entire column's current data from the source
            currentColumnData = app.base.project.session.meta.(columnName);

            % Determine the column's type from existing data
            [colType, isDetermined] = app.getColumnInfo(currentColumnData);

            % --- Safely determine if the input is numeric ---
            if isnumeric(newData)
                isNumericInput = true;
                num = newData;
            elseif ischar(newData) || isstring(newData)
                [num, isNumericInput] = str2num(newData);
            else
                isNumericInput = false;
                num = NaN; 
            end

            % --- Determine if the new data is valid for the column ---
            isValid = false;
            if ~isDetermined
                % If column type isn't set yet, any input is valid
                isValid = true;
            elseif strcmp(colType, 'numeric') && isNumericInput
                % Numeric column, numeric input
                isValid = true;
            elseif strcmp(colType, 'char') && ~isNumericInput
                % Text column, text input
                isValid = true;
            end

            % --- Main Logic: Only proceed if data is valid ---
            if isValid
                % --- Process and Store Data ---
                if isNumericInput
                    processedData = num;
                else
                    processedData = newData;
                end

                % Get target rows for the update
                row = indices(1);
                selectedRows = unique(app.MetadataTable.Selection(:, 1));
                if isempty(selectedRows) || ~any(selectedRows == row)
                    targetRows = row;
                else
                    targetRows = selectedRows;
                end
                
                % Update the underlying data structure
                app.base.project.session.meta.(columnName)(targetRows) = {processedData};
                
                % Refresh the table to show changes
                update_metadata_table(app);

            else
                % --- Handle Invalid Data ---
                % Determine the error message
                if strcmp(colType, 'numeric')
                    msg = ['Column "' columnName '" requires a numeric value.'];
                else
                    msg = ['Column "' columnName '" requires a text value.'];
                end
                uialert(app.UIFigure, msg, 'Invalid Data Type');
                
                % Revert the change in the UI table. This is the ONLY action.
                app.MetadataTable.Data{indices(1), indices(2)} = {event.PreviousData};
            end
        end

        % Menu selected function: AdjustQCRMAxisFontsizeMenu
        function AdjustQCRMAxisFontsizeMenuSelected(app, event)
            fontsizevalue = inputdlg('Enter Fontsize:','Set Fontsize', 1,{'12'});
            figure(app.UIFigure);

            if ~isempty(fontsizevalue)
                fontsizevalue=unique(str2num(fontsizevalue{1})); %#ok<ST2NM>
            else
                return
            end

            if isnumeric(fontsizevalue) && not(isnan(fontsizevalue)) && not(isinf(fontsizevalue))

                app.QCRMAxes.FontSize = fontsizevalue;

            end


        end

        % Menu selected function: AdjustQCRMAxisMarkersizeMenu
        function AdjustQCRMAxisMarkersizeMenuSelected(app, event)
            markersizevalue = inputdlg('Enter Marker Size:', 'Set Marker Size', 1, {'6'});
            figure(app.UIFigure);

            if ~isempty(markersizevalue)
                markersizevalue = unique(str2num(markersizevalue{1})); %#ok<ST2NM>
            else
                return
            end

            if isnumeric(markersizevalue) && ~isnan(markersizevalue) && ~isinf(markersizevalue) && markersizevalue > 0
                % Find all line objects in the axes that have markers
                lines = findobj(app.QCRMAxes, 'Type', 'line', 'Marker', 'o');

                % Set the marker size for all found line objects
                for i = 1:length(lines)
                    lines(i).MarkerSize = markersizevalue;
                end
            end
        end

        % Button pushed function: AddtextcolumnButton
        function AddtextcolumnButtonPushed(app, event)
            
     
            % Get the new column name from the Edit Field
            requestedName = strtrim(app.CustomcolumnnameEditField.Value);

            % Check if the name is empty
            if requestedName == ""
                uialert(app.UIFigure, 'Column name cannot be empty.', 'Invalid Name');
                return;
            end

            % Current table data
            T = app.MetadataTable.Data;
            if ~istable(T)
                uialert(app.UIFigure, 'MetadataTable.Data is not a table.', 'Type Error');
                return;
            end

            % Ensure meta struct exists
            if ~isfield(app.base.project.session, 'meta') || ~isstruct(app.base.project.session.meta)
                app.base.project.session.meta = struct();
            end

            % Make valid + unique field/column name (check both table and meta fields)
            validName = matlab.lang.makeValidName(requestedName);
            usedNames = [T.Properties.VariableNames, fieldnames(app.base.project.session.meta)'];
            validName = matlab.lang.makeUniqueStrings(validName, usedNames);

            % Row count
            nRows = height(T);

            % 1) Add field in app structure with empty cells for a neutral state
            app.base.project.session.meta.(validName) = repmat({''}, nRows, 1);

            % 2) Add column in UI table from structure field
            T.(validName) = app.base.project.session.meta.(validName);

            % Update table
            app.MetadataTable.Data = T;
            app.MetadataTable.ColumnName = T.Properties.VariableNames;

            % Make all columns editable except the first two
            numCols = width(T);
            if numCols > 2
                app.MetadataTable.ColumnEditable = [false, false, true(1, numCols - 2)];
            else
                app.MetadataTable.ColumnEditable = false(1, numCols);
            end

            % Clear the input field after adding the column
            app.CustomcolumnnameEditField.Value = '';

            update_metadata_table(app)

            
        end

        % Callback function: CALTree
        function CALTreeCheckedNodesChanged(app, event)
%             % 1. Get all currently checked nodes
%             checkedNodes = app.CALTree.CheckedNodes;
% 
%             % 2. Retrieve the OLD data from the Analyte Table BEFORE making changes
%             oldTableData = app.AnalyteTable.Data;
% 
%             % 3. Categorize checked nodes into NEW and OLD
%             newChildNodes = [];
%             oldChildNodes = [];
% 
%             for i = 1:numel(checkedNodes)
%                 node = checkedNodes(i);
% 
%                 % We only care about leaf (child) nodes.
%                 if isprop(node.Parent, 'Text')
%                     analyteName = char(node.Text);
%                     calName = char(node.Parent.Text);
% 
%                     % Find row index in the old table
%                     rowIdx = find(strcmp(oldTableData(:, 1), analyteName));
% 
%                     % If this exact CAL was ALREADY assigned to this analyte previously, it's an "old" check
%                     if ~isempty(rowIdx) && strcmp(oldTableData{rowIdx, 2}, calName)
%                         oldChildNodes = [oldChildNodes, node];
%                     else
%                         % Otherwise, the user JUST checked this box (or checked its parent)
%                         newChildNodes = [newChildNodes, node];
%                     end
%                 end
%             end
% 
%             % 4. Resolve Conflicts: NEW selections "steal" the assignment from OLD selections
%             finalChildNodes = newChildNodes; % We always keep what was just selected
% 
%             % Extract the names of the newly selected analytes
%             newAnalytes = strings(1, numel(newChildNodes));
%             for i = 1:numel(newChildNodes)
%                 newAnalytes(i) = string(newChildNodes(i).Text);
%             end
% 
%             % Check the old nodes to see if they got overridden
%             for i = 1:numel(oldChildNodes)
%                 analyteName = string(oldChildNodes(i).Text);
%                 % If this old node's analyte is NOT in our newly clicked ones, it gets to stay
%                 if ~ismember(analyteName, newAnalytes)
%                     finalChildNodes = [finalChildNodes, oldChildNodes(i)];
%                 end
%             end
% 
%             % 5. Uncheck the overridden nodes in the UI!
%             % Note: We only pass the child nodes. MATLAB will automatically put
%             % the Parent nodes into "partially checked" or "fully checked" states for us.
%             app.CALTree.CheckedNodes = finalChildNodes;
% 
%             % 6. Rebuild the Table Data based on our final list
%             tableData = oldTableData;
%             tableData(:, 2) = {''}; % Wipe calibrator column clean
% 
%             for i = 1:numel(finalChildNodes)
%                 node = finalChildNodes(i);
%                 analyteName = char(node.Text);
%                 calName = char(node.Parent.Text);
% 
%                 rowIdx = find(strcmp(tableData(:, 1), analyteName));
%                 if ~isempty(rowIdx)
%                     tableData{rowIdx, 2} = calName; % We know it's always one-to-one now!
%                 end
%             end
% 
%             % 7. Write the updated data back and format colors
%             app.AnalyteTable.Data = tableData;
%             update_analyte_table(app);
% 
%             % STORE IT IN THE SPOT DATA
%             app.base.project.session.spot.analyte_calibrator_assignment = tableData;
% 
% %             update_assigned_table(app)

% 1. Get ONLY the leaf nodes (analytes) from the Tree
            checkedNodes = app.CALTree.CheckedNodes;
            leafNodes = [];
            for i = 1:numel(checkedNodes)
                if isprop(checkedNodes(i).Parent, 'Text')
                    leafNodes = [leafNodes, checkedNodes(i)];
                end
            end
            
            % 2. Grab the known "truth" from the table BEFORE making changes
            tableData = app.AnalyteTable.Data;
            
            % 3. Group the currently checked nodes by their Analyte name
            newChecks = containers.Map(); 
            for i = 1:numel(leafNodes)
                aName = char(leafNodes(i).Text);
                if ~isKey(newChecks, aName)
                    newChecks(aName) = [];
                end
                newChecks(aName) = [newChecks(aName), leafNodes(i)];
            end
            
            % 4. Resolve conflicts and build final nodes list
            finalNodes = [];
            
            analyteKeys = keys(newChecks);
            for k = 1:numel(analyteKeys)
                aName = analyteKeys{k};
                nodesForAnalyte = newChecks(aName);
                
                if numel(nodesForAnalyte) == 1
                    % No conflict. 1-to-1 rule is respected.
                    finalNodes = [finalNodes, nodesForAnalyte(1)];
                else
                    % CONFLICT: The user checked multiple CALs for this same analyte!
                    % Find out which one was ALREADY assigned (the "old" one)
                    rowIdx = find(strcmp(tableData(:, 1), aName));
                    oldCal = '';
                    if ~isempty(rowIdx)
                        oldCal = char(tableData{rowIdx, 2});
                    end
                    
                    % Keep the node that is genuinely NEW (i.e. NOT the oldCal)
                    keptNode = [];
                    for n = 1:numel(nodesForAnalyte)
                        if ~strcmp(char(nodesForAnalyte(n).Parent.Text), oldCal)
                            keptNode = nodesForAnalyte(n);
                            break;
                        end
                    end
                    
                    % Fallback (safety)
                    if isempty(keptNode)
                        keptNode = nodesForAnalyte(1);
                    end
                    
                    finalNodes = [finalNodes, keptNode];
                end
            end
            
            % 5. Overwrite the UI! This instantly unchecks any conflicting duplicates.
            app.CALTree.CheckedNodes = finalNodes;
            
            % 6. Clean the table completely, and re-assign based on the final winners
            tableData(:, 2) = {''}; 
            for i = 1:numel(finalNodes)
                aName = char(finalNodes(i).Text);
                cName = char(finalNodes(i).Parent.Text);
                
                rowIdx = find(strcmp(tableData(:, 1), aName));
                if ~isempty(rowIdx)
                    tableData{rowIdx, 2} = cName;
                end
            end
            
            % 7. Instantly save the state so queued clicks don't break the logic!
            app.AnalyteTable.Data = tableData;
            app.base.project.session.spot.analyte_calibrator_assignment = tableData;
            
            % 8. Update colors and build the large data table
            update_analyte_table(app);
       
        
        end

        % Callback function
        function DevButtonPushed(app, event)
            update_CAL_tree(app)
        end

        % Value changed function: HostInclusionDropDown_2
        function HostInclusionDropDown_2ValueChanged(app, event)

            %app.HostInclusionDropDown.Value = event.Source.Value;
            app.HostInclusionDropDown_2.Value = event.Source.Value;
            app.selected_host_inclusion = app.HostInclusionDropDown_2.Value;

            update_assigned_table(app)
        end

        % Button pushed function: CreateOutputTableButton_2
        function CreateOutputTableButton_2Pushed(app, event)
                        [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file', defaultStartDir(app));
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_displayed_assigned_table(app, outputpath)

        end

        % Value changed function: RemovemassesfromanalytenameCheckBox
        function RemovemassesfromanalytenameCheckBoxValueChanged(app, event)

            update_assigned_table(app);

        end

        % Value changed function: MajorsasOxideswtCheckBox
        function MajorsasOxideswtCheckBoxValueChanged(app, event)

            
            update_assigned_table(app)
        end

        % Value changed function: IncludeMetadataCheckBox
        function IncludeMetadataCheckBoxValueChanged(app, event)
            update_assigned_table(app)
        end

        % Button pushed function: SendtoVisModuleButton
        function SendtoVisModuleButtonPushed(app, event)
            

            progress = uiprogressdlg(app.UIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Sending table to VisModule', 'Cancelable', 'off', 'Indeterminate','on');

            if isempty(app.AssignedTable.Data)
                message = "Proceeding to the VisModule failed.";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            VisModule(app.AssignedTable.Data, app.sourceapp);
            delete(progress)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)


            app.base.project.session.config.module_status.results = true;

            settings.ICP_Base_GUI_update_state = true;
            [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
            delete(app)

        end

        % Button pushed function: EnddataprocessingButton_2
        function EnddataprocessingButton_2Pushed(app, event)
                       app.base.project.session.config.module_status.results = true;

            settings.ICP_Base_GUI_update_state = true;
            [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
            delete(app)


        end

        % Button pushed function: UpdateTablePreviewButton
        function UpdateTablePreviewButtonPushed(app, event)
            
            update_assigned_table(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SavetoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SavetoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBaseandExitMenuSelected, true);
            app.SavetoBaseandExitMenu.Text = 'Save to Base and Exit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AdjustQCRMAxisFontsizeMenu = uimenu(app.SettingsMenu);
            app.AdjustQCRMAxisFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustQCRMAxisFontsizeMenuSelected, true);
            app.AdjustQCRMAxisFontsizeMenu.Text = 'Adjust QCRM Axis Fontsize';
            app.AdjustQCRMAxisMarkersizeMenu = uimenu(app.SettingsMenu);
            app.AdjustQCRMAxisMarkersizeMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustQCRMAxisMarkersizeMenuSelected, true);
            app.AdjustQCRMAxisMarkersizeMenu.Text = 'Adjust QCRM Axis Markersize';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.Text = 'Results';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AllResultsTab = uitab(app.TabGroup);
            app.AllResultsTab.Title = 'All Results';
            app.GridLayout2 = uigridlayout(app.AllResultsTab);
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.HostInclusionDropDown = uidropdown(app.GridLayout2);
            app.HostInclusionDropDown.Items = {};
            app.HostInclusionDropDown.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDownValueChanged, true);
            app.HostInclusionDropDown.Value = {};
            app.CreateOutputTableButton = uibutton(app.GridLayout2, 'push');
            app.CreateOutputTableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputTableButtonPushed, true);
            app.CreateOutputTableButton.Text = 'Create Output Table';
            app.CalibratorDropDownLabel = uilabel(app.GridLayout2);
            app.CalibratorDropDownLabel.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.GridLayout2);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.GridLayout2);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.GridLayout2);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            app.ShowDropDownLabel = uilabel(app.GridLayout2);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.GridLayout2);
            app.ShowDropDown.Items = {'Mass Fractions [µg/g]', 'Mass Fractions [µg/g] unfiltered', 'Sensitivities', 'CAL Sensitivities', 'CPS', 'Limits of detection', 'Majors as Oxides', 'Fe2+ and Fe3+'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Mass Fractions [µg/g]';
            app.EnddataprocessingButton = uibutton(app.GridLayout2, 'push');
            app.EnddataprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @EnddataprocessingButtonPushed, true);
            app.EnddataprocessingButton.Text = 'End data processing';
            app.ShowCommentsCheckBox = uicheckbox(app.GridLayout2);
            app.ShowCommentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCommentsCheckBoxValueChanged, true);
            app.ShowCommentsCheckBox.Text = 'Show Comments';
            app.QualityControlOptionalTab = uitab(app.TabGroup);
            app.QualityControlOptionalTab.Title = 'Quality Control (Optional)';
            app.TabGroup2 = uitabgroup(app.QualityControlOptionalTab);
            app.QCReferenceMaterialsTab = uitab(app.TabGroup2);
            app.QCReferenceMaterialsTab.Title = 'QC Reference Materials';
            app.QCRMAxes = uiaxes(app.QCReferenceMaterialsTab);
            xlabel(app.QCRMAxes, 'Analyte')
            ylabel(app.QCRMAxes, 'Measured Values/Accepted Values')
            zlabel(app.QCRMAxes, 'Z')
            app.QCRMAxes.XLim = [0 1];
            app.GotoCorrectionModuleButton = uibutton(app.QCReferenceMaterialsTab, 'push');
            app.GotoCorrectionModuleButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCorrectionModuleButtonPushed, true);
            app.GotoCorrectionModuleButton.Enable = 'off';
            app.GotoCorrectionModuleButton.Text = 'Go to Correction Module';
            app.CalibratorDropDown_2Label = uilabel(app.QCReferenceMaterialsTab);
            app.CalibratorDropDown_2Label.Text = 'Calibrator';
            app.CalibratorDropDown_2 = uidropdown(app.QCReferenceMaterialsTab);
            app.CalibratorDropDown_2.Items = {};
            app.CalibratorDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDown_2ValueChanged, true);
            app.CalibratorDropDown_2.Value = {};
            app.BracketidDropDown_2Label = uilabel(app.QCReferenceMaterialsTab);
            app.BracketidDropDown_2Label.Text = 'Bracket id';
            app.BracketidDropDown_2 = uidropdown(app.QCReferenceMaterialsTab);
            app.BracketidDropDown_2.Items = {};
            app.BracketidDropDown_2.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDown_2ValueChanged, true);
            app.BracketidDropDown_2.Value = {};
            app.QualityControlReferenceMaterialDropDownLabel = uilabel(app.QCReferenceMaterialsTab);
            app.QualityControlReferenceMaterialDropDownLabel.Text = 'Quality Control Reference Material';
            app.QualityControlReferenceMaterialDropDown = uidropdown(app.QCReferenceMaterialsTab);
            app.QualityControlReferenceMaterialDropDown.Items = {};
            app.QualityControlReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @QualityControlReferenceMaterialDropDownValueChanged, true);
            app.QualityControlReferenceMaterialDropDown.Value = {};
            app.LogscaleCheckBox_2 = uicheckbox(app.QCReferenceMaterialsTab);
            app.LogscaleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBox_2ValueChanged, true);
            app.LogscaleCheckBox_2.Text = 'Log-scale';
            app.MatrixSensitivityFactorMSFTab = uitab(app.TabGroup2);
            app.MatrixSensitivityFactorMSFTab.Title = 'Matrix Sensitivity Factor (MSF)';
            app.MSFAxes = uiaxes(app.MatrixSensitivityFactorMSFTab);
            xlabel(app.MSFAxes, 'X')
            ylabel(app.MSFAxes, 'Y')
            zlabel(app.MSFAxes, 'Z')
            app.SensitivitybasedonDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDownLabel.Text = 'Sensitivity based on:';
            app.SensitivitybasedonDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDown.Items = {'Mean', 'Min', 'Max'};
            app.SensitivitybasedonDropDown.ValueChangedFcn = createCallbackFcn(app, @SensitivitybasedonDropDownValueChanged, true);
            app.SensitivitybasedonDropDown.Value = 'Mean';
            app.RatioListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.RatioListBox.Items = {};
            app.RatioListBox.Multiselect = 'on';
            app.RatioListBox.ValueChangedFcn = createCallbackFcn(app, @RatioListBoxValueChanged, true);
            app.RatioListBox.Value = {};
            app.ComparetoDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDownLabel.Text = 'Compare to:';
            app.ComparetoDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDown.Items = {'', ''};
            app.ComparetoDropDown.ValueChangedFcn = createCallbackFcn(app, @ComparetoDropDownValueChanged, true);
            app.ComparetoDropDown.Value = '';
            app.DenominatorListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.DenominatorListBox.Items = {};
            app.DenominatorListBox.Multiselect = 'on';
            app.DenominatorListBox.ValueChangedFcn = createCallbackFcn(app, @DenominatorListBoxValueChanged, true);
            app.DenominatorListBox.Value = {};
            app.ShowLegendCheckBox = uicheckbox(app.MatrixSensitivityFactorMSFTab);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.ExportTableButton = uibutton(app.MatrixSensitivityFactorMSFTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.AddMetadataOptionalTab = uitab(app.TabGroup);
            app.AddMetadataOptionalTab.Title = 'Add Metadata (Optional)';
            app.MetadataTable = uitable(app.AddMetadataOptionalTab);
            app.MetadataTable.ColumnName = '';
            app.MetadataTable.RowName = {};
            app.MetadataTable.ColumnEditable = [false false true true true];
            app.MetadataTable.CellEditCallback = createCallbackFcn(app, @MetadataTableCellEdit, true);
            app.AddnumericcolumnButton = uibutton(app.AddMetadataOptionalTab, 'push');
            app.AddnumericcolumnButton.ButtonPushedFcn = createCallbackFcn(app, @AddnumericcolumnButtonPushed, true);
            app.AddnumericcolumnButton.Text = 'Add numeric column';
            app.Panel = uipanel(app.AddMetadataOptionalTab);
            app.GridLayout_2 = uigridlayout(app.Panel);
            app.ApplyButton = uibutton(app.GridLayout_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StringCompareDropDown = uidropdown(app.GridLayout_2);
            app.StringCompareDropDown.Items = {};
            app.StringCompareDropDown.Value = {};
            app.SpotnamecontainsEditFieldLabel = uilabel(app.GridLayout_2);
            app.SpotnamecontainsEditFieldLabel.Text = 'Spotname contains:';
            app.SpotnamecontainsEditField = uieditfield(app.GridLayout_2, 'text');
            app.MetadataInputEditField = uieditfield(app.GridLayout_2, 'text');
            app.ResetButton = uibutton(app.AddMetadataOptionalTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CustomcolumnnameEditFieldLabel = uilabel(app.AddMetadataOptionalTab);
            app.CustomcolumnnameEditFieldLabel.Text = 'Custom column name:';
            app.CustomcolumnnameEditField = uieditfield(app.AddMetadataOptionalTab, 'text');
            app.AddtextcolumnButton = uibutton(app.AddMetadataOptionalTab, 'push');
            app.AddtextcolumnButton.ButtonPushedFcn = createCallbackFcn(app, @AddtextcolumnButtonPushed, true);
            app.AddtextcolumnButton.Text = 'Add text column';
            app.OutputTableTab = uitab(app.TabGroup);
            app.OutputTableTab.Title = 'Configure Output Table';
            app.TabGroup3 = uitabgroup(app.OutputTableTab);
            app.AnalyteCalibratorAssignmentTab = uitab(app.TabGroup3);
            app.AnalyteCalibratorAssignmentTab.Title = 'Analyte-Calibrator Assignment';
            app.AnalyteTable = uitable(app.AnalyteCalibratorAssignmentTab);
            app.AnalyteTable.ColumnName = {'Analyte'; 'Calibrator'};
            app.AnalyteTable.RowName = {};
            app.AnalyteTable.ColumnSortable = [false false];
            app.AnalyteTable.ColumnEditable = [false false];
            app.CALTree = uitree(app.AnalyteCalibratorAssignmentTab, 'checkbox');
            % Assign Checked Nodes
            app.CALTree.CheckedNodesChangedFcn = createCallbackFcn(app, @CALTreeCheckedNodesChanged, true);
            app.Label = uilabel(app.AnalyteCalibratorAssignmentTab);
            app.Label.Text = {'Be aware that the quantified results are not isotope-resolved! For example, ‘Pb206’ indicates that Pb was measured on mass 206 a.m.u., not that a specific amount of the isotope ²⁰⁶Pb (in e.g., µg/g) is present in the sample.'; ''; 'You can remove the masses from the element name in the "Output Table Preview" Tab. If multiple masses were measured, this feature will use the preferred isotopes defined in the Oxides.csv file found in Documents/ICP-Base. '; ''; 'To change the preferred isotopes, edit (but don''t rename) this .csv file and rerun the quantification. You can check which preferred isotopes are used in the Settings Menu in the Quantifcation Module.'; ''; 'This .csv file also defines which elements are treated as majors although this is purely cosmetic and does not influence the quantification.'};
            app.Label_2 = uilabel(app.AnalyteCalibratorAssignmentTab);
            app.Label_2.Text = 'Use the tree to choose the desired calibrator for each analyte. The selected calibrator will be used when generating the output table.';
            app.Label_3 = uilabel(app.AnalyteCalibratorAssignmentTab);
            app.Label_3.Text = 'The output table can be previewed and exported in the "Output Table Preview" Tab.';
            app.UpdateTablePreviewButton = uibutton(app.AnalyteCalibratorAssignmentTab, 'push');
            app.UpdateTablePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTablePreviewButtonPushed, true);
            app.UpdateTablePreviewButton.Text = 'Update Table Preview';
            app.OutputTablePreview = uitab(app.TabGroup3);
            app.OutputTablePreview.Title = 'Output Table Preview';
            app.AssignedTable = uitable(app.OutputTablePreview);
            app.AssignedTable.ColumnName = '';
            app.AssignedTable.RowName = {};
            app.HostInclusionDropDown_2 = uidropdown(app.OutputTablePreview);
            app.HostInclusionDropDown_2.Items = {};
            app.HostInclusionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDown_2ValueChanged, true);
            app.HostInclusionDropDown_2.Value = {};
            app.EnddataprocessingButton_2 = uibutton(app.OutputTablePreview, 'push');
            app.EnddataprocessingButton_2.ButtonPushedFcn = createCallbackFcn(app, @EnddataprocessingButton_2Pushed, true);
            app.EnddataprocessingButton_2.Text = 'End data processing';
            app.CreateOutputTableButton_2 = uibutton(app.OutputTablePreview, 'push');
            app.CreateOutputTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputTableButton_2Pushed, true);
            app.CreateOutputTableButton_2.Text = 'Create Output Table';
            app.RemovemassesfromanalytenameCheckBox = uicheckbox(app.OutputTablePreview);
            app.RemovemassesfromanalytenameCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovemassesfromanalytenameCheckBoxValueChanged, true);
            app.RemovemassesfromanalytenameCheckBox.Text = 'Remove masses from analyte name';
            app.RemovemassesfromanalytenameCheckBox.Value = true;
            app.MajorsasOxideswtCheckBox = uicheckbox(app.OutputTablePreview);
            app.MajorsasOxideswtCheckBox.ValueChangedFcn = createCallbackFcn(app, @MajorsasOxideswtCheckBoxValueChanged, true);
            app.MajorsasOxideswtCheckBox.Text = 'Majors as Oxides (wt%)';
            app.IncludeMetadataCheckBox = uicheckbox(app.OutputTablePreview);
            app.IncludeMetadataCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeMetadataCheckBoxValueChanged, true);
            app.IncludeMetadataCheckBox.Text = 'Include Metadata';
            app.SendtoVisModuleButton = uibutton(app.OutputTablePreview, 'push');
            app.SendtoVisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoVisModuleButtonPushed, true);
            app.SendtoVisModuleButton.Text = 'Send to VisModule';
            app.AllvaluesinggunlessexpressedasoxideswtLabel = uilabel(app.OutputTablePreview);
            app.AllvaluesinggunlessexpressedasoxideswtLabel.Text = 'All values in µg/g unless expressed as oxides (wt%)';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.OutputallmatrixmassfractionsggMenu = uimenu(app.ContextMenu2);
            app.OutputallmatrixmassfractionsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallmatrixmassfractionsggMenuSelected, true);
            app.OutputallmatrixmassfractionsggMenu.Text = 'Output all matrix mass fractions [µg/g]';
            app.OutputallinclusionmassfractionsggMenu = uimenu(app.ContextMenu2);
            app.OutputallinclusionmassfractionsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallinclusionmassfractionsggMenuSelected, true);
            app.OutputallinclusionmassfractionsggMenu.Text = 'Output all inclusion mass fractions [µg/g]';
            app.OutputallmatrixLODsggMenu = uimenu(app.ContextMenu2);
            app.OutputallmatrixLODsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallmatrixLODsggMenuSelected, true);
            app.OutputallmatrixLODsggMenu.Text = 'Output all matrix LODs [µg/g]';
            app.OutputallinclusionLODsggMenu = uimenu(app.ContextMenu2);
            app.OutputallinclusionLODsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallinclusionLODsggMenuSelected, true);
            app.OutputallinclusionLODsggMenu.Text = 'Output all inclusion LODs [µg/g]';
            % Assign app.ContextMenu2
            app.CreateOutputTableButton.ContextMenu = app.ContextMenu2;
            app.CreateOutputTableButton_2.ContextMenu = app.ContextMenu2;
            app.SendtoVisModuleButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end