classdef Interference_Correction_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LaserBackgroundsTab          matlab.ui.container.Tab
        BlanktoallspotscontainingEditField_2  matlab.ui.control.EditField
        toallICMcontainingLabel_3    matlab.ui.control.Label
        BlankAssignSpotButton        matlab.ui.control.Button
        SpotAssignmentTable          matlab.ui.control.Table
        BlanktoallspotscontainingEditField  matlab.ui.control.EditField
        toallICMcontainingLabel      matlab.ui.control.Label
        BlankAssignIDEditField       matlab.ui.control.NumericEditField
        AssignIDEditField_2Label     matlab.ui.control.Label
        BlankICMIDDropDown           matlab.ui.control.DropDown
        ICMIDDropDown_2Label         matlab.ui.control.Label
        BlankAssignButton            matlab.ui.control.Button
        BlankAnalyteListBox          matlab.ui.control.ListBox
        BlankAssignmentTable         matlab.ui.control.Table
        BlankICMTable                matlab.ui.control.Table
        BlankProceedtoDriftModuleButton  matlab.ui.control.Button
        IsobaricInterferencesTab     matlab.ui.container.Tab
        toallICMcontainingEditField  matlab.ui.control.EditField
        toallICMcontainingLabel_2    matlab.ui.control.Label
        AssignIDEditField            matlab.ui.control.NumericEditField
        AssignIDEditFieldLabel       matlab.ui.control.Label
        ICMIDDropDown                matlab.ui.control.DropDown
        ICMIDLabel                   matlab.ui.control.Label
        AssignButton                 matlab.ui.control.Button
        RemoveRowButton              matlab.ui.control.Button
        AddRowButton                 matlab.ui.control.Button
        AnalyteListBox               matlab.ui.control.ListBox
        AssignmentTable              matlab.ui.control.Table
        ICMTable                     matlab.ui.control.Table
        ProceedtoDriftModuleButton   matlab.ui.control.Button
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
    %                     %%%      ICP-Base     %%%                        %
    %                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
    %                                                                      %
    %  Copyright (C) 2026 Sebastian Stumpf                                 %
    %                                                                      %
    %  This program is free software: you can redistribute it and/or       %
    %  modify it under the terms of the GNU General Public License as      %
    %  published by the Free Software Foundation, either version 3 of      %
    %  the License, or any later version.                                  %
    %                                                                      %
    %  This program is distributed in the hope that it will be useful,     %
    %  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
    %  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
    %  GNU General Public License for more details.                        %
    %                                                                      %
    %  You should have received a copy of the GNU General Public License   %
    %  along with this program. If not, see <https://www.gnu.org/licenses/>%
    %                                                                      %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        source
        icm_progress

        icm_bracket_id
        correction_pairs
        selected_assignment_cell

        blank_bracket_id
        blank_correction_elements
        blank_spot_assignment

    end

    methods (Access = private)

        %%%%%%%%%%% Isobaric %%%%%%%%%%%%%%

        function update_icm_table(app)
            sess         = app.source.base.project.session;
            icm_mask     = sess.spot.type == "ICM";
            spot_numbers = num2cell(find(icm_mask));
            spotnames    = cellstr(sess.spot.name(icm_mask));
            ids          = num2cell(app.icm_bracket_id(icm_mask));

            T = cell2table([spot_numbers, spotnames, ids], ...
                'VariableNames', {'#', 'Spotname', 'ID'});

            app.ICMTable.Data           = T;
            app.ICMTable.ColumnName     = {'#', 'Spotname', 'ID'};
            app.ICMTable.ColumnEditable = [false, false, true];
        end

        function update_id_dropdown(app)
            sess     = app.source.base.project.session;
            icm_mask = sess.spot.type == "ICM";
            ids      = unique(app.icm_bracket_id(icm_mask));
            ids      = ids(ids ~= 0);

            app.ICMIDDropDown.Items     = cellstr(string(ids));
            app.ICMIDDropDown.ItemsData = num2cell(ids);
        end

        function update_analyte_listbox(app)
            analytes                 = cellstr(app.source.base.project.session.spot.analytes);
            app.AnalyteListBox.Items = analytes;
        end

        function update_assignment_table(app)
            if isempty(app.ICMIDDropDown.Items)
                app.AssignmentTable.Data           = table(string.empty(0,1), string.empty(0,1), ...
                    'VariableNames', {'Interferer', 'Target'});
                app.AssignmentTable.ColumnName     = {'Interferer', 'Target'};
                app.AssignmentTable.ColumnEditable = [false, false];
                return
            end

            selected_id = app.ICMIDDropDown.Value;
            mask        = app.correction_pairs.ID == selected_id;
            pairs       = app.correction_pairs(mask, {'Interferer', 'Target'});

            app.AssignmentTable.Data           = pairs;
            app.AssignmentTable.ColumnName     = {'Interferer', 'Target'};
            app.AssignmentTable.ColumnEditable = [false, false];
        end

        %%%%%%%%%%% Laser Backgrounds %%%%%%%%%%%%%%

        function update_blank_table(app)
            sess         = app.source.base.project.session;
            icm_mask     = sess.spot.type == "ICM";
            spot_numbers = num2cell(find(icm_mask));
            spotnames    = cellstr(sess.spot.name(icm_mask));
            ids          = num2cell(app.blank_bracket_id(icm_mask));
            T = cell2table([spot_numbers, spotnames, ids], ...
                'VariableNames', {'#', 'Spotname', 'ID'});
            app.BlankICMTable.Data           = T;
            app.BlankICMTable.ColumnName     = {'#', 'Spotname', 'ID'};
            app.BlankICMTable.ColumnEditable = [false, false, true];
        end

        function update_blank_id_dropdown(app)
            sess     = app.source.base.project.session;
            icm_mask = sess.spot.type == "ICM";
            ids      = unique(app.blank_bracket_id(icm_mask));
            ids      = ids(ids ~= 0);
            app.BlankICMIDDropDown.Items     = cellstr(string(ids));
            app.BlankICMIDDropDown.ItemsData = num2cell(ids);
        end

        function update_blank_element_listbox(app)
            analytes = cellstr(app.source.base.project.session.spot.analytes);
            app.BlankAnalyteListBox.Items = analytes;
            if isempty(app.BlankICMIDDropDown.Items)
                app.BlankAnalyteListBox.Value = {};
                return
            end
            selected_id = app.BlankICMIDDropDown.Value;
            mask        = app.blank_correction_elements.ID == selected_id;
            assigned    = cellstr(app.blank_correction_elements.Element(mask));
            app.BlankAnalyteListBox.Value = intersect(assigned, analytes);
        end

        function update_spot_assignment_table(app)
            sess             = app.source.base.project.session;
            non_icm_mask     = sess.spot.type ~= "ICM";
            spot_numbers     = num2cell(find(non_icm_mask));
            spotnames        = cellstr(sess.spot.name(non_icm_mask));
            assignments      = num2cell(app.blank_spot_assignment(non_icm_mask));
            T = cell2table([spot_numbers, spotnames, assignments], ...
                'VariableNames', {'#', 'Spotname', 'Blank_ID'});
            app.SpotAssignmentTable.Data           = T;
            app.SpotAssignmentTable.ColumnName     = {'#', 'Spotname', 'Blank_ID'};
            app.SpotAssignmentTable.ColumnEditable = [false, false, true];
        end

        function update_blank_assignment_table(app)
            if isempty(app.BlankICMIDDropDown.Items)
                app.BlankAssignmentTable.Data       = table(string.empty(0,1), 'VariableNames', {'Zero Analyte'});
                app.BlankAssignmentTable.ColumnName = {'Zero Analyte'};
                return
            end
            selected_id = app.BlankICMIDDropDown.Value;
            mask        = app.blank_correction_elements.ID == selected_id;
            elements    = app.blank_correction_elements.Element(mask);
            app.BlankAssignmentTable.Data       = table(elements, 'VariableNames', {'Zero Analyte'});
            app.BlankAssignmentTable.ColumnName = {'Zero Analyte'};
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, icm_progress)
            app.source       = sourceapp;
            app.icm_progress = icm_progress;
            sess     = app.source.base.project.session;
            icm_mask = sess.spot.type == "ICM";

            % --- ICM ---
            if isfield(sess.spot, 'icm_bracket_id')
                app.icm_bracket_id = sess.spot.icm_bracket_id;
            else
                app.icm_bracket_id = zeros(numel(sess.spot.name), 1);
                app.icm_bracket_id(icm_mask) = 1;
            end
            new_icm = icm_mask & (app.icm_bracket_id == 0);
            if any(new_icm)
                app.icm_bracket_id(new_icm) = 1;
            end

            if isfield(sess.spot, 'icm_correction_pairs') && ~isempty(sess.spot.icm_correction_pairs)
                raw      = sess.spot.icm_correction_pairs;
                expected = {'ID', 'Interferer', 'Target'};
                if all(ismember(expected, raw.Properties.VariableNames))
                    app.correction_pairs = raw(:, expected);
                else
                    app.correction_pairs = table(double.empty(0,1), string.empty(0,1), string.empty(0,1), ...
                        'VariableNames', {'ID', 'Interferer', 'Target'});
                end
            else
                app.correction_pairs = table(double.empty(0,1), string.empty(0,1), string.empty(0,1), ...
                    'VariableNames', {'ID', 'Interferer', 'Target'});
            end

            % --- Blank ---
            if isfield(sess.spot, 'blank_bracket_id')
                app.blank_bracket_id = sess.spot.blank_bracket_id;
            else
                app.blank_bracket_id = zeros(numel(sess.spot.name), 1);
                app.blank_bracket_id(icm_mask) = 1;
            end
            new_blank = icm_mask & (app.blank_bracket_id == 0);
            if any(new_blank)
                app.blank_bracket_id(new_blank) = 1;
            end

            if isfield(sess.spot, 'blank_correction_elements') && ~isempty(sess.spot.blank_correction_elements)
                raw      = sess.spot.blank_correction_elements;
                expected = {'ID', 'Element'};
                if all(ismember(expected, raw.Properties.VariableNames))
                    app.blank_correction_elements = raw(:, expected);
                else
                    app.blank_correction_elements = table(double.empty(0,1), string.empty(0,1), ...
                        'VariableNames', {'ID', 'Element'});
                end
            else
                app.blank_correction_elements = table(double.empty(0,1), string.empty(0,1), ...
                    'VariableNames', {'ID', 'Element'});
            end

            if isfield(sess.spot, 'blank_spot_assignment')
                app.blank_spot_assignment = sess.spot.blank_spot_assignment;
            else
                app.blank_spot_assignment = zeros(numel(sess.spot.name), 1);
            end

            % --- Update GUI ---
            update_icm_table(app);
            update_id_dropdown(app);
            update_analyte_listbox(app);
            update_assignment_table(app);

            update_blank_table(app);
            update_blank_id_dropdown(app);
            update_blank_element_listbox(app);
            update_spot_assignment_table(app);
            update_blank_assignment_table(app) 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Closing will discard your interference correction configuration.', ...
                'Discard Configuration', ...
                'Options', {'Close', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);

            if strcmp(selection, 'Close')
                delete(app)
            end
        end

        % Button pushed function: BlankProceedtoDriftModuleButton, 
        % ProceedtoDriftModuleButton
        function ProceedtoDriftModuleButtonPushed(app, event)
            sess    = app.source.base.project.session;
            has_icm = any(sess.spot.type == "ICM");

            if has_icm
                no_isobaric = isempty(app.correction_pairs) || height(app.correction_pairs) == 0;
                no_blank    = isempty(app.blank_correction_elements) || height(app.blank_correction_elements) == 0;
                if no_isobaric && no_blank
                    uialert(app.UIFigure, ...
                        'At least one correction must be configured — either an isobaric interference or a blank correction.', ...
                        'No Correction Configured');
                    return
                end
            end

            % --- ICM validation ---
            if has_icm
                pairs = app.correction_pairs;

                if isempty(pairs) || height(pairs) == 0
                    uialert(app.UIFigure, 'No interference corrections have been assigned. Add at least one Interferer → Target pair before proceeding.', 'No Assignments');
                    return
                end

                incomplete = pairs.Interferer == "" | pairs.Target == "";
                if any(incomplete)
                    uialert(app.UIFigure, 'One or more rows have an empty Interferer or Target.', 'Incomplete Assignment');
                    return
                end

                if any(pairs.Interferer == pairs.Target)
                    uialert(app.UIFigure, 'One or more rows have the same analyte as both Interferer and Target.', 'Invalid Assignment');
                    return
                end

                interferer_mass = str2double(regexp(pairs.Interferer, '\d+$', 'match', 'once'));
                target_mass     = str2double(regexp(pairs.Target,     '\d+$', 'match', 'once'));
                if any(target_mass <= interferer_mass)
                    uialert(app.UIFigure, 'Target must have a higher mass number than the interferer.', 'Invalid Mass Order');
                    return
                end

                icm_ids       = app.icm_bracket_id(sess.spot.type == "ICM");
                unique_ids    = unique(icm_ids);
                singleton_ids = unique_ids(arrayfun(@(id) sum(icm_ids == id) == 1, unique_ids));
                if ~isempty(singleton_ids)
                    id_str    = strjoin(string(singleton_ids), ', ');
                    selection = uiconfirm(app.UIFigure, ...
                        sprintf(['ICM ID(s) %s have only one spot assigned. No regression possible — ' ...
                        'the same correction factor will be applied to all spots. Proceed anyway?'], id_str), ...
                        'Single ICM Spot', 'Options', {'Proceed', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2);
                    if strcmp(selection, 'Cancel'), return; end
                end

                unique_pairs = unique(pairs(:, {'Interferer', 'Target'}), 'rows');
                if height(unique_pairs) < height(pairs)
                    uialert(app.UIFigure, 'The same Interferer → Target pair is assigned to multiple IDs.', 'Duplicate Assignment');
                    return
                end
            end

            % --- Blank validation ---
            if has_icm
                if ~isempty(app.blank_correction_elements) && height(app.blank_correction_elements) > 0

                    if ~any(app.blank_spot_assignment ~= 0)
                        uialert(app.UIFigure, 'Elements are assigned to blank IDs but no spots have been assigned for blank correction.', 'No Spots Assigned');
                        return
                    end

                    assigned_ids = unique(app.blank_spot_assignment(app.blank_spot_assignment ~= 0));
                    element_ids  = unique(app.blank_correction_elements.ID);
                    missing_ids  = assigned_ids(~ismember(assigned_ids, element_ids));
                    if ~isempty(missing_ids)
                        id_str = strjoin(string(missing_ids), ', ');
                        uialert(app.UIFigure, ...
                            sprintf('Blank ID(s) %s are assigned to spots but have no elements defined.', id_str), ...
                            'Missing Elements');
                        return
                    end

                    blank_ids     = app.blank_bracket_id(sess.spot.type == "ICM");
                    unique_ids    = unique(blank_ids);
                    singleton_ids = unique_ids(arrayfun(@(id) sum(blank_ids == id) == 1, unique_ids));
                    if ~isempty(singleton_ids)
                        id_str    = strjoin(string(singleton_ids), ', ');
                        selection = uiconfirm(app.UIFigure, ...
                            sprintf(['Blank ID(s) %s have only one spot assigned. The same blank signal will be ' ...
                            'applied to all spots regardless of drift. Proceed anyway?'], id_str), ...
                            'Single Blank Spot', 'Options', {'Proceed', 'Cancel'}, ...
                            'DefaultOption', 2, 'CancelOption', 2);
                        if strcmp(selection, 'Cancel'), return; end
                    end
                end
            end

            % --- Write to session ---
            app.source.base.project.session.spot.icm_bracket_id          = app.icm_bracket_id;
            app.source.base.project.session.spot.icm_correction_pairs     = app.correction_pairs;
            app.source.base.project.session.spot.blank_bracket_id         = app.blank_bracket_id;
            app.source.base.project.session.spot.blank_correction_elements = app.blank_correction_elements;
            app.source.base.project.session.spot.blank_spot_assignment    = app.blank_spot_assignment;

            delete(app.icm_progress)
            app.source.launch_drift_module()
            delete(app.source)
            delete(app)
        end

        % Cell edit callback: ICMTable
        function ICMTableCellEdit(app, event)
            newData = event.NewData;
            indices = event.Indices;

            if ~isnumeric(newData) || newData < 1 || floor(newData) ~= newData
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                update_icm_table(app);
                return
            end

            % Find which spot this row corresponds to
            spot_num = app.ICMTable.Data{indices(1), '#'};
            app.icm_bracket_id(spot_num) = newData;

            valid_ids = unique(app.icm_bracket_id(app.source.base.project.session.spot.type == "ICM"));
            app.correction_pairs = app.correction_pairs(ismember(app.correction_pairs.ID, valid_ids), :);

            update_icm_table(app);
            update_id_dropdown(app);
            update_assignment_table(app);
        end

        % Value changed function: ICMIDDropDown
        function ICMIDDropDownValueChanged(app, event)
           update_assignment_table(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            selected_id = app.ICMIDDropDown.Value;
            new_row     = table(selected_id, string(""), string(""), ...
                'VariableNames', {'ID', 'Interferer', 'Target'});
            app.correction_pairs = [app.correction_pairs; new_row];
            update_assignment_table(app);
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            if isempty(app.AssignmentTable.Selection)
                return
            end

            selected_id  = app.ICMIDDropDown.Value;
            selected_row = app.AssignmentTable.Selection(1, 1);
            mask         = app.correction_pairs.ID == selected_id;
            id_rows      = find(mask);

            if selected_row > numel(id_rows)
                return
            end

            app.correction_pairs(id_rows(selected_row), :) = [];
            update_assignment_table(app);
        end

        % Value changed function: AnalyteListBox
        function AnalyteListBoxValueChanged(app, event)
            if isempty(app.selected_assignment_cell)
                return
            end

            selected_analyte = app.AnalyteListBox.Value;
            selected_row     = app.selected_assignment_cell(1);
            selected_col     = app.selected_assignment_cell(2);

            selected_id = app.ICMIDDropDown.Value;
            mask        = app.correction_pairs.ID == selected_id;
            id_rows     = find(mask);

            if selected_row > numel(id_rows)
                return
            end

            global_row = id_rows(selected_row);

            switch selected_col
                case 1
                    app.correction_pairs.Interferer(global_row) = string(selected_analyte);
                case 2
                    app.correction_pairs.Target(global_row) = string(selected_analyte);
            end

            update_assignment_table(app);
        end

        % Callback function: AssignmentTable
        function AssignmentTableSelectionChanged(app, event)
            if ~isempty(app.AssignmentTable.Selection)
                app.selected_assignment_cell = app.AssignmentTable.Selection(1, :);
            end
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            pattern = app.toallICMcontainingEditField.Value;
            id_val  = app.AssignIDEditField.Value;

            if isnan(id_val) || id_val < 1 || floor(id_val) ~= id_val
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                return
            end

            sess        = app.source.base.project.session;
            icm_mask    = sess.spot.type == "ICM";
            icm_indices = find(icm_mask);
            icm_names   = sess.spot.name(icm_mask);

            match_mask = contains(icm_names, pattern, 'IgnoreCase', true);
            if ~any(match_mask)
                uialert(app.UIFigure, 'No ICM spots found containing that string.', 'No Match');
                return
            end

            app.icm_bracket_id(icm_indices(match_mask)) = id_val;

            update_icm_table(app);
            update_id_dropdown(app);
            update_assignment_table(app);

        end

        % Cell edit callback: BlankICMTable
        function BlankICMTableCellEdit(app, event)
            newData = event.NewData;
            indices = event.Indices;

            if ~isnumeric(newData) || newData < 1 || floor(newData) ~= newData
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                update_blank_table(app);
                return
            end

            spot_num = app.BlankICMTable.Data{indices(1), '#'};
            app.blank_bracket_id(spot_num) = newData;

            valid_ids = unique(app.blank_bracket_id(app.source.base.project.session.spot.type == "ICM"));
            app.blank_correction_elements = app.blank_correction_elements(ismember(app.blank_correction_elements.ID, valid_ids), :);

            update_blank_table(app);
            update_blank_id_dropdown(app);
            update_blank_element_listbox(app);
        end

        % Value changed function: BlankICMIDDropDown
        function BlankICMIDDropDownValueChanged(app, event)
            update_blank_element_listbox(app);
            update_blank_assignment_table(app);
        end

        % Value changed function: BlankAnalyteListBox
        function BlankAnalyteListBoxValueChanged(app, event)
            if isempty(app.BlankICMIDDropDown.Items), return; end

            selected_id       = app.BlankICMIDDropDown.Value;
            selected_elements = string(app.BlankAnalyteListBox.Value);

            % Update backend
            app.blank_correction_elements = app.blank_correction_elements( ...
                app.blank_correction_elements.ID ~= selected_id, :);
            if ~isempty(selected_elements)
                new_rows = table(repmat(selected_id, numel(selected_elements), 1), selected_elements(:), ...
                    'VariableNames', {'ID', 'Element'});
                app.blank_correction_elements = [app.blank_correction_elements; new_rows];
            end

            % Update display table
            app.BlankAssignmentTable.Data       = table(selected_elements(:), 'VariableNames', {'Zero Analyte'});
            app.BlankAssignmentTable.ColumnName = {'Zero Analyte'};
        end

        % Cell edit callback: SpotAssignmentTable
        function SpotAssignmentTableCellEdit(app, event)
            newData = event.NewData;
            indices = event.Indices;

            if ~isnumeric(newData) || newData < 0 || floor(newData) ~= newData
                uialert(app.UIFigure, 'blank ID must be a positive integer or 0 (uncorrected).', 'Invalid Input');
                update_spot_assignment_table(app);
                return
            end

            spot_num = app.SpotAssignmentTable.Data{indices(1), '#'};
            app.blank_spot_assignment(spot_num) = newData;
        end

        % Button pushed function: BlankAssignButton
        function BlankAssignButtonPushed(app, event)
            pattern = app.BlanktoallspotscontainingEditField.Value;
            id_val  = str2double(app.BlankAssignIDEditField.Value);

            if isnan(id_val) || id_val < 1 || floor(id_val) ~= id_val
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                return
            end

            sess        = app.source.base.project.session;
            icm_mask    = sess.spot.type == "ICM";
            icm_indices = find(icm_mask);
            icm_names   = sess.spot.name(icm_mask);

            match_mask = contains(icm_names, pattern, 'IgnoreCase', true);
            if ~any(match_mask)
                uialert(app.UIFigure, 'No ICM spots found containing that string.', 'No Match');
                return
            end

            app.blank_bracket_id(icm_indices(match_mask)) = id_val;

            update_blank_table(app);
            update_blank_id_dropdown(app);
            update_blank_element_listbox(app);
        end

        % Button pushed function: BlankAssignSpotButton
        function BlankAssignSpotButtonPushed(app, event)
            if isempty(app.BlankICMIDDropDown.Items)
                uialert(app.UIFigure, 'No Blank IDs defined yet.', 'No IDs');
                return
            end

            pattern     = app.BlanktoallspotscontainingEditField.Value;
            selected_id = app.BlankICMIDDropDown.Value;

            sess             = app.source.base.project.session;
            non_icm_mask     = sess.spot.type ~= "ICM";
            non_icm_indices  = find(non_icm_mask);
            non_icm_names    = sess.spot.name(non_icm_mask);

            match_mask = contains(non_icm_names, pattern, 'IgnoreCase', true);
            if ~any(match_mask)
                uialert(app.UIFigure, 'No spots found containing that string.', 'No Match');
                return
            end

            app.blank_spot_assignment(non_icm_indices(match_mask)) = selected_id;

            update_spot_assignment_table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interference Correction Configuration';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LaserBackgroundsTab = uitab(app.TabGroup);
            app.LaserBackgroundsTab.Title = 'Laser Backgrounds';
            app.BlankProceedtoDriftModuleButton = uibutton(app.LaserBackgroundsTab, 'push');
            app.BlankProceedtoDriftModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDriftModuleButtonPushed, true);
            app.BlankProceedtoDriftModuleButton.Text = 'Proceed to Drift Module';
            app.BlankICMTable = uitable(app.LaserBackgroundsTab);
            app.BlankICMTable.ColumnName = '';
            app.BlankICMTable.RowName = {};
            app.BlankICMTable.CellEditCallback = createCallbackFcn(app, @BlankICMTableCellEdit, true);
            app.BlankAssignmentTable = uitable(app.LaserBackgroundsTab);
            app.BlankAssignmentTable.ColumnName = '';
            app.BlankAssignmentTable.RowName = {};
            app.BlankAnalyteListBox = uilistbox(app.LaserBackgroundsTab);
            app.BlankAnalyteListBox.Items = {};
            app.BlankAnalyteListBox.Multiselect = 'on';
            app.BlankAnalyteListBox.ValueChangedFcn = createCallbackFcn(app, @BlankAnalyteListBoxValueChanged, true);
            app.BlankAnalyteListBox.Value = {};
            app.BlankAssignButton = uibutton(app.LaserBackgroundsTab, 'push');
            app.BlankAssignButton.ButtonPushedFcn = createCallbackFcn(app, @BlankAssignButtonPushed, true);
            app.BlankAssignButton.Text = 'Assign';
            app.ICMIDDropDown_2Label = uilabel(app.LaserBackgroundsTab);
            app.ICMIDDropDown_2Label.Text = 'ICM ID:';
            app.BlankICMIDDropDown = uidropdown(app.LaserBackgroundsTab);
            app.BlankICMIDDropDown.Items = {};
            app.BlankICMIDDropDown.ValueChangedFcn = createCallbackFcn(app, @BlankICMIDDropDownValueChanged, true);
            app.BlankICMIDDropDown.Value = {};
            app.AssignIDEditField_2Label = uilabel(app.LaserBackgroundsTab);
            app.AssignIDEditField_2Label.Text = 'Assign ID';
            app.BlankAssignIDEditField = uieditfield(app.LaserBackgroundsTab, 'numeric');
            app.BlankAssignIDEditField.Limits = [1 Inf];
            app.BlankAssignIDEditField.Value = 1;
            app.toallICMcontainingLabel = uilabel(app.LaserBackgroundsTab);
            app.toallICMcontainingLabel.Text = 'to all ICM containing';
            app.BlanktoallspotscontainingEditField = uieditfield(app.LaserBackgroundsTab, 'text');
            app.SpotAssignmentTable = uitable(app.LaserBackgroundsTab);
            app.SpotAssignmentTable.ColumnName = '';
            app.SpotAssignmentTable.RowName = {};
            app.SpotAssignmentTable.CellEditCallback = createCallbackFcn(app, @SpotAssignmentTableCellEdit, true);
            app.BlankAssignSpotButton = uibutton(app.LaserBackgroundsTab, 'push');
            app.BlankAssignSpotButton.ButtonPushedFcn = createCallbackFcn(app, @BlankAssignSpotButtonPushed, true);
            app.BlankAssignSpotButton.Text = 'Assign';
            app.toallICMcontainingLabel_3 = uilabel(app.LaserBackgroundsTab);
            app.toallICMcontainingLabel_3.Text = 'Assign current ICM ID to all spots containing';
            app.BlanktoallspotscontainingEditField_2 = uieditfield(app.LaserBackgroundsTab, 'text');
            app.IsobaricInterferencesTab = uitab(app.TabGroup);
            app.IsobaricInterferencesTab.Title = 'Isobaric Interferences';
            app.ProceedtoDriftModuleButton = uibutton(app.IsobaricInterferencesTab, 'push');
            app.ProceedtoDriftModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDriftModuleButtonPushed, true);
            app.ProceedtoDriftModuleButton.Text = 'Proceed to Drift Module';
            app.ICMTable = uitable(app.IsobaricInterferencesTab);
            app.ICMTable.ColumnName = '';
            app.ICMTable.RowName = {};
            app.ICMTable.CellEditCallback = createCallbackFcn(app, @ICMTableCellEdit, true);
            app.AssignmentTable = uitable(app.IsobaricInterferencesTab);
            app.AssignmentTable.ColumnName = '';
            app.AssignmentTable.RowName = {};
            app.AssignmentTable.SelectionChangedFcn = createCallbackFcn(app, @AssignmentTableSelectionChanged, true);
            app.AnalyteListBox = uilistbox(app.IsobaricInterferencesTab);
            app.AnalyteListBox.Items = {};
            app.AnalyteListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyteListBoxValueChanged, true);
            app.AnalyteListBox.Value = {};
            app.AddRowButton = uibutton(app.IsobaricInterferencesTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.RemoveRowButton = uibutton(app.IsobaricInterferencesTab, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Text = 'Remove Row';
            app.AssignButton = uibutton(app.IsobaricInterferencesTab, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            app.ICMIDLabel = uilabel(app.IsobaricInterferencesTab);
            app.ICMIDLabel.Text = 'ICM ID:';
            app.ICMIDDropDown = uidropdown(app.IsobaricInterferencesTab);
            app.ICMIDDropDown.Items = {};
            app.ICMIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ICMIDDropDownValueChanged, true);
            app.ICMIDDropDown.Value = {};
            app.AssignIDEditFieldLabel = uilabel(app.IsobaricInterferencesTab);
            app.AssignIDEditFieldLabel.Text = 'Assign ID';
            app.AssignIDEditField = uieditfield(app.IsobaricInterferencesTab, 'numeric');
            app.AssignIDEditField.Limits = [1 Inf];
            app.AssignIDEditField.Value = 1;
            app.toallICMcontainingLabel_2 = uilabel(app.IsobaricInterferencesTab);
            app.toallICMcontainingLabel_2.Text = 'to all ICM containing';
            app.toallICMcontainingEditField = uieditfield(app.IsobaricInterferencesTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interference_Correction_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end