classdef Moving_average_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MovingAverageSettingsUIFigure  matlab.ui.Figure
        NumberofsweepstostepforwardEditField  matlab.ui.control.NumericEditField
        NumberofsweepstostepforwardEditFieldLabel  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        HowmanysweepstoindenttheintervalintheunselectedEditField  matlab.ui.control.NumericEditField
        HowmanysweepstoindenttheintervalintheunselectedstartandendLabel  matlab.ui.control.Label
        NumberofsweepstoskipatthestartEditField  matlab.ui.control.NumericEditField
        NumberofsweepstoskipatthestartEditFieldLabel  matlab.ui.control.Label
        NumberofsweepstoskipattheendEditField  matlab.ui.control.NumericEditField
        NumberofsweepstoskipattheendEditFieldLabel  matlab.ui.control.Label
        NumberofsweepstobeaveragedEditField  matlab.ui.control.NumericEditField
        NumberofsweepstobeaveragedEditFieldLabel  matlab.ui.control.Label
        SpotListBox                    matlab.ui.control.ListBox
    end

    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        source 
        selected_spot

    end
    
    methods (Access = private)
        

        function apply_rolling_average(app)

            % here check which spot is selected
            if isempty(app.selected_spot)
                    message = "No spots selected.";
                    uialert(app.MovingAverageSettingsUIFigure, message, "Warning", "Icon", "warning");
                    return
            end

            signal_starts = app.source.base.project.session.spot.signal_starts(app.selected_spot);
            signal_ends = app.source.base.project.session.spot.signal_ends(app.selected_spot);

            sweeps_to_average = app.NumberofsweepstobeaveragedEditField.Value;
            sweeps_to_skip_start = app.NumberofsweepstoskipatthestartEditField.Value;
            sweeps_to_skip_end = app.NumberofsweepstoskipattheendEditField.Value;
            sweeps_to_step_forward = app.NumberofsweepstostepforwardEditField.Value;

            num_signals = numel(signal_starts);
            all_starts = cell(num_signals, 1);
            all_ends = cell(num_signals, 1);

            % Loop through each signal
            for i = 1:num_signals
                
                % Adjust signal boundaries based on sweeps to skip
                effective_signal_start = signal_starts(i) + sweeps_to_skip_start;
                effective_signal_end = signal_ends(i) - sweeps_to_skip_end;

                % Determine the last possible start index for a full window
                last_possible_start = effective_signal_end - sweeps_to_average + 1;

                % Check if the adjusted signal is long enough for at least one window
                if effective_signal_start <= last_possible_start
                    % Generate all start indices for the rolling windows, stepping by the specified amount
                    start_indices = effective_signal_start : sweeps_to_step_forward : last_possible_start;
                    
                    % Generate corresponding end indices
                    end_indices = start_indices + sweeps_to_average - 1;
                    
                    % Store the results
                    all_starts{i} = start_indices';
                    all_ends{i} = end_indices';
                else
                    % Signal is too short for the given window size after applying skips
                    message = "Signal is too short for the given window size after applying skips.";
                    uialert(app.MovingAverageSettingsUIFigure, message, "Warning", "Icon", "warning");
                    disp('Signal is too short for the given window size after applying skips.')
                    return
                end
            end

            % Initialize cell arrays for the new spot data
            final_names = {};
            final_signal_starts = [];
            final_signal_ends = [];
            final_matrix_1_starts = {};
            final_matrix_1_ends = {};

            % Create a lookup for the expanded data of selected spots
            selected_spot_map = containers.Map('KeyType', 'double', 'ValueType', 'any');
            for i = 1:num_signals
                selected_idx = app.selected_spot(i);
                
                num_new_spots = numel(all_starts{i});
                base_name = app.source.base.project.session.spot.name{selected_idx};
                spot_suffixes = cellstr(num2str((1:num_new_spots)', ' -%d'));
                
                data.generated_names = strcat(base_name, spot_suffixes);
                data.replicated_starts = repmat(app.source.base.project.session.spot.signal_starts(selected_idx), num_new_spots, 1);
                data.replicated_ends = repmat(app.source.base.project.session.spot.signal_ends(selected_idx), num_new_spots, 1);
                
                % Convert the array of starts/ends into a cell array where each cell has one value
                data.matrix_starts = num2cell(all_starts{i});
                data.matrix_ends = num2cell(all_ends{i});
                
                selected_spot_map(selected_idx) = data;
            end

            % Iterate through all original spots to build the new list in order
            original_spot_count = numel(app.source.base.project.session.spot.name);
            unselected_indent = app.HowmanysweepstoindenttheintervalintheunselectedEditField.Value; % Default indent for unselected spots

             for i = 1:original_spot_count
                if isKey(selected_spot_map, i)
                    % This was a selected spot, append its expanded data
                    data = selected_spot_map(i);
                    final_names = [final_names; data.generated_names];
                    final_signal_starts = [final_signal_starts; data.replicated_starts];
                    final_signal_ends = [final_signal_ends; data.replicated_ends];
                    final_matrix_1_starts = [final_matrix_1_starts; data.matrix_starts];
                    final_matrix_1_ends = [final_matrix_1_ends; data.matrix_ends];
                else
                    % This was an unselected spot, append its original data
                    final_names = [final_names; app.source.base.project.session.spot.name(i)];
                    current_signal_start = app.source.base.project.session.spot.signal_starts(i);
                    current_signal_end = app.source.base.project.session.spot.signal_ends(i);
                    final_signal_starts = [final_signal_starts; single(current_signal_start)];
                    final_signal_ends = [final_signal_ends; single(current_signal_end)];
                    
                    % For unselected spots, calculate matrix start/end based on indent
                    matrix_start = current_signal_start + unselected_indent;
                    matrix_end = current_signal_end - unselected_indent;

                    % Ensure matrix_start is not greater than matrix_end
                    if matrix_start > matrix_end
                        matrix_start = current_signal_start;
                        matrix_end = current_signal_end;
                    end

                    final_matrix_1_starts = [final_matrix_1_starts; {matrix_start}];
                    final_matrix_1_ends = [final_matrix_1_ends; {matrix_end}];
                end
            end

            final_matrix_1_starts = cellfun(@single, final_matrix_1_starts, 'UniformOutput', false);
            final_matrix_1_ends = cellfun(@single, final_matrix_1_ends, 'UniformOutput', false);

            % Update the spot structure in the app
            app.source.base.project.session.spot.name = final_names;
            app.source.base.project.session.spot.signal_starts = final_signal_starts;
            app.source.base.project.session.spot.signal_ends = final_signal_ends;
            app.source.base.project.session.spot.matrix_1_starts = final_matrix_1_starts;
            app.source.base.project.session.spot.matrix_1_ends = final_matrix_1_ends;

            app.source.base.project.session.meta.rolling_average_settings.sweeps_to_average = sweeps_to_average;
            app.source.base.project.session.meta.rolling_average_settings.sweeps_to_skip_start = sweeps_to_skip_start;
            app.source.base.project.session.meta.rolling_average_settings.sweeps_to_skip_end = sweeps_to_skip_end;
            app.source.base.project.session.meta.rolling_average_settings.sweeps_to_step_forward = sweeps_to_step_forward;
            app.source.base.project.session.meta.rolling_average_settings.unselected_indent = unselected_indent;    
            app.source.base.project.session.meta.rolling_average_settings.applied = true;


            if isequal(app.source.base.project.session.config.datatype, 'Continuous')

                app.source.base.project.session.spot.integer_time_points = cell2mat(final_matrix_1_starts);

            elseif isequal(app.source.base.project.session.config.datatype, 'Single')

                
                % Initialize cell arrays for new data
                final_acquisition_times = {};
                final_signal_data = {};
                
                % Iterate through all original spots
                for i = 1:original_spot_count
                    if isKey(selected_spot_map, i)
                        % This was a selected spot with duplicates
                        data = selected_spot_map(i);
                        num_duplicates = numel(data.matrix_starts);

                        % Replicate signal data table for each new spot
                        replicated_data = repmat(app.source.base.project.session.spot.signal_data(i), num_duplicates, 1);
                        final_signal_data = [final_signal_data; replicated_data];
                        
                        % Get current and next acquisition time
                        current_time = app.source.base.project.session.spot.acquisition_time{i};
                        
                        % Determine next acquisition time
                        if i < original_spot_count
                            next_time = app.source.base.project.session.spot.acquisition_time{i + 1};
                        else
                            % For last spot, extrapolate based on previous interval
                            if i > 1
                                prev_time = app.source.base.project.session.spot.acquisition_time{i - 1};
                                prev_parts = sscanf(prev_time, '%d:%d:%d');
                                prev_seconds = prev_parts(1) * 3600 + prev_parts(2) * 60 + prev_parts(3);
                                current_parts = sscanf(current_time, '%d:%d:%d');
                                current_seconds = current_parts(1) * 3600 + current_parts(2) * 60 + current_parts(3);
                                time_diff = current_seconds - prev_seconds;
                                next_seconds = current_seconds + time_diff;
                                hours = floor(next_seconds / 3600);
                                minutes = floor(mod(next_seconds, 3600) / 60);
                                secs = floor(mod(next_seconds, 60));
                                next_time = sprintf('%02d:%02d:%02d', hours, minutes, secs);
                            else
                                % Only one spot, use arbitrary interval
                                current_parts = sscanf(current_time, '%d:%d:%d');
                                current_seconds = current_parts(1) * 3600 + current_parts(2) * 60 + current_parts(3);
                                next_seconds = current_seconds + 600; % 10 minutes
                                hours = floor(next_seconds / 3600);
                                minutes = floor(mod(next_seconds, 3600) / 60);
                                secs = floor(mod(next_seconds, 60));
                                next_time = sprintf('%02d:%02d:%02d', hours, minutes, secs);
                            end
                        end
                        
                        % Convert times to seconds
                        current_parts = sscanf(current_time, '%d:%d:%d');
                        t_start = current_parts(1) * 3600 + current_parts(2) * 60 + current_parts(3);
                        next_parts = sscanf(next_time, '%d:%d:%d');
                        t_end = next_parts(1) * 3600 + next_parts(2) * 60 + next_parts(3);
                        
                        % Get signal boundaries
                        signal_start = app.source.base.project.session.spot.signal_starts(i);
                        signal_end = app.source.base.project.session.spot.signal_ends(i);
                        signal_length = signal_end - signal_start;
                        
                        % Calculate relative positions and interpolate times
                        for j = 1:num_duplicates
                            % Get the center of the current rolling window
                            window_center = (data.matrix_starts{j} + data.matrix_ends{j}) / 2;
                            
                            % Calculate relative position (0 to 1)
                            relative_pos = (window_center - signal_start) / signal_length;
                            
                            % Interpolate time
                            interpolated_seconds = t_start + relative_pos * (t_end - t_start);
                            hours = floor(interpolated_seconds / 3600);
                            minutes = floor(mod(interpolated_seconds, 3600) / 60);
                            secs = floor(mod(interpolated_seconds, 60));
                            interpolated_time = sprintf('%02d:%02d:%02d', hours, minutes, secs);
                            
                            final_acquisition_times = [final_acquisition_times; {interpolated_time}];
                        end
                    else
                        % Unselected spot, keep original time
                        final_acquisition_times = [final_acquisition_times; app.source.base.project.session.spot.acquisition_time(i)];
                        % Unselected spot, keep original signal data
                        final_signal_data = [final_signal_data; app.source.base.project.session.spot.signal_data(i)];
                    end
                end
                
                % Update acquisition times
                app.source.base.project.session.spot.acquisition_time = final_acquisition_times;
                % Update signal data
                app.source.base.project.session.spot.signal_data = final_signal_data;

            end

            if isfield(app.source.base.project.session, 'meta') && isfield(app.source.base.project.session.meta, 'laser_parameters')
                % Expand laser_parameters (or la_parameters) fields dynamically
                laser_params = app.source.base.project.session.meta.laser_parameters;
                param_fields = fieldnames(laser_params);
                new_laser_params = struct();

                for f = 1:numel(param_fields)
                    field = param_fields{f};
                    orig_data = laser_params.(field);

                    % Skip fields that don't match the original spot count
                    if iscell(orig_data)
                        field_length = length(orig_data);
                    elseif isnumeric(orig_data) || islogical(orig_data)
                        field_length = size(orig_data, 1);
                    else
                        % Skip unknown types
                        continue;
                    end

                    if field_length ~= original_spot_count
                        % Copy field as-is if it doesn't match the expected length
                        new_laser_params.(field) = orig_data;
                        continue;
                    end

                    expanded_data = {};
                    idx = 1;
                    for i = 1:original_spot_count
                        if isKey(selected_spot_map, i)
                            num_duplicates = numel(selected_spot_map(i).matrix_starts);
                            % Replicate the value for each duplicate
                            if iscell(orig_data)
                                expanded_data(idx:idx+num_duplicates-1,1) = repmat(orig_data(i), num_duplicates, 1);
                            else
                                expanded_data(idx:idx+num_duplicates-1,1) = num2cell(repmat(orig_data(i), num_duplicates, 1));
                            end
                            idx = idx + num_duplicates;
                        else
                            % Unselected: keep original
                            if iscell(orig_data)
                                expanded_data(idx,1) = orig_data(i);
                            else
                                expanded_data(idx,1) = {orig_data(i)};
                            end
                            idx = idx + 1;
                        end
                    end

                    % Convert back to original type if needed
                    if isnumeric(orig_data)
                        new_laser_params.(field) = cell2mat(expanded_data);
                    elseif iscell(orig_data)
                        new_laser_params.(field) = expanded_data;
                    else
                        new_laser_params.(field) = expanded_data;
                    end
                end

                app.source.base.project.session.meta.laser_parameters = new_laser_params;

            end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp)
            
            app.source = sourceapp;

            app.SpotListBox.Items = app.source.base.project.session.spot.name;
            app.SpotListBox.ItemsData = 1:numel(app.source.base.project.session.spot.name);

            if isfield(app.source.base.project.session.meta, 'rolling_average_settings') && app.source.base.project.session.meta.rolling_average_settings.applied == true
                
                app.NumberofsweepstobeaveragedEditField.Value = app.source.base.project.session.meta.rolling_average_settings.sweeps_to_average;
                app.NumberofsweepstoskipatthestartEditField.Value = app.source.base.project.session.meta.rolling_average_settings.sweeps_to_skip_start;
                app.NumberofsweepstoskipattheendEditField.Value = app.source.base.project.session.meta.rolling_average_settings.sweeps_to_skip_end;
                app.NumberofsweepstostepforwardEditField.Value = app.source.base.project.session.meta.rolling_average_settings.sweeps_to_step_forward;
                app.HowmanysweepstoindenttheintervalintheunselectedEditField.Value = app.source.base.project.session.meta.rolling_average_settings.unselected_indent;
           
                app.SpotListBox.Enable = false;
                app.NumberofsweepstobeaveragedEditField.Enable = false;
                app.NumberofsweepstoskipatthestartEditField.Enable = false;
                app.NumberofsweepstoskipattheendEditField.Enable = false;
                app.NumberofsweepstostepforwardEditField.Enable = false;
                app.HowmanysweepstoindenttheintervalintheunselectedEditField.Enable = false;
                app.ApplyButton.Enable = false;

            end


        end

        % Callback function
        function devButtonPushed(app, event)
                
            apply_rolling_average(app)

        end

        % Value changed function: SpotListBox
        function SpotListBoxValueChanged(app, event)
            
            app.selected_spot = app.SpotListBox.Value;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            

                fig = app.MovingAverageSettingsUIFigure;
                title = "ICP-Base";

                msg = "After applying, you process your data like you would normally but there are a few quirks:" + newline + ...
                    "1. If you change anything in the global interval setting for the matrix, everything will be reset and you can trash this session. Just don't touch this feature for the matrix." + newline + ...
                    "2. For the spots that are not rolling averaged, adjust the matrix intervals manually (and not globally!)." + newline + ...
                    "3. You can set the backgrounds using the global interval setting like normal though! Just don't touch the matrix intervals." + newline + ...
                    "4. If the spikes should be reset globally for datatype continuous it has to be done once for the first rolling average window. All subsequent windows will also be reset since they all access the same underlying data." + newline + ...
                    "5. Once this has been applied, there is no going back. Either you roll with it (get it?) or start from scratch." + newline + ...
                    "6. If the datatype is single, the acquisition time for each window is interpolated between the acquisition time of the original spot and the next spot. Be aware that this can result in slight inaccuracies of the drift correction in certain cases.";
                
                main_selection = uiconfirm(fig,msg,title, ...
                    "Options",["I understood and if things go sideways it's my own fault","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2, ...
                    "Icon","warning");

                if isequal(main_selection, "I understood and if things go sideways it's my own fault")

                    msg_2 = "Really?";
                    selection_2 = uiconfirm(fig, msg_2, title, ...
                        "Options",["Yes, really!", "No"], ...
                        "DefaultOption",1,"CancelOption",2);

                    if isequal(selection_2, 'Yes, really!')

                        apply_rolling_average(app)

                        delete(app)

                    elseif isequal(selection_2, 'No')

                        return

                    end


                elseif isequal(main_selection, 'Cancel')

                    return

                end


        end

        % Value changed function: NumberofsweepstostepforwardEditField
        function NumberofsweepstostepforwardEditFieldValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MovingAverageSettingsUIFigure = uifigure('Visible', 'off');
            app.MovingAverageSettingsUIFigure.Name = 'Moving Average Settings';
            app.MovingAverageSettingsUIFigure.Icon = 'icon_48.png';
            app.SpotListBox = uilistbox(app.MovingAverageSettingsUIFigure);
            app.SpotListBox.Items = {};
            app.SpotListBox.Multiselect = 'on';
            app.SpotListBox.ValueChangedFcn = createCallbackFcn(app, @SpotListBoxValueChanged, true);
            app.SpotListBox.Value = {};
            app.NumberofsweepstobeaveragedEditFieldLabel = uilabel(app.MovingAverageSettingsUIFigure);
            app.NumberofsweepstobeaveragedEditFieldLabel.Text = 'Number of sweeps to be averaged:';
            app.NumberofsweepstobeaveragedEditField = uieditfield(app.MovingAverageSettingsUIFigure, 'numeric');
            app.NumberofsweepstobeaveragedEditField.Limits = [1 Inf];
            app.NumberofsweepstobeaveragedEditField.Value = 5;
            app.NumberofsweepstoskipattheendEditFieldLabel = uilabel(app.MovingAverageSettingsUIFigure);
            app.NumberofsweepstoskipattheendEditFieldLabel.Text = 'Number of sweeps to skip at the end:';
            app.NumberofsweepstoskipattheendEditField = uieditfield(app.MovingAverageSettingsUIFigure, 'numeric');
            app.NumberofsweepstoskipattheendEditField.Limits = [0 Inf];
            app.NumberofsweepstoskipatthestartEditFieldLabel = uilabel(app.MovingAverageSettingsUIFigure);
            app.NumberofsweepstoskipatthestartEditFieldLabel.Text = 'Number of sweeps to skip at the start:';
            app.NumberofsweepstoskipatthestartEditField = uieditfield(app.MovingAverageSettingsUIFigure, 'numeric');
            app.NumberofsweepstoskipatthestartEditField.Limits = [0 Inf];
            app.HowmanysweepstoindenttheintervalintheunselectedstartandendLabel = uilabel(app.MovingAverageSettingsUIFigure);
            app.HowmanysweepstoindenttheintervalintheunselectedstartandendLabel.Text = 'Number of sweeps to indent the interval in the unselected spots (start and end):';
            app.HowmanysweepstoindenttheintervalintheunselectedEditField = uieditfield(app.MovingAverageSettingsUIFigure, 'numeric');
            app.HowmanysweepstoindenttheintervalintheunselectedEditField.Limits = [0 15];
            app.HowmanysweepstoindenttheintervalintheunselectedEditField.Value = 5;
            app.ApplyButton = uibutton(app.MovingAverageSettingsUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.NumberofsweepstostepforwardEditFieldLabel = uilabel(app.MovingAverageSettingsUIFigure);
            app.NumberofsweepstostepforwardEditFieldLabel.Text = 'Number of sweeps to step forward:';
            app.NumberofsweepstostepforwardEditField = uieditfield(app.MovingAverageSettingsUIFigure, 'numeric');
            app.NumberofsweepstostepforwardEditField.Limits = [1 Inf];
            app.NumberofsweepstostepforwardEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsweepstostepforwardEditFieldValueChanged, true);
            app.NumberofsweepstostepforwardEditField.Value = 1;
            % Show the figure after all components are created
            app.MovingAverageSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moving_average_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MovingAverageSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MovingAverageSettingsUIFigure)
        end
    end
end